classdef autoseg_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PreviewImageLabel               matlab.ui.control.Label
        InformationPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        NeuronsTab                      matlab.ui.container.Tab
        JumptoNeuronButton              matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        SegCount                        matlab.ui.control.NumericEditField
        IdCount                         matlab.ui.control.NumericEditField
        SegmentedneuronssuccesfullyIDd75neuronsLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        VolumesTab                      matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        Panel_4                         matlab.ui.container.Panel
        OpenSegmentationFolderButton_2  matlab.ui.control.Button
        OpenDataFolderButton            matlab.ui.control.Button
        MaxProjectionButton             matlab.ui.control.StateButton
        Panel                           matlab.ui.container.Panel
        xLabel                          matlab.ui.control.Label
        ImageDimsLabel                  matlab.ui.control.Label
        ImgWidth                        matlab.ui.control.NumericEditField
        ImgHeight                       matlab.ui.control.NumericEditField
        zSlicesListBox                  matlab.ui.control.ListBox
        Panel_3                         matlab.ui.container.Panel
        zSlicesLabel                    matlab.ui.control.Label
        CreditTab                       matlab.ui.container.Tab
        LaboratoryPageButton            matlab.ui.control.Button
        GithubRepositoryButton          matlab.ui.control.Button
        OriginalPaperButton             matlab.ui.control.Button
        CiteassuchLabel                 matlab.ui.control.Label
        Segmentationrelieson3DeeCellTrackeraLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        Slider                          matlab.ui.control.Slider
        Slider_Overlay                  matlab.ui.control.Slider
        ButtonGroup                     matlab.ui.container.ButtonGroup
        CellFilterLabel                 matlab.ui.control.Label
        ZScalingEditField               matlab.ui.control.NumericEditField
        ZScalingEditFieldLabel          matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        VoxelResolutionEditField        matlab.ui.control.NumericEditField
        MinimumSizeLabel                matlab.ui.control.Label
        AveragenoncellintensityEditField  matlab.ui.control.NumericEditField
        AveragenoncellintensityEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SettingsButtonGroup             matlab.ui.container.ButtonGroup
        NoiseFilterButtonGroup          matlab.ui.container.ButtonGroup
        ManualButton                    matlab.ui.control.RadioButton
        AutoButton                      matlab.ui.control.RadioButton
        HoveroverlabelsforexplanationLabel  matlab.ui.control.Label
        DisplayButtonGroup              matlab.ui.container.ButtonGroup
        OverlayButton                   matlab.ui.control.RadioButton
        SliderButton                    matlab.ui.control.RadioButton
        RESETButton                     matlab.ui.control.Button
        BlackOutToolButton              matlab.ui.control.Button
        HistogramToolButton             matlab.ui.control.Button
        GENERATEButton                  matlab.ui.control.Button
        CellFilterButtonGroup           matlab.ui.container.ButtonGroup
        NeuronCountButton               matlab.ui.control.RadioButton
        MinimumSizeButton               matlab.ui.control.RadioButton
        ImageAxes                       matlab.ui.control.UIAxes
        OverlayAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        live_view; %
        parent_app; % Reference to main Neuropal window.
        image_data; % Passed from main
        image_neurons;
        repo_img;
        repo_view;
        image_gamma; % Passed from main
        image_view; % Passed from main
        avg_nci;
        z_siz;
        image_raw;
        image_array;
    end

    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function UpdateFromChild(app, image_data, image_neurons,image_gamma)
            app.image_data = image_data;
            app.image_gamma = image_gamma;
            app.DrawImageData()
        end

        function CalcNCI(app)
            width = size(app.image_view,2);
            height = size(app.image_view,1);
            top_left = mean(app.image_data(floor(height-height/10):height,1:floor(width/10)),'all');
            top_right = mean(app.image_view(floor(height-height/10):height, floor(width-width/10):width),'all');
            bot_left = mean(app.image_view(1:floor(height/10),1:floor(width/10)),'all');
            bot_right = mean(app.image_view(1:floor(height/10),floor(width-width/10):width),'all');
            app.avg_nci = mean([top_left top_right bot_left bot_right],'all');
            app.AveragenoncellintensityEditField.Value = app.avg_nci;
        end

        function [image_cell_bg, l_center_coordinates, segmentation_auto, image_gcn, r_coordinates_segment] = ...
            segment(obj, vol, method, print_shape)
            if nargin < 4
                print_shape = false;
            end
        
            image_raw = ReadImageTs(vol, obj.paths.raw_image, obj.paths.image_name, [0, obj.z_siz], print_shape);
        
            % image_gcn will be used to correct tracking results
            image_gcn = (image_raw.copy() / 65536.0);
        
            fprintf('%d\n', image_raw);
        
            image_cell_bg = obj.predict_cellregions(image_raw, vol);
        
            fprintf('%d\n', image_cell_bg);
            fprintf('%d\n', max(image_cell_bg(:)));
        
            if max(image_cell_bg(:)) <= 0.5
                error('No cell was detected by 3D U-Net! Try to reduce the noise_level.');
            end
        
            % segment connected cell-like regions using _watershed
            segmentation_auto = obj.watershed(image_cell_bg, method);
            if max(segmentation_auto(:)) == 0
                error('No cell was detected by watershed! Try to reduce the min_size.');
            end
        
            % calculate coordinates of the centers of each segmented cell
            l_center_coordinates = snm.center_of_mass(segmentation_auto > 0, segmentation_auto, 1:max(segmentation_auto(:)));
            r_coordinates_segment = obj.transform_layer_to_real(l_center_coordinates);
        end

        function image_cell_bg = predict_cellregions(app, image_raw, vol)
            % Predict cell regions by 3D U-Net and save it if the prediction has not been cached
            
            % pre-processing: local contrast normalization
            image_norm = cat(4, NormalizeImage(image_raw, self.noise_level));
            % predict cell-like regions using 3D U-net
            image_cell_bg = unet3_prediction(image_norm, self.unet_model, self.shrink);
            save(sprintf('%s/t%%04i.npy', self.paths.unet_cache), 'image_cell_bg', '-v7.3', '-mat');
            return;
        end

        function DrawImageData(app)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = str2double(app.parent_app.RDropDown.Value);
            green = str2double(app.parent_app.GDropDown.Value);
            blue = str2double(app.parent_app.BDropDown.Value);
            white = str2double(app.parent_app.WDropDown.Value);
            dic = str2double(app.parent_app.DICDropDown.Value);
            gfp = str2double(app.parent_app.GFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Add in the white channel.
            if app.parent_app.WCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.parent_app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.parent_app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,dic);

                % Adjust the gamma.
                DIC_i = app.parent_app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.parent_app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.parent_app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end

            app.image_view = double(app.image_view)/double(max(app.image_view(:)));
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    app.CalcNCI()
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_neurons, image_view, image_gamma)
            
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.repo_img = image_data;
            app.image_neurons = image_neurons;
            app.image_view = image_view;
            app.repo_view = image_view;
            app.image_gamma = image_gamma;

            C = {};

            for n = 1:size(app.image_data, 3)
                C{n} = ['Slice ' num2str(n)];
            end

            app.zSlicesListBox.Items = C;
            app.zSlicesListBox.ItemsData = 1:size(app.image_data,3);
            app.zSlicesListBox.Value = 1:size(app.image_data,3);

            axis(app.ImageAxes, 'normal');
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));

            app.ImgHeight.Value = size(app.image_data, 1);
            app.ImgWidth.Value = size(app.image_data, 2);
            app.z_siz = size(app.image_data, 3);
            app.CalcNCI()
    
            app.Slider_Overlay.Limits = [0,size(app.image_data, 3)];
            app.Slider.Limits = [0,896];
            app.Slider.Value = 896/2;

            app.ImageAxes.XLim = [0, app.Slider.Value];
            app.ImageAxes.Position = [43,307,app.Slider.Value,233];
            app.OverlayAxes.Position = [43,307,app.Slider.Value,233];
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.ImageAxes.XLim = [0, event.Value];
            app.ImageAxes.Position = [app.ImageAxes.Position(1),app.ImageAxes.Position(2),event.Value,app.ImageAxes.Position(4)];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.ImageAxes.XLim = [0, event.Value];
            app.ImageAxes.Position = [app.ImageAxes.Position(1),app.ImageAxes.Position(2),app.Slider.Value,app.ImageAxes.Position(4)];
        end

        % Selection changed function: CellFilterButtonGroup
        function CellFilterButtonGroupSelectionChanged(app, event)
            switch app.CellFilterButtonGroup.SelectedObject.Text
                case 'Minimum Size'
                    app.CellFilterLabel.Text = 'Minimum Size:';
                case 'Neuron Count'
                    app.CellFilterLabel.Text = 'Expected Count:';
            end
        end

        % Selection changed function: NoiseFilterButtonGroup
        function NoiseFilterButtonGroupSelectionChanged(app, event)
            switch app.NoiseFilterButtonGroup.SelectedObject.Text
                case 'Auto'
                    set(app.AveragenoncellintensityEditField,'Editable','off')
                    app.CalcNCI()
                case 'Manual'
                    set(app.AveragenoncellintensityEditField,'Editable','on')
            end
        end

        % Button pushed function: OriginalPaperButton
        function OriginalPaperButtonPushed(app, event)
            web('https://elifesciences.org/articles/59187')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/WenChentao/3DeeCellTracker')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://en.kokimura-lab.org/')
        end

        % Button pushed function: HistogramToolButton
        function HistogramToolButtonPushed(app, event)
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.parent_app.RDropDown.Value, app.parent_app.GDropDown.Value, app.parent_app.BDropDown.Value, app.parent_app.WDropDown.Value, app.parent_app.DICDropDown.Value, app.parent_app.GFPDropDown.Value);
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');
            
            switch check
                case 'Yes'

                    folder = 'seg_data/data/';
                    files = dir(fullfile(folder,'*.*'));  % Get a list of all files in the folder
                    for i = 1:length(files)  % Loop through each file
                        file = fullfile(folder,files(i).name);  % Get the full path to the file
                        if ~files(i).isdir  % Only delete files, not directories
                            delete(file);  % Delete the file
                        end
                    end

                    for n = min(app.zSlicesListBox.Value):max(app.zSlicesListBox.Value)
                        name = join(['seg_data/data/npal_','worm_','c1_',int2str(n-min(app.zSlicesListBox.Value)), '.tiff']);
                        imwrite(app.image_data(:,:,n), name, 'tiff', 'WriteMode', 'overwrite');
                    end

                    neuron_info = pyrunfile(sprintf('npal_seg.py %f %f %f', app.EditField.Value, app.VoxelResolutionEditField.Value, app.ZScalingEditField.Value),'neuron_info');
                    
                    app.SegCount.Value = length(neuron_info);
                    app.UITable.Data = cell(length(neuron_info), 4);

                    for i = 1:length(neuron_info)
                        id = neuron_info{i};
                        app.UITable.Data(i,:) = {i, '', '', sprintf('(%.2f, %.2f, %.2f)', id(1), id(2), id(3))};
                    end

                    % Get all the image files in the folder
                    imageFiles = dir('seg_data/auto_vol1/*.tif'); 
                    
                    % Initialize an empty label matrix
                    label_matrix = zeros(size(imread(fullfile('seg_data/auto_vol1/',imageFiles(1).name))));
                    
                    % Loop through all the image files
                    for i = 1:length(imageFiles)
                        % Read in the image file
                        img = imread(fullfile('seg_data/auto_vol1/',imageFiles(i).name));
                        
                        % Add the image to the label matrix
                        label_matrix = label_matrix + i*(img > 0);
                    end

                    base = app.image_data(:,:,round(app.Slider_Overlay.Value,0));
                    seg = imread(sprintf('seg_data/auto_vol1/auto_t1_z%d.tif',round(app.Slider_Overlay.Value,0)));
                    image(app.OverlayAxes,labeloverlay(base,seg))

                case 'No'
                    return
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.image_data = app.repo_img;
            app.image_view = app.repo_view;
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: BlackOutToolButton
        function BlackOutToolButtonPushed(app, event)
            check = uiconfirm(app.UIFigure,'There are two available black out modes. Regular black out allows you to select a region to be nulled out before segmentation. Inverse mode allows you to select a region to be segmented.','Select Mode','Options',{'Regular Mode','Inverse Mode'},'DefaultOption','Regular Mode');

            app.Slider.Value = app.Slider.Limits(2);
            app.ImageAxes.XLim = [0, app.Slider.Value];
            app.ImageAxes.Position = [43,307,app.Slider.Value,233];

            uiwait(msgbox('Click and drag your cursor on the image to select the region to ignore during segmentation.','Instructions'))
            
            roi = drawfreehand(app.ImageAxes,'Color','black','StripeColor','m');
            draw(roi)
            mask = createMask(roi,app.image_view);

            switch check
                case 'Regular Mode'
                    mask = ~mask;
                    mask = double(mask);
        
                case 'Inverse Mode'
                    mask = double(mask);
            end
        
            for n = 1:size(app.image_data,3)
                color_indices = [1, 2, 3];
                app.image_data(:,:,:,color_indices) = double(app.image_data(:,:,:,color_indices)).*mask;
            end
    
            image(app.ImageAxes, squeeze(max(app.image_view,[],3)).*mask);
        end

        % Clicked callback: zSlicesListBox
        function zSlicesListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            app.z_siz = max(app.zSlicesListBox.Value) - min(app.zSlicesListBox.Value);
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: zSlicesListBox
        function zSlicesListBoxValueChanged(app, event)
            image(app.ImageAxes, squeeze(app.image_view(:,:,app.zSlicesListBox.Value,:,:)));
            app.MaxProjectionButton.Value = 0;
        end

        % Value changed function: MaxProjectionButton
        function MaxProjectionButtonValueChanged(app, event)
            if app.MaxProjectionButton.Value == 1
                image(app.ImageAxes, squeeze(max(app.image_view,[],3)));
                app.z_siz = size(app.image_data,3);
                app.zSlicesListBox.Value = 1:size(app.image_data,3);
            else
                image(app.ImageAxes, squeeze(app.image_view(:,:,app.zSlicesListBox.Value,:,:)));
            end
        end

        % Selection changed function: DisplayButtonGroup
        function DisplayButtonGroupSelectionChanged(app, event)
            switch app.DisplayButtonGroup.SelectedObject.Text
                case 'Slider'
                    set(app.Slider,'Visible','On');
                    set(app.Slider_Overlay,'Visible','Off');
                    set(app.ImageAxes,'Visible','On');
                    set(app.OverlayAxes,'Visible','Off');
                case 'Overlay'
                    set(app.Slider,'Visible','Off');
                    set(app.Slider_Overlay,'Visible','On');
                    set(app.ImageAxes,'Visible','Off');
                    set(app.OverlayAxes,'Visible','On');
            end
        end

        % Value changed function: Slider_Overlay
        function Slider_OverlayValueChanged(app, event)
            base = app.image_data(:,:,round(app.Slider_Overlay.Value));
            seg = imread(sprintf('seg_data/auto_vol1/auto_t1_z%d.tif',round(app.Slider_Overlay.Value,0)));
            image(app.OverlayAxes,labeloverlay(base,seg))
        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            winopen('seg_data/data/');
        end

        % Button pushed function: OpenSegmentationFolderButton_2
        function OpenSegmentationFolderButton_2Pushed(app, event)
            winopen('seg_data/auto_vol1/');
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            file_info = 'seg_data/*.csv';
            [name, path, ~] = uigetfile(file_info, 'Save neuron table.');
            writetable(app.UITable.Data,join([path,name]))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OverlayAxes = uiaxes(app.UIFigure);
            app.OverlayAxes.XTick = [];
            app.OverlayAxes.XTickLabel = '';
            app.OverlayAxes.YTick = [];
            app.OverlayAxes.Visible = 'off';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = '';
            app.ImageAxes.YTick = [];
            app.SettingsButtonGroup = uibuttongroup(app.UIFigure);
            app.SettingsButtonGroup.Title = 'Settings                                           ';
            app.CellFilterButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.CellFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CellFilterButtonGroupSelectionChanged, true);
            app.CellFilterButtonGroup.Title = 'Cell Filter';
            app.MinimumSizeButton = uiradiobutton(app.CellFilterButtonGroup);
            app.MinimumSizeButton.Text = 'Minimum Size';
            app.MinimumSizeButton.Value = true;
            app.NeuronCountButton = uiradiobutton(app.CellFilterButtonGroup);
            app.NeuronCountButton.Text = 'Neuron Count';
            app.GENERATEButton = uibutton(app.SettingsButtonGroup, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.HistogramToolButton = uibutton(app.SettingsButtonGroup, 'push');
            app.HistogramToolButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramToolButtonPushed, true);
            app.HistogramToolButton.Text = 'Histogram Tool';
            app.BlackOutToolButton = uibutton(app.SettingsButtonGroup, 'push');
            app.BlackOutToolButton.ButtonPushedFcn = createCallbackFcn(app, @BlackOutToolButtonPushed, true);
            app.BlackOutToolButton.Text = 'Black Out Tool';
            app.RESETButton = uibutton(app.SettingsButtonGroup, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.DisplayButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.DisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayButtonGroupSelectionChanged, true);
            app.DisplayButtonGroup.Title = 'Display';
            app.SliderButton = uiradiobutton(app.DisplayButtonGroup);
            app.SliderButton.Enable = 'off';
            app.SliderButton.Text = 'Slider';
            app.OverlayButton = uiradiobutton(app.DisplayButtonGroup);
            app.OverlayButton.Enable = 'off';
            app.OverlayButton.Text = 'Overlay';
            app.OverlayButton.Value = true;
            app.HoveroverlabelsforexplanationLabel = uilabel(app.SettingsButtonGroup);
            app.HoveroverlabelsforexplanationLabel.Text = ' (Hover over labels for explanation.)';
            app.NoiseFilterButtonGroup = uibuttongroup(app.SettingsButtonGroup);
            app.NoiseFilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NoiseFilterButtonGroupSelectionChanged, true);
            app.NoiseFilterButtonGroup.Title = 'Noise Filter';
            app.AutoButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.NoiseFilterButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Label = uilabel(app.ButtonGroup);
            app.Label.Text = '';
            app.AveragenoncellintensityEditFieldLabel = uilabel(app.ButtonGroup);
            app.AveragenoncellintensityEditFieldLabel.Text = 'Average non-cell intensity:';
            app.AveragenoncellintensityEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.AveragenoncellintensityEditField.Editable = 'off';
            app.AveragenoncellintensityEditField.Tooltip = {'Averaged non-cell region intensity, which helps the program to ignore the background noises.'};
            app.MinimumSizeLabel = uilabel(app.ButtonGroup);
            app.MinimumSizeLabel.Text = 'Voxel Resolution:';
            app.VoxelResolutionEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.VoxelResolutionEditField.Tooltip = {'Resolution (um/voxels) ratio between z (depth) and x-y plane.'};
            app.EditFieldLabel = uilabel(app.ButtonGroup);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.Tooltip = {'Depending on cell filter, either the minimum cell size or the expected number of neurons. Used to help the program ignore small non-cell regions.'};
            app.ZScalingEditFieldLabel = uilabel(app.ButtonGroup);
            app.ZScalingEditFieldLabel.Text = 'Z-Scaling:';
            app.ZScalingEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ZScalingEditField.Tooltip = {'Factor used to interpolate the unbalanced z_xy resolution.'};
            app.CellFilterLabel = uilabel(app.ButtonGroup);
            app.CellFilterLabel.Text = 'Minimum Size:';
            app.Slider_Overlay = uislider(app.UIFigure);
            app.Slider_Overlay.Limits = [0 10];
            app.Slider_Overlay.MajorTicks = [];
            app.Slider_Overlay.ValueChangedFcn = createCallbackFcn(app, @Slider_OverlayValueChanged, true);
            app.Slider_Overlay.MinorTicks = [];
            app.Slider_Overlay.Visible = 'off';
            app.Slider_Overlay.Value = 5;
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 100;
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.TabGroup2 = uitabgroup(app.InformationPanel);
            app.NeuronsTab = uitab(app.TabGroup2);
            app.NeuronsTab.Title = 'Neurons';
            app.UITable = uitable(app.NeuronsTab);
            app.UITable.ColumnName = {'Neuron'; 'Suspected ID'; 'Confidence (%)'; 'Position'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.Multiselect = 'off';
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel = uilabel(app.NeuronsTab);
            app.SegmentedneuronssuccesfullyIDd75neuronsLabel.Text = 'Segmented              neurons, succesfully ID''d (75%+)              neurons.';
            app.IdCount = uieditfield(app.NeuronsTab, 'numeric');
            app.IdCount.Editable = 'off';
            app.SegCount = uieditfield(app.NeuronsTab, 'numeric');
            app.SegCount.Editable = 'off';
            app.ExportButton = uibutton(app.NeuronsTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.JumptoNeuronButton = uibutton(app.NeuronsTab, 'push');
            app.JumptoNeuronButton.Enable = 'off';
            app.JumptoNeuronButton.Text = 'Jump to Neuron...';
            app.VolumesTab = uitab(app.TabGroup2);
            app.VolumesTab.Title = 'Volumes';
            app.Panel_3 = uipanel(app.VolumesTab);
            app.zSlicesLabel = uilabel(app.Panel_3);
            app.zSlicesLabel.Text = 'z-Slices';
            app.zSlicesListBox = uilistbox(app.VolumesTab);
            app.zSlicesListBox.Multiselect = 'on';
            app.zSlicesListBox.ValueChangedFcn = createCallbackFcn(app, @zSlicesListBoxValueChanged, true);
            app.zSlicesListBox.Tooltip = {'Click on single z-slice to preview or edit. Select multiple to limit segmentation to selection.'};
            app.zSlicesListBox.ClickedFcn = createCallbackFcn(app, @zSlicesListBoxClicked, true);
            app.zSlicesListBox.Value = {'Item 1'};
            app.Panel = uipanel(app.VolumesTab);
            app.ImgHeight = uieditfield(app.Panel, 'numeric');
            app.ImgHeight.Editable = 'off';
            app.ImgWidth = uieditfield(app.Panel, 'numeric');
            app.ImgWidth.Editable = 'off';
            app.ImageDimsLabel = uilabel(app.Panel);
            app.ImageDimsLabel.Text = 'Image Dims:';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x';
            app.Panel_2 = uipanel(app.VolumesTab);
            app.MaxProjectionButton = uibutton(app.Panel_2, 'state');
            app.MaxProjectionButton.ValueChangedFcn = createCallbackFcn(app, @MaxProjectionButtonValueChanged, true);
            app.MaxProjectionButton.Text = 'Max Projection';
            app.MaxProjectionButton.Value = true;
            app.Panel_4 = uipanel(app.Panel_2);
            app.OpenDataFolderButton = uibutton(app.Panel_4, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.OpenSegmentationFolderButton_2 = uibutton(app.Panel_4, 'push');
            app.OpenSegmentationFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenSegmentationFolderButton_2Pushed, true);
            app.OpenSegmentationFolderButton_2.Text = 'Open Segmentation Folder';
            app.CreditTab = uitab(app.TabGroup2);
            app.CreditTab.Title = 'Credit';
            app.TextArea = uitextarea(app.CreditTab);
            app.TextArea.Value = {'@article{'; 'author = {Wen, Chentao and Miura, Takuya and Voleti, Venkatakaushik and Yamaguchi, Kazushi and Tsutsumi, Motosuke and Yamamoto, Kei and Otomo, Kohei and Fujie, Yukako and Teramoto, Takayuki and Ishihara, Takeshi and Aoki, Kazuhiro and Nemoto, Tomomi and Hillman, Elizabeth MC and Kimura, Koutarou D},'; 'doi = {10.7554/eLife.59187},'; 'journal = {eLife},'; 'month = {mar},'; 'title = {{3DeeCellTracker, a deep learning-based pipeline for segmenting and tracking cells in 3D time lapse images}},'; 'volume = {10},'; 'year = {2021}'; '}'};
            app.Segmentationrelieson3DeeCellTrackeraLabel = uilabel(app.CreditTab);
            app.Segmentationrelieson3DeeCellTrackeraLabel.Text = 'Segmentation relies on a modified version of 3DeeCellTracker, a deep-learning based pipeline for tracking cells in 3D time lapse images of deforming/moving organs developed by Chentao Wen et al.';
            app.CiteassuchLabel = uilabel(app.CreditTab);
            app.CiteassuchLabel.Text = 'Cite as such:';
            app.OriginalPaperButton = uibutton(app.CreditTab, 'push');
            app.OriginalPaperButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalPaperButtonPushed, true);
            app.OriginalPaperButton.Text = 'Original Paper';
            app.GithubRepositoryButton = uibutton(app.CreditTab, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.LaboratoryPageButton = uibutton(app.CreditTab, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.PreviewImageLabel = uilabel(app.UIFigure);
            app.PreviewImageLabel.Text = 'Preview Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoseg_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end