classdef channel_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveButton                   matlab.ui.control.Button
        PleaseselectwhichofthefollowingmeasuresyoudliketocorrectLabel  matlab.ui.control.Label
        GutautofluorescenceCheckBox  matlab.ui.control.CheckBox
        LysosomeautofluorescenceCheckBox  matlab.ui.control.CheckBox
        GFPCheckBox                  matlab.ui.control.CheckBox
        WhiteCheckBox                matlab.ui.control.CheckBox
        BackgroundCheckBox           matlab.ui.control.CheckBox
        BlueCheckBox                 matlab.ui.control.CheckBox
        GreenCheckBox                matlab.ui.control.CheckBox
        RedCheckBox                  matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        parent_app; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app)
            app.parent_app = parent_app;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.parent_app.eyedrop(app.RedCheckBox.Value,app.GreenCheckBox.Value,app.BlueCheckBox.Value,app.BackgroundCheckBox.Value,app.WhiteCheckBox.Value,app.GFPCheckBox.Value,app.LysosomeautofluorescenceCheckBox.Value,app.GutautofluorescenceCheckBox.Value);
            delete(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RedCheckBox = uicheckbox(app.UIFigure);
            app.RedCheckBox.Enable = 'off';
            app.RedCheckBox.Text = 'Red';
            app.RedCheckBox.Value = true;
            app.GreenCheckBox = uicheckbox(app.UIFigure);
            app.GreenCheckBox.Enable = 'off';
            app.GreenCheckBox.Text = 'Green';
            app.GreenCheckBox.Value = true;
            app.BlueCheckBox = uicheckbox(app.UIFigure);
            app.BlueCheckBox.Enable = 'off';
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.BackgroundCheckBox = uicheckbox(app.UIFigure);
            app.BackgroundCheckBox.Enable = 'off';
            app.BackgroundCheckBox.Text = 'Background';
            app.BackgroundCheckBox.Value = true;
            app.WhiteCheckBox = uicheckbox(app.UIFigure);
            app.WhiteCheckBox.Text = 'White';
            app.GFPCheckBox = uicheckbox(app.UIFigure);
            app.GFPCheckBox.Text = 'GFP';
            app.LysosomeautofluorescenceCheckBox = uicheckbox(app.UIFigure);
            app.LysosomeautofluorescenceCheckBox.Text = 'Lysosome autofluorescence';
            app.GutautofluorescenceCheckBox = uicheckbox(app.UIFigure);
            app.GutautofluorescenceCheckBox.Text = 'Gut autofluorescence';
            app.PleaseselectwhichofthefollowingmeasuresyoudliketocorrectLabel = uilabel(app.UIFigure);
            app.PleaseselectwhichofthefollowingmeasuresyoudliketocorrectLabel.Text = {'Please select which of the following'; 'measures you''d like to correct:'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = channel_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end