classdef trace_window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroPAL_IDTraceWindowUIFigure  matlab.ui.Figure
        AdjustAlignmentPanel            matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        SelectionButtonGroup            matlab.ui.container.ButtonGroup
        MoveVolumeNeuronsButton         matlab.ui.control.RadioButton
        MoveVideoButton                 matlab.ui.control.RadioButton
        MoveNeuronsButton               matlab.ui.control.RadioButton
        MovePanel                       matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        Panel_15                        matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        Left                            matlab.ui.control.Button
        Up                              matlab.ui.control.Button
        Right                           matlab.ui.control.Button
        Down                            matlab.ui.control.Button
        CCW                             matlab.ui.control.Button
        CW                              matlab.ui.control.Button
        Panel_13                        matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        MoveLabel                       matlab.ui.control.Label
        MoveEditField_2                 matlab.ui.control.NumericEditField
        pixelsatatimeLabel              matlab.ui.control.Label
        CenterHorizontallyButton        matlab.ui.control.Button
        CenterVerticallyButton          matlab.ui.control.Button
        FlipVerticallyButton            matlab.ui.control.Button
        FlipHorizontallyButton          matlab.ui.control.Button
        ResetTransformButton            matlab.ui.control.Button
        SaveResultButton                matlab.ui.control.Button
        ResetMoveButton                 matlab.ui.control.Button
        TransformPanel                  matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        WidthSpinner                    matlab.ui.control.Spinner
        WidthSpinnerLabel               matlab.ui.control.Label
        HeightSpinner                   matlab.ui.control.Spinner
        HeightSpinnerLabel              matlab.ui.control.Label
        GridLayout6                     matlab.ui.container.GridLayout
        ClicktoselectvideofileButton    matlab.ui.control.Button
        NeuroPALVolumePanel             matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        Npal_Axes                       matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        CentralControlTab               matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        Panel_16                        matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        FilesLoadedLabel                matlab.ui.control.Label
        Tree                            matlab.ui.container.CheckBoxTree
        DataNode                        matlab.ui.container.TreeNode
        WorldlinesNode                  matlab.ui.container.TreeNode
        AnnotationsNode                 matlab.ui.container.TreeNode
        MetadatajsonNode                matlab.ui.container.TreeNode
        Panel_6                         matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        RedMaytaketimeLabel             matlab.ui.control.Label
        RunTrackButton                  matlab.ui.control.Button
        RunTracerButton                 matlab.ui.control.Button
        SaveResultsButton_3             matlab.ui.control.Button
        AdjustAlignmentButton_3         matlab.ui.control.Button
        RecommendFramesButton_2         matlab.ui.control.Button
        Panel_8                         matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        MotionPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        MotionSlider                    matlab.ui.control.Slider
        BookmarksPanel                  matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        ListBox                         matlab.ui.control.ListBox
        SetbookmarkButton               matlab.ui.control.Button
        BasicSettingsTab                matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Panel_12                        matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        grid_shape                      matlab.ui.control.NumericEditField
        SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel  matlab.ui.control.Label
        grid_shapeLabel                 matlab.ui.control.Label
        l_n_mode                        matlab.ui.control.DropDown
        MethodtouseforcalculatingNLabel  matlab.ui.control.Label
        NmodeLabel                      matlab.ui.control.Label
        l_n                             matlab.ui.control.NumericEditField
        EditField4Label                 matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        LRCoeffLabel                    matlab.ui.control.Label
        lr_coeff                        matlab.ui.control.NumericEditField
        EditField6Label                 matlab.ui.control.Label
        nn_max                          matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        nn_maxLabel                     matlab.ui.control.Label
        UseGPULabel                     matlab.ui.control.Label
        use_gpu                         matlab.ui.control.DropDown
        SetsdevicetoGPUandenablesCUDAuseDropDownLabel  matlab.ui.control.Label
        EnableManualSettingsCheckBox    matlab.ui.control.CheckBox
        AdvancedSettingsTab             matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        fovea_sigma                     matlab.ui.control.NumericEditField
        EditFieldLabel_4                matlab.ui.control.Label
        foveasigmaLabel                 matlab.ui.control.Label
        sort_mode                       matlab.ui.control.DropDown
        DropDown_2Label                 matlab.ui.control.Label
        motion_predict                  matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        lr_ceiling                      matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        lr_ceilingLabel                 matlab.ui.control.Label
        motion_predictLabel             matlab.ui.control.Label
        sort_modeLabel                  matlab.ui.control.Label
        n_chunksLabel                   matlab.ui.control.Label
        n_chunks                        matlab.ui.control.NumericEditField
        NumberofstepstodividetheforwardpassintoEditFieldLabel  matlab.ui.control.Label
        n_epoch_dLabel                  matlab.ui.control.Label
        n_epochLabel                    matlab.ui.control.Label
        n_epoch_d                       matlab.ui.control.NumericEditField
        EditField2_2Label               matlab.ui.control.Label
        n_epoch                         matlab.ui.control.NumericEditField
        NumberofiterationsforimageregistrationLREditFieldLabel  matlab.ui.control.Label
        HoveroverlabelsforfurtherdetailsorguidanceLabel  matlab.ui.control.Label
        ZCompLabel                      matlab.ui.control.Label
        LRFloorLabel                    matlab.ui.control.Label
        dLabel                          matlab.ui.control.Label
        tLabel                          matlab.ui.control.Label
        clip_gradLabel                  matlab.ui.control.Label
        z_comp                          matlab.ui.control.NumericEditField
        Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel  matlab.ui.control.Label
        lr_floor                        matlab.ui.control.NumericEditField
        Minimumvalueforinitiallearningratedefault002EditFieldLabel  matlab.ui.control.Label
        l_d                             matlab.ui.control.NumericEditField
        EditField3Label                 matlab.ui.control.Label
        l_t                             matlab.ui.control.NumericEditField
        EditField2Label                 matlab.ui.control.Label
        clip_grad                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        CreditTab                       matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        CitationTextArea                matlab.ui.control.TextArea
        CitationTextAreaLabel           matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        GithubRepositoryButton          matlab.ui.control.Button
        ViewPreprintButton              matlab.ui.control.Button
        LaboratoryPageButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        parent_app;
        image_data;
        image_view;
        repo_neurons;
        image_neurons;
        path;
        dir;
        vidArray;
        vreader;
        neuron_table;
        red; % Color indices.
        green;
        blue;
        white;
        gfp;
        dic;
        image_gamma;
        data_store;
        v_master;
        v_data;
        zen_chunk;
        master_path;
        timerObj;
    end
    
    methods (Access = private)
        
        function checkExistence(app, directory_path, data_file)
            % Create new Tree
            newTree = uitree(app.GridLayout30, 'checkbox');
            newTree.Layout.Row = 2;
            newTree.Layout.Column = 1;
            
            % Create an array to store the checked nodes
            checkedNodes = {};
            
            % Check for existence of data.h5
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for data...','Indeterminate','on');
            if endsWith(data_file,'h5')
                close(d)
                title = sprintf('Loading %s...', data_file);
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                info = h5info(fullfile(directory_path, data_file));
                if ~any(strcmp({info.Datasets.Name}, 'data')) && ~any(strcmp({info.Datasets.Name}, 'times'))
                    uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'.h5 file structure is invalid. Please refer to documentation.','Error');
                    return
                end
            elseif endsWith(data_file,'nwb')
                close(d)
                title = sprintf('Detected nwb file. Building data structure from %s...', data_file);
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
                try
                    whole_file = nwbRead(filename);
                    gc_data = whole_file.acquisition.get('GCaMP_series');
                    gc_data = permute(gc_data,[2,3,1,4]);
                    data = gc_data.data.load();
                    
                    % Save data_var to the "data" dataset
                    h5create('data.h5', '/data', size(data));
                    h5write('data.h5', '/data', data);

                    data_file = fullfile(directory_path, 'data.h5');
                catch
                    close(d)
                    uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'Unable to build data structure from nwb file. Please refer to documentation.','Error');
                    return
                end
            end
            close(d)
            newNode = uitreenode(newTree);
            newNode.Text = data_file;
            checkedNodes{end+1} = newNode;

            % Check for existence of metadata.json
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for metadata...','Indeterminate','on');
            if exist(fullfile(directory_path, 'metadata.json'), 'file')
                close(d)
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading metadata.json...','Indeterminate','on');
                newNode = uitreenode(newTree);
                newNode.Text = 'Metadata.json';
                checkedNodes{end+1} = newNode;
            else
                close(d)
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','No metadata found. Creating metadata.json...','Indeterminate','on');
                
                % Get dataset information
                dataInfo = h5info(data_file, '/data');
                dataDims = dataInfo.Dataspace.Size;
                
                % Extract the dimensions
                shape_t = dataDims(5);
                shape_c = dataDims(4);
                shape_z = dataDims(3);
                shape_y = dataDims(2);
                shape_x = dataDims(1);
                
                % Create the metadata struct
                metadata = struct('shape_t', shape_t, 'shape_c', shape_c, 'shape_z', shape_z, 'shape_y', shape_y, 'shape_x', shape_x);
                
                % Write the metadata to a JSON file
                jsonStr = jsonencode(metadata);
                jsonFile = fopen([app.master_path,'metadata.json'], 'w');
                fwrite(jsonFile, jsonStr);
                fclose(jsonFile);
            end
            close(d)
            
            % Check for existence of worldlines.h5
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for worldlines...','Indeterminate','on');
            if exist(fullfile(directory_path, 'worldlines.h5'), 'file')
                close(d)
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading worldlines.h5...','Indeterminate','on');
                newNode = uitreenode(newTree);
                newNode.Text = 'Worldlines.h5';
                checkedNodes{end+1} = newNode;
            else
                close(d)
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'No worldlines.h5 file found. This cannot be created from data. Please refer to documentation.','Error');
                return
            end
            close(d)
            
            % Check for existence of annotations.h5
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Scanning for annotations...','Indeterminate','on');
            if exist(fullfile(directory_path, 'annotations.h5'), 'file')
                close(d)
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading annotations.h5...','Indeterminate','on');
                newNode = uitreenode(newTree);
                newNode.Text = 'Annotations.h5';
                checkedNodes{end+1} = newNode;
            end
            close(d)
            
            % Set the checked nodes
            newTree.CheckedNodes = [checkedNodes{:}];
            
            % Assign Checked Nodes Changed Function
            newTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            
            % Replace the existing Tree with the new one
            delete(app.Tree);
            app.Tree = newTree;
            set(app.Tree, 'Enable', 'off');
        end

        function data_packer(app, data_file)
            app.checkExistence(app.master_path, data_file);
        end

        function zeph_wrapper(app, routine)            
            switch routine
                case 'recommend_frames'
                    title = 'Analyzing frames...';
                case 'annotator'
                    title = 'Loading annotator...';
                case 'tracker'
                    title = 'Tracking neurons...';
                case 'tracer'
                    title = 'Calculating traces...';
            end

            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title',title,'Indeterminate','on');
            python_command = ['External_Dependencies\ZephIR\example\npal-wrapper.py ',app.master_path, ' ', routine];
            pyrunfile(python_command);
            close(d)
        end

        function openMinimalBrowser(app, url)

            %app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'maximized';
            
            screenSize = get(0, 'ScreenSize');
            axesPosition = app.UIAxes.Position;
            gridPosition = app.GridLayout6.Position;
            figurePosition = app.NeuroPAL_IDTraceWindowUIFigure.Position;
            x = figurePosition(1) + axesPosition(1);
            y = screenSize(4) - (figurePosition(2) + axesPosition(2) + axesPosition(4));
            width = axesPosition(3);
            height = gridPosition(4);
            position = [x, y, width, height];
            
            % Absolute path to the packaged Electron application
            [pathToThisFile, ~, ~] = fileparts(mfilename('fullpath'));
            exePath = fullfile(pathToThisFile, 'External_Dependencies', 'ZephIR', 'web-wrap', 'wrap-browser-win32-x64', 'wrap-browser.exe');
            
            args = sprintf('--x %d --y %d --width %d --height %d', position(1), position(2), position(3), position(4));
            batchFile = 'startElectronApp.bat';
            fid = fopen(batchFile, 'wt');
            fprintf(fid, 'start "" "%s" %s', exePath, args);
            fclose(fid);
            
            system(batchFile);
            delete(batchFile);

        end

        function updateBrowserPosition(app)
            screenSize = get(0, 'ScreenSize');
            axesPosition = app.UIAxes.Position;
            gridPosition = app.GridLayout6.Position;
            figurePosition = app.NeuroPAL_IDTraceWindowUIFigure.Position;
            x = figurePosition(1) + axesPosition(1);
            y = screenSize(4) - (figurePosition(2) + axesPosition(2) + axesPosition(4));
            width = axesPosition(3);
            height = gridPosition(4);
            position = [x, y, width, height];
            url = sprintf('http://localhost:5003/updatePosition?x=%d&y=%d&width=%d&height=%d', position(1), position(2), position(3), position(4));  
            webread(url); % Send HTTP request
        end

        function startBrowser(app)
            app.zeph_wrapper('annotator');

            try
                app.openMinimalBrowser('http://localhost:5001/');
            catch
                web('http://localhost:5001/','-new','-notoolbar');
            end
            
            % Timer to update coordinates
            app.timerObj = timer('TimerFcn', @(~,~) app.updateBrowserPosition(), 'Period', 0.1, 'ExecutionMode', 'fixedRate');
            start(app.timerObj);
        end

        function closeBrowser(app)
            % Delete the position update timer
            delete(app.timerObj);

            try
                webread('http://localhost:5002/close');
            catch
                warning('Failed to close Electron window');
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent_app, image_data, image_view, image_neurons, path, dir, red, green, blue, white, dic, gfp, image_gamma)
            app.parent_app = parent_app;
            app.image_data = image_data;
            app.image_view = image_view;
            app.repo_neurons = image_neurons;
            app.image_neurons = image_neurons;
            app.repo_neurons = app.repo_neurons;
            app.image_gamma = image_gamma;
            app.path = path;
            app.dir = dir;

            app.red = str2double(red);
            app.green = str2double(green);
            app.blue = str2double(blue);
            app.white = str2double(white);
            app.dic = str2double(dic);
            app.gfp = str2double(gfp);

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizing
            app.NeuroPAL_IDTraceWindowUIFigure.Position = figure_size;

            image(app.Npal_Axes, squeeze(max(app.image_view,[],3)));

            app.Npal_Axes.XLim = [1, size(app.image_data, 2)];
            app.Npal_Axes.YLim = [1, size(app.image_data, 1)];
        end

        % Button pushed function: ViewPreprintButton
        function ViewPreprintButtonPushed(app, event)
            web('https://www.biorxiv.org/content/10.1101/2022.07.18.500485v1')
        end

        % Button pushed function: GithubRepositoryButton
        function GithubRepositoryButtonPushed(app, event)
            web('https://github.com/venkatachalamlab/ZephIR')
        end

        % Button pushed function: LaboratoryPageButton
        function LaboratoryPageButtonPushed(app, event)
            web('https://venkatachalamlab.org/')
        end

        % Button pushed function: ClicktoselectvideofileButton
        function ClicktoselectvideofileButtonPushed(app, event)
            % Prompt user to select video file
            [file,path] = uigetfile({'*.h5;*.nwb','Supported Files (*.h5, *.nwb)'},'Select GCaMP file');
    
            d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Loading data...',...
'Indeterminate','on');
            app.master_path = path;
            app.data_packer(file);
            app.startBrowser();
            app.UIAxes.Color = [0, 0, 0];

            set(app.SetbookmarkButton,'Enable','On');
            set(app.AdjustAlignmentButton_3,'Enable','On');
            set(app.RunTrackButton,'Enable','On');
            set(app.RunTracerButton,'Enable','On');
            set(app.SaveResultsButton_3,'Enable','On');
            set(app.RecommendFramesButton_2,'Enable','On');
            set(app.RedMaytaketimeLabel,'Enable','On');
            set(app.MotionSlider,'Enable','On');
            set(app.FilesLoadedLabel,'Enable','On');
            set(app.MotionPanel,'Enable','On');
            set(app.BookmarksPanel,'Enable','On');
            set(app.ListBox,'Enable','On');
            set(app.EnableManualSettingsCheckBox,'Enable','On');
            set(app.ClicktoselectvideofileButton,'Visible','Off');
            close(d)

        end

        % Button pushed function: RunTrackButton
        function RunTrackButtonPushed2(app, event)
            check = uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'Depending on your computer, this may take a while. Are you sure you want to proceed?','Warning!','Options',{'Yes','No'},'DefaultOption','Yes');

            switch check
                case 'Yes'
    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Processing data...','Indeterminate','on');
                    
                    app.data_packer();
                    
                    close(d)
                    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Running trace...','Indeterminate','on');
                    
                    app.zeph_wrapper('tracker');
                    
                    close(d)
                    
                    d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Rendering results...','Indeterminate','on');
                    
                    close(d)

                case 'No'
                    return
            end
        end

        % Button pushed function: RunTracerButton
        function RunTracerButtonPushed2(app, event)
            app.zeph_wrapper('tracer');
        end

        % Value changed function: EnableManualSettingsCheckBox
        function EnableManualSettingsCheckBoxValueChanged(app, event)
            if app.EnableManualSettingsCheckBox.Value == 1
                
                set(app.use_gpu,'Enable','On')
                set(app.n_epoch,'Enable','On')
                set(app.n_epoch_d,'Enable','On')
                set(app.n_chunks,'Enable','On')

                set(app.clip_grad,'Enable','On')
                set(app.l_t,'Enable','On')
                set(app.l_d,'Enable','On')
                set(app.l_n,'Enable','On')
                set(app.z_comp,'Enable','On')
                set(app.lr_coeff,'Enable','On')
                set(app.lr_floor,'Enable','On')
                set(app.l_n_mode,'Enable','On')
                set(app.nn_max,'Enable','On')

                set(app.lr_ceiling,'Enable','On')
                set(app.fovea_sigma,'Enable','On')
                set(app.sort_mode,'Enable','On')
                set(app.grid_shape,'Enable','On')
                set(app.motion_predict,'Enable','On')
            else
                set(app.n_epoch,'Enable','Off')
                set(app.n_epoch_d,'Enable','Off')
                set(app.n_chunks,'Enable','Off')
                set(app.use_gpu,'Enable','Off')
                set(app.clip_grad,'Enable','Off')
                set(app.l_t,'Enable','Off')
                set(app.l_d,'Enable','Off')
                set(app.l_n,'Enable','Off')
                set(app.z_comp,'Enable','Off')
                set(app.lr_coeff,'Enable','Off')
                set(app.lr_floor,'Enable','Off')
                set(app.l_n_mode,'Enable','Off')
                set(app.nn_max,'Enable','Off')

                set(app.lr_ceiling,'Enable','Off')
                set(app.fovea_sigma,'Enable','Off')
                set(app.sort_mode,'Enable','Off')
                set(app.grid_shape,'Enable','Off')
                set(app.motion_predict,'Enable','Off')
            end
        end

        % Button pushed function: SetbookmarkButton
        function SetbookmarkButtonPushed(app, event)
            bmLabel = ['Bookmark #',int2str(size(app.ListBox.Items,2)+1)];
            bmFrame = app.vreader.CurrentTime;
            app.ListBox.Items = [app.ListBox.Items, bmLabel];
            if size(app.ListBox.ItemsData)>0
                app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
            else
                app.ListBox.ItemsData = [bmFrame];
            end
            
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = round(event.InteractionInformation.Item);
            app.load_t('frames',item);
        end

        % Button pushed function: Up
        function UpPushed(app, event)
            switch app.SelectionButtonGroup.SelectedObject.Text
                case 'Move Neurons'
                    for n = 1:size(app.repo_neurons.neurons,2)
                        app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1)-app.MoveEditField_2.Value;
                    end
        
                    app.DrawNeurons();
                case 'Move Video'
                    
            end
        end

        % Button pushed function: AdjustAlignmentButton_3
        function AdjustAlignmentButton_3Pushed(app, event)
            set(app.AdjustAlignmentPanel,'Visible','On');
        end

        % Button pushed function: SaveResultButton
        function SaveResultButtonPushed(app, event)
            set(app.AdjustAlignmentPanel,'Visible','Off');
        end

        % Button pushed function: Right
        function RightButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2)+app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: Left
        function LeftButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2)-app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: Down
        function DownButtonPushed(app, event)
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1)+app.MoveEditField_2.Value;
            end

            app.DrawNeurons();
        end

        % Button pushed function: FlipHorizontallyButton
        function FlipHorizontallyButtonPushed(app, event)
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Flip all neurons horizontally around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [pos(1), 2*center(2)-pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.DrawNeurons();
        end

        % Button pushed function: FlipVerticallyButton
        function FlipVerticallyButtonPushed(app, event)
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Flip all neurons vertically around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                new_pos = [2*center(1)-pos(1), pos(2), pos(3)];
                app.image_neurons.neurons(n).position = new_pos;
            end
        
            app.DrawNeurons();
        end

        % Button pushed function: CCW
        function CCWButtonPushed(app, event)
            % Find bounding box
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute center of bounding box
            center_x = (min_x + max_x) / 2;
            center_y = (min_y + max_y) / 2;
            
            % Translate all neurons to origin
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) - center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) - center_y;
            end
            
            % Compute rotation matrix
            theta = pi/2; % rotation angle (90 degrees)
            R = [cos(theta) -sin(theta); sin(theta) cos(theta)]; % 2D rotation matrix
            
            % Apply rotation matrix to each position vector
            for n = 1:size(app.repo_neurons.neurons,2)
                position = app.repo_neurons.neurons(n).position(1:2); % extract x and y coordinates
                position = R * position'; % apply rotation matrix
                app.repo_neurons.neurons(n).position(1:2) = position'; % update x and y coordinates
            end
            
            % Translate all neurons back to their original position
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + center_x;
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + center_y;
            end
            
            app.DrawNeurons();
        end

        % Button pushed function: CenterHorizontallyButton
        function CenterHorizontallyButtonPushed(app, event)
            % Find the vertical extent of the neurons
            min_y = Inf; max_y = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(2) < min_y, min_y = pos(2); end
                if pos(2) > max_y, max_y = pos(2); end
            end
            
            % Compute the center of the UIAxes
            center_y = mean(app.UIAxes.YLim);
            
            % Compute the displacement needed to center the neurons vertically
            dy = center_y - (min_y + max_y) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(2) = app.repo_neurons.neurons(n).position(2) + dy;
            end
            
            app.DrawNeurons();
        end

        % Button pushed function: CenterVerticallyButton
        function CenterVerticallyButtonPushed(app, event)
            % Find the horizontal extent of the neurons
            min_x = Inf; max_x = -Inf;
            for n = 1:size(app.repo_neurons.neurons,2)
                pos = app.repo_neurons.neurons(n).position;
                if pos(1) < min_x, min_x = pos(1); end
                if pos(1) > max_x, max_x = pos(1); end
            end
            
            % Compute the center of the UIAxes
            center_x = mean(app.UIAxes.XLim);
            
            % Compute the displacement needed to center the neurons horizontally
            dx = center_x - (min_x + max_x) / 2;
            
            % Translate all neurons by the computed displacement
            for n = 1:size(app.repo_neurons.neurons,2)
                app.repo_neurons.neurons(n).position(1) = app.repo_neurons.neurons(n).position(1) + dx;
            end
            
            app.DrawNeurons();
        end

        % Close request function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureCloseRequest(app, event)
            try
                app.NeuroPAL_IDTraceWindowUIFigure.WindowState = 'normal';
                app.closeBrowser();
            catch
                'No browser detected.'
            end

            app.repo_neurons = app.image_neurons;
            delete(app)
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            % Get the scaling factor from the TransformScale slider
            scale_factor = app.ScaleEditField.Value;
        
            % Find the center of the bounding box
            bbox = [inf, -inf, inf, -inf, inf, -inf];
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                bbox(1) = min(bbox(1), pos(1));
                bbox(2) = max(bbox(2), pos(1));
                bbox(3) = min(bbox(3), pos(2));
                bbox(4) = max(bbox(4), pos(2));
                bbox(5) = min(bbox(5), pos(3));
                bbox(6) = max(bbox(6), pos(3));
            end
            center = [(bbox(1)+bbox(2))/2, (bbox(3)+bbox(4))/2, (bbox(5)+bbox(6))/2];
        
            % Scale all neurons around the center point
            for n = 1:size(app.image_neurons.neurons, 2)
                pos = app.image_neurons.neurons(n).position;
                displacement = pos - center;
                new_displacement = displacement * scale_factor;
                app.image_neurons.neurons(n).position = center + new_displacement;
            end
        
            app.DrawNeurons();
        end

        % Value changed function: MotionSlider
        function MotionSliderValueChanged(app, event)
            app.MotionSlider.Value = round(app.MotionSlider.Value);

            switch app.MotionSlider.Value
                case 0
                    app.l_n.Value = 2.0;
                    app.lr_floor.Value = 0.01;
                    app.z_comp.Value = 0.5;
                    app.lr_ceiling.Value = 0.1;
                    app.lr_coeff.Value = 1.0;
                    app.sort_mode.Value = 'linear';
                case 1
                case 2
                    app.clip_grad.Value = 0.2;
                    app.l_n.Value = 0.1;
                    app.lr_floor.Value = 0.01;
                    app.lr_ceiling.Value = 0.1;
                case 3
                    app.clip_grad.Value = 1;
                    app.l_n_mode.Value = 'norm';
                    app.lr_floor.Value = 0.1;
                    app.z_comp.Value = 4.0;
                    app.fovea_sigma.Value = 10;
                    app.grid_shape.Value = 49;
                    app.lr_ceiling.Value = 0.1;
                    app.motion_predict.Value = 'True';
            end
            
        end

        % Button pushed function: RecommendFramesButton_2
        function RecommendFramesButton_2Pushed(app, event)
            app.closeBrowser();

            try
                try
                    app.zeph_wrapper('recommend_frames');
                catch
                    uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'recommend_frames experienced a code error.','Error');
                end
    
                file_path = fullfile(app.master_path, 'metadata.json');
                data = jsondecode(fileread(file_path));
                keys = fieldnames(data);
                t_ref_keys = startsWith(keys, 't_ref');
                t_ref_fn_keys = startsWith(keys, 't_ref_fn');
                all_keys = [keys(t_ref_keys); keys(t_ref_fn_keys)];
                
                max_num = 0;
                for i = 1:numel(all_keys)
                    key = all_keys{i};
                    num = str2double(regexp(key, 't_ref(\d+)', 'tokens', 'once'));
                    if ~isnan(num) && num > max_num
                        max_num = num;
                        values = data.(key);
                    end
                end
                
                t_frames = unique(values);
    
                d = uiprogressdlg(app.NeuroPAL_IDTraceWindowUIFigure,'Title','Creating bookmarks...','Indeterminate','on');
                for n=1:size(t_frames)
                    bmLabel = ['Recommended Frame #',int2str(n)];
                    bmFrame = t_frames(n);
                    app.ListBox.Items = [app.ListBox.Items, bmLabel];
                    if size(app.ListBox.ItemsData)>0
                        app.ListBox.ItemsData = [app.ListBox.ItemsData, bmFrame];
                    else
                        app.ListBox.ItemsData = [bmFrame];
                    end
                end
                close(d)
            catch
                uiconfirm(app.NeuroPAL_IDTraceWindowUIFigure,'recommend_frames failed.','Error');
            end

            app.startBrowser();
        end

        % Button pushed function: ResetMoveButton
        function ResetMoveButtonPushed(app, event)
            app.repo_neurons.neurons.position = app.image_neurons.neurons.position;
        end

        % Button pushed function: ResetTransformButton
        function ResetTransformButtonPushed(app, event)
            
        end

        % Key press function: NeuroPAL_IDTraceWindowUIFigure
        function NeuroPAL_IDTraceWindowUIFigureKeyPress(app, event)
            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '1' % Toggle the red channel.
                    app.RCheckBox.Value = ~app.RCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '2' % Toggle the green channel.
                    app.GCheckBox.Value = ~app.GCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData(round(app.Slider.Value));

                case 'leftarrow' % Go up in the z stack.
                    if app.Slider.Value > 1
                        zEvent.Value = app.Slider.Value - 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.Slider.Value < app.Slider.Limits(2)
                        zEvent.Value = app.Slider.Value + 1;
                        app.SliderValueChanged(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.UIAxes, 1 + 0.2);

                case 'downarrow' % Zoom out.
                    zoom(app.UIAxes, 1 - 0.2);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.UIAxes.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.UIAxes.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.UIAxes.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.UIAxes,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.UIAxes,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.UIAxes.XLim = [1, size(app.image_data, 2)];
                    app.UIAxes.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                otherwise
                    % Do nothing.
            end

        end

        % Callback function: not associated with a component
        function SaveNeuronIDsButtonPushed(app, event)
            set(app.IDCurrentFramePanel,'Enable','Off');
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuroPAL_IDTraceWindowUIFigure = uifigure('Visible', 'off');
            app.NeuroPAL_IDTraceWindowUIFigure.Name = 'NeuroPAL_ID Trace Window';
            app.NeuroPAL_IDTraceWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureCloseRequest, true);
            app.NeuroPAL_IDTraceWindowUIFigure.KeyPressFcn = createCallbackFcn(app, @NeuroPAL_IDTraceWindowUIFigureKeyPress, true);
            app.GridLayout6 = uigridlayout(app.NeuroPAL_IDTraceWindowUIFigure);
            app.UIAxes = uiaxes(app.GridLayout6);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.GridLayout6);
            app.CentralControlTab = uitab(app.TabGroup);
            app.CentralControlTab.Title = 'Central Control';
            app.Panel_4 = uipanel(app.CentralControlTab);
            app.GridLayout12 = uigridlayout(app.Panel_4);
            app.Panel_8 = uipanel(app.GridLayout12);
            app.GridLayout8 = uigridlayout(app.Panel_8);
            app.SetbookmarkButton = uibutton(app.GridLayout8, 'push');
            app.SetbookmarkButton.ButtonPushedFcn = createCallbackFcn(app, @SetbookmarkButtonPushed, true);
            app.SetbookmarkButton.Enable = 'off';
            app.SetbookmarkButton.Text = 'Set bookmark';
            app.BookmarksPanel = uipanel(app.GridLayout8);
            app.BookmarksPanel.Enable = 'off';
            app.BookmarksPanel.Title = 'Bookmarks';
            app.GridLayout13 = uigridlayout(app.BookmarksPanel);
            app.ListBox = uilistbox(app.GridLayout13);
            app.ListBox.Items = {};
            app.ListBox.Enable = 'off';
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.MotionPanel = uipanel(app.GridLayout8);
            app.MotionPanel.Enable = 'off';
            app.MotionPanel.Title = 'Motion';
            app.GridLayout5 = uigridlayout(app.MotionPanel);
            app.MotionSlider = uislider(app.GridLayout5);
            app.MotionSlider.Limits = [0 3];
            app.MotionSlider.MajorTicks = [0 1 2 3];
            app.MotionSlider.MajorTickLabels = {'None', 'Low', 'Mid', 'High'};
            app.MotionSlider.ValueChangedFcn = createCallbackFcn(app, @MotionSliderValueChanged, true);
            app.MotionSlider.MinorTicks = [];
            app.MotionSlider.Enable = 'off';
            app.Panel_6 = uipanel(app.GridLayout12);
            app.GridLayout29 = uigridlayout(app.Panel_6);
            app.RecommendFramesButton_2 = uibutton(app.GridLayout29, 'push');
            app.RecommendFramesButton_2.ButtonPushedFcn = createCallbackFcn(app, @RecommendFramesButton_2Pushed, true);
            app.RecommendFramesButton_2.Enable = 'off';
            app.RecommendFramesButton_2.Text = 'Recommend Frames';
            app.AdjustAlignmentButton_3 = uibutton(app.GridLayout29, 'push');
            app.AdjustAlignmentButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdjustAlignmentButton_3Pushed, true);
            app.AdjustAlignmentButton_3.Enable = 'off';
            app.AdjustAlignmentButton_3.Text = 'Adjust Alignment';
            app.SaveResultsButton_3 = uibutton(app.GridLayout29, 'push');
            app.SaveResultsButton_3.Enable = 'off';
            app.SaveResultsButton_3.Text = 'Save Results';
            app.RunTracerButton = uibutton(app.GridLayout29, 'push');
            app.RunTracerButton.ButtonPushedFcn = createCallbackFcn(app, @RunTracerButtonPushed2, true);
            app.RunTracerButton.Enable = 'off';
            app.RunTracerButton.Text = 'Run Tracer';
            app.RunTrackButton = uibutton(app.GridLayout29, 'push');
            app.RunTrackButton.ButtonPushedFcn = createCallbackFcn(app, @RunTrackButtonPushed2, true);
            app.RunTrackButton.Enable = 'off';
            app.RunTrackButton.Text = 'Run Track';
            app.RedMaytaketimeLabel = uilabel(app.GridLayout29);
            app.RedMaytaketimeLabel.Visible = 'off';
            app.RedMaytaketimeLabel.Text = 'Red = May take time.';
            app.Panel_16 = uipanel(app.GridLayout12);
            app.GridLayout30 = uigridlayout(app.Panel_16);
            app.Tree = uitree(app.GridLayout30, 'checkbox');
            app.Tree.Enable = 'off';
            app.DataNode = uitreenode(app.Tree);
            app.DataNode.Text = 'Data';
            app.WorldlinesNode = uitreenode(app.Tree);
            app.WorldlinesNode.Text = 'Worldlines';
            app.AnnotationsNode = uitreenode(app.Tree);
            app.AnnotationsNode.Text = 'Annotations';
            app.MetadatajsonNode = uitreenode(app.Tree);
            app.MetadatajsonNode.Text = 'Metadata.json';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.FilesLoadedLabel = uilabel(app.GridLayout30);
            app.FilesLoadedLabel.Enable = 'off';
            app.FilesLoadedLabel.Text = 'Files Loaded';
            app.BasicSettingsTab = uitab(app.TabGroup);
            app.BasicSettingsTab.Title = 'Basic Settings';
            app.GridLayout4 = uigridlayout(app.BasicSettingsTab);
            app.EnableManualSettingsCheckBox = uicheckbox(app.GridLayout4);
            app.EnableManualSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableManualSettingsCheckBoxValueChanged, true);
            app.EnableManualSettingsCheckBox.Enable = 'off';
            app.EnableManualSettingsCheckBox.Text = ' Enable Manual Settings';
            app.Panel_12 = uipanel(app.GridLayout4);
            app.GridLayout2 = uigridlayout(app.Panel_12);
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel = uilabel(app.GridLayout2);
            app.SetsdevicetoGPUandenablesCUDAuseDropDownLabel.Text = 'Sets device to GPU and enables CUDA use.';
            app.use_gpu = uidropdown(app.GridLayout2);
            app.use_gpu.Items = {'True', 'False'};
            app.use_gpu.Editable = 'on';
            app.use_gpu.Enable = 'off';
            app.use_gpu.Value = 'True';
            app.UseGPULabel = uilabel(app.GridLayout2);
            app.UseGPULabel.Text = 'Use GPU';
            app.nn_maxLabel = uilabel(app.GridLayout2);
            app.nn_maxLabel.Tooltip = {'default: 5'};
            app.nn_maxLabel.Text = 'nn_max';
            app.EditFieldLabel_2 = uilabel(app.GridLayout2);
            app.EditFieldLabel_2.Tooltip = {'default: 5'};
            app.EditFieldLabel_2.Text = 'Maximum number of neighboring keypoints to be connected by springs for calculating λN.';
            app.nn_max = uieditfield(app.GridLayout2, 'numeric');
            app.nn_max.ValueDisplayFormat = '%.2f';
            app.nn_max.Enable = 'off';
            app.nn_max.Tooltip = {'default: 5'};
            app.EditField6Label = uilabel(app.GridLayout2);
            app.EditField6Label.Text = 'Coefficient for initial learning rate, multiplied by the distance between current frame and its parent.';
            app.lr_coeff = uieditfield(app.GridLayout2, 'numeric');
            app.lr_coeff.ValueDisplayFormat = '%.2f';
            app.lr_coeff.Enable = 'off';
            app.lr_coeff.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.lr_coeff.Value = 2;
            app.LRCoeffLabel = uilabel(app.GridLayout2);
            app.LRCoeffLabel.Tooltip = {'TIP: If a dynamic learning rate is not necessary for your dataset, set to 0 or lower to disable. This will also skip calculation of distances between frames which can be very computationally costly for long datasets.'};
            app.LRCoeffLabel.Text = 'LR Coeff';
            app.NLabel = uilabel(app.GridLayout2);
            app.NLabel.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.NLabel.Text = 'λN';
            app.EditField4Label = uilabel(app.GridLayout2);
            app.EditField4Label.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.EditField4Label.Text = 'Coefficient for spring constant for intra-keypoint spatial regularization, λN.';
            app.l_n = uieditfield(app.GridLayout2, 'numeric');
            app.l_n.ValueDisplayFormat = '%.2f';
            app.l_n.Enable = 'off';
            app.l_n.Tooltip = {'Spring constants are calculated by multiplying the covariance of connected pairs by this number and passing the result through a ReLU layer. The resulting loss is also rescaled to this value, meaning it cannot exceed this value. If a covariance value is unavailable, the spring constant is set equal to this number. default: 1.0'; ''; 'TIP: Increase up to 10.0 for non-deforming datasets. Decrease down to 0.01 or turn off for large motion/deformation. Optimal value tends to be between 1.0-4.0. Set to 0 or -1 if regularization is unnecessary (this can speed up performance).'};
            app.l_n.Value = 5;
            app.NmodeLabel = uilabel(app.GridLayout2);
            app.NmodeLabel.Tooltip = {'''disp'': use inter-keypoint displacements'; '''norm'': use inter-keypoint distances (rotation is not penalized)'; '''ljp'': use a Lenard-Jones potential on inter-keypoint distances (collapsing onto the same position is highly penalized)'; 'default: ''disp'''};
            app.NmodeLabel.Text = 'λN mode';
            app.MethodtouseforcalculatingNLabel = uilabel(app.GridLayout2);
            app.MethodtouseforcalculatingNLabel.Text = 'Method to use for calculating LN.';
            app.l_n_mode = uidropdown(app.GridLayout2);
            app.l_n_mode.Items = {'disp', 'norm', 'ljp'};
            app.l_n_mode.Editable = 'on';
            app.l_n_mode.Enable = 'off';
            app.l_n_mode.Value = 'disp';
            app.grid_shapeLabel = uilabel(app.GridLayout2);
            app.grid_shapeLabel.Text = 'grid_shape';
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel = uilabel(app.GridLayout2);
            app.SizeoftheimagedescriptorsinthexyplaneinpixelsEditFieldLabel.Text = 'Size of the image descriptors in the xy-plane in pixels.';
            app.grid_shape = uieditfield(app.GridLayout2, 'numeric');
            app.grid_shape.Enable = 'off';
            app.grid_shape.Value = 25;
            app.AdvancedSettingsTab = uitab(app.TabGroup);
            app.AdvancedSettingsTab.Title = 'Advanced Settings';
            app.GridLayout = uigridlayout(app.AdvancedSettingsTab);
            app.EditFieldLabel = uilabel(app.GridLayout);
            app.EditFieldLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.EditFieldLabel.Text = 'Maximum value for gradients for gradient descent. Use -1 to uncap. default: 1.0';
            app.clip_grad = uieditfield(app.GridLayout, 'numeric');
            app.clip_grad.ValueDisplayFormat = '%.2f';
            app.clip_grad.Enable = 'off';
            app.clip_grad.Value = -1;
            app.EditField2Label = uilabel(app.GridLayout);
            app.EditField2Label.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.EditField2Label.Text = 'Coefficient for temporal smoothing loss, λT, enforcing a 0th-order linear fit for intensity over n_frame frames.';
            app.l_t = uieditfield(app.GridLayout, 'numeric');
            app.l_t.ValueDisplayFormat = '%.2f';
            app.l_t.Enable = 'off';
            app.l_t.Value = -1;
            app.EditField3Label = uilabel(app.GridLayout);
            app.EditField3Label.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.EditField3Label.Text = 'Coefficient for feature detection loss, λD. ';
            app.l_d = uieditfield(app.GridLayout, 'numeric');
            app.l_d.ValueDisplayFormat = '%.2f';
            app.l_d.Enable = 'off';
            app.l_d.Value = -1;
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel = uilabel(app.GridLayout);
            app.Minimumvalueforinitiallearningratedefault002EditFieldLabel.Text = 'Minimum value for initial learning rate. default: 0.02';
            app.lr_floor = uieditfield(app.GridLayout, 'numeric');
            app.lr_floor.ValueDisplayFormat = '%.2f';
            app.lr_floor.Enable = 'off';
            app.lr_floor.Value = 0.02;
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel = uilabel(app.GridLayout);
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.Multiplygradientsinthezaxisby1z_compensatorEditFieldLabel.Text = 'Multiply gradients in the z-axis by (1 + z_compensator).';
            app.z_comp = uieditfield(app.GridLayout, 'numeric');
            app.z_comp.ValueDisplayFormat = '%.2f';
            app.z_comp.Enable = 'off';
            app.z_comp.Value = -1;
            app.clip_gradLabel = uilabel(app.GridLayout);
            app.clip_gradLabel.Tooltip = {'TIP: If motion is small, set lower to ~0.1. This is a more aggressive tactic than lr_ceiling.'};
            app.clip_gradLabel.Text = 'clip_grad';
            app.tLabel = uilabel(app.GridLayout);
            app.tLabel.Tooltip = {'TIP: 0.1 generally matches order of magnitude of registration loss. Increase up to 1.0 for non-deforming datasets. Set to 0 or -1 if regularization is unnecessary (this will skip the regularization step entirely and can dramatically speed up performance). Alternatively, setting n_frame to 1 will also disable this.'};
            app.tLabel.Text = 'λt';
            app.dLabel = uilabel(app.GridLayout);
            app.dLabel.Tooltip = {'This regularization is turned on at the last n_epoch_d of each optimization loop with everything else turned off. Set to -1 to disable. default: -1.0'};
            app.dLabel.Text = 'λd';
            app.LRFloorLabel = uilabel(app.GridLayout);
            app.LRFloorLabel.Text = 'LR Floor';
            app.ZCompLabel = uilabel(app.GridLayout);
            app.ZCompLabel.Tooltip = {'Since the internal coordinate system is rescaled from -1 to 1 in all directions, gradients in the z-axis may be too small when there is a large disparity between the xy- and z-shapes of the dataset, and thus fail to track motion in the z-axis. Increasing this will compensate for the disparity. Note that gradients will still be clipped to (clip_grad * z_compensator) if clip_grad is enabled. Set to 0 or -1 to disable. default: -1'};
            app.ZCompLabel.Text = 'Z Comp.';
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel = uilabel(app.GridLayout);
            app.HoveroverlabelsforfurtherdetailsorguidanceLabel.Text = 'Hover over labels for further details or guidance.';
            app.NumberofiterationsforimageregistrationLREditFieldLabel = uilabel(app.GridLayout);
            app.NumberofiterationsforimageregistrationLREditFieldLabel.Text = 'Number of iterations for image registration, LR.';
            app.n_epoch = uieditfield(app.GridLayout, 'numeric');
            app.n_epoch.ValueDisplayFormat = '%.0f';
            app.n_epoch.Enable = 'off';
            app.n_epoch.Value = 40;
            app.EditField2_2Label = uilabel(app.GridLayout);
            app.EditField2_2Label.Text = 'Number of iterations for feature detection regularization, LD.';
            app.n_epoch_d = uieditfield(app.GridLayout, 'numeric');
            app.n_epoch_d.ValueDisplayFormat = '%.0f';
            app.n_epoch_d.Enable = 'off';
            app.n_epoch_d.Value = 10;
            app.n_epochLabel = uilabel(app.GridLayout);
            app.n_epochLabel.Text = 'n_epoch';
            app.n_epoch_dLabel = uilabel(app.GridLayout);
            app.n_epoch_dLabel.Text = 'n_epoch_d';
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofstepstodividetheforwardpassintoEditFieldLabel.Text = 'Number of steps to divide the forward pass into.';
            app.n_chunks = uieditfield(app.GridLayout, 'numeric');
            app.n_chunks.ValueDisplayFormat = '%.0f';
            app.n_chunks.Enable = 'off';
            app.n_chunks.Value = 10;
            app.n_chunksLabel = uilabel(app.GridLayout);
            app.n_chunksLabel.Text = 'n_chunks';
            app.sort_modeLabel = uilabel(app.GridLayout);
            app.sort_modeLabel.Text = 'sort_mode';
            app.motion_predictLabel = uilabel(app.GridLayout);
            app.motion_predictLabel.Text = 'motion_predict';
            app.lr_ceilingLabel = uilabel(app.GridLayout);
            app.lr_ceilingLabel.Text = 'lr_ceiling';
            app.EditFieldLabel_3 = uilabel(app.GridLayout);
            app.EditFieldLabel_3.Text = 'Maximum value for initial learning rate. Note that learning rate decays by a factor of 0.5 every 10 epochs.';
            app.lr_ceiling = uieditfield(app.GridLayout, 'numeric');
            app.lr_ceiling.Enable = 'off';
            app.lr_ceiling.Value = 0.2;
            app.DropDownLabel = uilabel(app.GridLayout);
            app.DropDownLabel.Text = 'Enable parent-child flow field to predict low-freq. motion & initialize new keypoints pos. for current frame.';
            app.motion_predict = uidropdown(app.GridLayout);
            app.motion_predict.Items = {'True', 'False'};
            app.motion_predict.Enable = 'off';
            app.motion_predict.Value = 'False';
            app.DropDown_2Label = uilabel(app.GridLayout);
            app.DropDown_2Label.Text = 'Method for sorting frames and determining parent-child branches.';
            app.sort_mode = uidropdown(app.GridLayout);
            app.sort_mode.Items = {'similarity', 'linear', 'depth'};
            app.sort_mode.Enable = 'off';
            app.sort_mode.Tooltip = {'''similarity'' minimizes distance between parent and child.'; '''linear'' branches out from reference frames linearly forwards and backwards, with every parent-child one frame apart, until it reaches the first frame, last frame, or another branch. Simplest and fastest.'; '''depth'' uses shortest-path grid search, then sorts frames based on depth in the resulting parent-child tree. This can scale up to O(n4) in computation with number of frames.'};
            app.sort_mode.Value = 'similarity';
            app.foveasigmaLabel = uilabel(app.GridLayout);
            app.foveasigmaLabel.Text = 'fovea sigma';
            app.EditFieldLabel_4 = uilabel(app.GridLayout);
            app.EditFieldLabel_4.Text = 'Sigma for Gaussian mask over foveated regions of the descriptors.';
            app.fovea_sigma = uieditfield(app.GridLayout, 'numeric');
            app.fovea_sigma.Enable = 'off';
            app.fovea_sigma.Tooltip = {'Decreasing this can help prioritize keeping a keypoint at the center. Increase to a large number or set at -1 to disable.'};
            app.fovea_sigma.Value = 2.5;
            app.CreditTab = uitab(app.TabGroup);
            app.CreditTab.Title = 'Credit';
            app.GridLayout14 = uigridlayout(app.CreditTab);
            app.Label = uilabel(app.GridLayout14);
            app.Label.Text = 'Tracing relies on a modified version of Zephir, a multiple object tracking algorithm based on image registration and developed by James Yu et al. ZephIR tracks keypoints in a 2D or 3D movie by registering image descriptors sampled around each keypoint. ';
            app.LaboratoryPageButton = uibutton(app.GridLayout14, 'push');
            app.LaboratoryPageButton.ButtonPushedFcn = createCallbackFcn(app, @LaboratoryPageButtonPushed, true);
            app.LaboratoryPageButton.Text = 'Laboratory Page';
            app.ViewPreprintButton = uibutton(app.GridLayout14, 'push');
            app.ViewPreprintButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPreprintButtonPushed, true);
            app.ViewPreprintButton.Text = 'View Preprint';
            app.GithubRepositoryButton = uibutton(app.GridLayout14, 'push');
            app.GithubRepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @GithubRepositoryButtonPushed, true);
            app.GithubRepositoryButton.Text = 'Github Repository';
            app.Image2 = uiimage(app.GridLayout14);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'External_Dependencies', 'zephir', 'zephir-logo.png');
            app.CitationTextAreaLabel = uilabel(app.GridLayout14);
            app.CitationTextAreaLabel.Text = 'Citation';
            app.CitationTextArea = uitextarea(app.GridLayout14);
            app.CitationTextArea.Value = {'Versatile Multiple Object Tracking in Sparse 2D/3D Videos Via Diffeomorphic Image Registration'; 'James Yu, Amin Nejatbakhsh, Mahdi Torkashvand, Sahana Gangadharan, Maedeh Seyedolmohadesin, Jinmahn Kim, Liam Paninski, Vivek Venkatachalam bioRxiv 2022.07.18.500485; doi: https://doi.org/10.1101/2022.07.18.500485'; 'This article is a preprint and has not been certified by peer review'};
            app.NeuroPALVolumePanel = uipanel(app.GridLayout6);
            app.NeuroPALVolumePanel.Title = 'NeuroPAL Volume';
            app.GridLayout7 = uigridlayout(app.NeuroPALVolumePanel);
            app.Npal_Axes = uiaxes(app.GridLayout7);
            app.Npal_Axes.XTickLabel = '';
            app.Npal_Axes.YTick = [0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Npal_Axes.YTickLabel = '';
            app.ClicktoselectvideofileButton = uibutton(app.GridLayout6, 'push');
            app.ClicktoselectvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoselectvideofileButtonPushed, true);
            app.ClicktoselectvideofileButton.Text = 'Click to select video file.';
            app.AdjustAlignmentPanel = uipanel(app.NeuroPAL_IDTraceWindowUIFigure);
            app.AdjustAlignmentPanel.Title = 'Adjust Alignment';
            app.AdjustAlignmentPanel.Visible = 'off';
            app.GridLayout15 = uigridlayout(app.AdjustAlignmentPanel);
            app.TransformPanel = uipanel(app.GridLayout15);
            app.TransformPanel.Title = 'Transform';
            app.GridLayout17 = uigridlayout(app.TransformPanel);
            app.HeightSpinnerLabel = uilabel(app.GridLayout17);
            app.HeightSpinnerLabel.Text = 'Height';
            app.HeightSpinner = uispinner(app.GridLayout17);
            app.WidthSpinnerLabel = uilabel(app.GridLayout17);
            app.WidthSpinnerLabel.Text = 'Width';
            app.WidthSpinner = uispinner(app.GridLayout17);
            app.ScaleEditFieldLabel = uilabel(app.GridLayout17);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.GridLayout17, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ResetMoveButton = uibutton(app.GridLayout15, 'push');
            app.ResetMoveButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMoveButtonPushed, true);
            app.ResetMoveButton.Text = 'Reset Move';
            app.SaveResultButton = uibutton(app.GridLayout15, 'push');
            app.SaveResultButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultButtonPushed, true);
            app.SaveResultButton.Text = 'Save Result';
            app.ResetTransformButton = uibutton(app.GridLayout15, 'push');
            app.ResetTransformButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTransformButtonPushed, true);
            app.ResetTransformButton.Enable = 'off';
            app.ResetTransformButton.Text = 'Reset Transform';
            app.MovePanel = uipanel(app.GridLayout15);
            app.MovePanel.Title = 'Move';
            app.GridLayout16 = uigridlayout(app.MovePanel);
            app.FlipHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontallyButtonPushed, true);
            app.FlipHorizontallyButton.Text = 'Flip Horizontally';
            app.FlipVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.FlipVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticallyButtonPushed, true);
            app.FlipVerticallyButton.Text = 'Flip Vertically';
            app.CenterVerticallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterVerticallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterVerticallyButtonPushed, true);
            app.CenterVerticallyButton.Text = 'Center Vertically';
            app.CenterHorizontallyButton = uibutton(app.GridLayout16, 'push');
            app.CenterHorizontallyButton.ButtonPushedFcn = createCallbackFcn(app, @CenterHorizontallyButtonPushed, true);
            app.CenterHorizontallyButton.Text = 'Center Horizontally';
            app.Panel_13 = uipanel(app.GridLayout16);
            app.GridLayout18 = uigridlayout(app.Panel_13);
            app.pixelsatatimeLabel = uilabel(app.GridLayout18);
            app.pixelsatatimeLabel.Text = 'pixels at a time.';
            app.MoveEditField_2 = uieditfield(app.GridLayout18, 'numeric');
            app.MoveEditField_2.ValueDisplayFormat = '%.0f';
            app.MoveEditField_2.Value = 5;
            app.MoveLabel = uilabel(app.GridLayout18);
            app.MoveLabel.Text = 'Move ';
            app.Panel_15 = uipanel(app.GridLayout16);
            app.GridLayout27 = uigridlayout(app.Panel_15);
            app.CW = uibutton(app.GridLayout27, 'push');
            app.CW.Text = '↻';
            app.CCW = uibutton(app.GridLayout27, 'push');
            app.CCW.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCW.Text = '↺';
            app.Down = uibutton(app.GridLayout27, 'push');
            app.Down.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.Down.Text = '⬇️';
            app.Right = uibutton(app.GridLayout27, 'push');
            app.Right.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.Right.Text = '➡️';
            app.Up = uibutton(app.GridLayout27, 'push');
            app.Up.ButtonPushedFcn = createCallbackFcn(app, @UpPushed, true);
            app.Up.Text = '⬆️';
            app.Left = uibutton(app.GridLayout27, 'push');
            app.Left.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.Left.Text = '⬅️';
            app.SelectionButtonGroup = uibuttongroup(app.GridLayout15);
            app.SelectionButtonGroup.Title = 'Selection';
            app.MoveNeuronsButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveNeuronsButton.Text = 'Move Neurons';
            app.MoveNeuronsButton.Value = true;
            app.MoveVideoButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveVideoButton.Text = 'Move Video';
            app.MoveVolumeNeuronsButton = uiradiobutton(app.SelectionButtonGroup);
            app.MoveVolumeNeuronsButton.Text = 'Move Volume & Neurons';
            % Show the figure after all components are created
            app.NeuroPAL_IDTraceWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace_window(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroPAL_IDTraceWindowUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroPAL_IDTraceWindowUIFigure)
        end
    end
end