classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID                         matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenMenu                        matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        PreprocessingMenu               matlab.ui.container.Menu
        ArtifactRemovalMenu             matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        AutomaticMenu                   matlab.ui.container.Menu
        DownsampleImageMenu             matlab.ui.container.Menu
        ThresholdImageMenu              matlab.ui.container.Menu
        HistogramMatchingMenu           matlab.ui.container.Menu
        AutoHistogramMenu               matlab.ui.container.Menu
        ImageMenu                       matlab.ui.container.Menu
        NormalizeColorsMenu             matlab.ui.container.Menu
        ColorRegisterMenu               matlab.ui.container.Menu
        AdjustGammaMenu                 matlab.ui.container.Menu
        AllChannels                     matlab.ui.container.Menu
        IndividualChannels              matlab.ui.container.Menu
        AdjustBrightnessMenu            matlab.ui.container.Menu
        AdjustHistogramMenu             matlab.ui.container.Menu
        RotateAllMenu                   matlab.ui.container.Menu
        RotateAllHorizontalMenu         matlab.ui.container.Menu
        RotateAllVerticalMenu           matlab.ui.container.Menu
        RotateAllClockwiseMenu          matlab.ui.container.Menu
        RotateAllAntiClockwiseMenu      matlab.ui.container.Menu
        RotateImageMenu                 matlab.ui.container.Menu
        RotateImageHorizontalMenu       matlab.ui.container.Menu
        RotateImageVerticalMenu         matlab.ui.container.Menu
        RotateImageClockwiseMenu        matlab.ui.container.Menu
        RotateImageAntiClockwiseMenu    matlab.ui.container.Menu
        RotateNeuronsMenu               matlab.ui.container.Menu
        RotateNeuronsHorizontalMenu     matlab.ui.container.Menu
        RotateNeuronsVerticalMenu       matlab.ui.container.Menu
        RotateNeuronsClockwiseMenu      matlab.ui.container.Menu
        RotateNeuronsAntiClockwiseMenu  matlab.ui.container.Menu
        MirrorImageMenu                 matlab.ui.container.Menu
        MirrorNeuronsMenu               matlab.ui.container.Menu
        NeuronsMenu                     matlab.ui.container.Menu
        ImportneuronsfromMenu           matlab.ui.container.Menu
        CSVfileMenu                     matlab.ui.container.Menu
        NWBfileMenu                     matlab.ui.container.Menu
        ImportactivityfromMenu          matlab.ui.container.Menu
        h5fileMenu                      matlab.ui.container.Menu
        NWBfileMenu_2                   matlab.ui.container.Menu
        ZephiroutputMenu                matlab.ui.container.Menu
        FlipLRNeuronsMenu               matlab.ui.container.Menu
        AutoSegmentationMenu            matlab.ui.container.Menu
        NeuronActivityMenu              matlab.ui.container.Menu
        TraceActivityMenu_2             matlab.ui.container.Menu
        ActivityBrowserMenu             matlab.ui.container.Menu
        DisplayNeuronActivityMenu       matlab.ui.container.Menu
        BatchDetectIDNeuronsMenu        matlab.ui.container.Menu
        DeleteUserIDsMenu               matlab.ui.container.Menu
        DeleteModelIDsMenu              matlab.ui.container.Menu
        ToggleAutoIDUpdatesMenu         matlab.ui.container.Menu
        ToggleNeuronDetectionMenu       matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        ToggleBirthTimesMenu            matlab.ui.container.Menu
        NeuronDotsMenu                  matlab.ui.container.Menu
        GFPColorMenu                    matlab.ui.container.Menu
        AnalysisMenu                    matlab.ui.container.Menu
        SaveIDInfoMenu                  matlab.ui.container.Menu
        SaveIDImageMenu                 matlab.ui.container.Menu
        SaveNWBMenu                     matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        CheckforUpdatesMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        KeyboardShortcutsMenu           matlab.ui.container.Menu
        NeuroPALWebsiteMenu             matlab.ui.container.Menu
        ReportaBugMenu                  matlab.ui.container.Menu
        ActivityBrowser                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        ActivityTrace                   matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        ImageTab                        matlab.ui.container.Tab
        XYPanel                         matlab.ui.container.Panel
        XY                              matlab.ui.control.UIAxes
        AutoNameCheckBox                matlab.ui.control.CheckBox
        ColorsLabel                     matlab.ui.control.Label
        NeuronRankedConfidenceLabel     matlab.ui.control.Label
        NextNeuronDropDown              matlab.ui.control.DropDown
        NextNeuronLabel                 matlab.ui.control.Label
        SaveLabel                       matlab.ui.control.Label
        NotesEditField                  matlab.ui.control.EditField
        NotesEditFieldLabel             matlab.ui.control.Label
        StrainEditField                 matlab.ui.control.EditField
        StrainEditFieldLabel            matlab.ui.control.Label
        SexDropDown                     matlab.ui.control.DropDown
        SexLabel                        matlab.ui.control.Label
        AgeDropDown                     matlab.ui.control.DropDown
        AgeDropDownLabel                matlab.ui.control.Label
        BodyDropDown                    matlab.ui.control.DropDown
        BodyLabel                       matlab.ui.control.Label
        ColorAtlasCheckBox              matlab.ui.control.CheckBox
        ColorAtlasCheckBoxLabel         matlab.ui.control.Label
        ZCenterEditField                matlab.ui.control.NumericEditField
        CenterZLabel                    matlab.ui.control.Label
        FlipZButton                     matlab.ui.control.Button
        ZRightLabel                     matlab.ui.control.Label
        ZLeftLabel                      matlab.ui.control.Label
        ZAxisDropDown                   matlab.ui.control.DropDown
        UserLabel                       matlab.ui.control.Label
        AutoIDButton                    matlab.ui.control.Button
        SaveIDsButton                   matlab.ui.control.Button
        GFPDropDown                     matlab.ui.control.DropDown
        GFPCheckBox                     matlab.ui.control.CheckBox
        GFPLabel                        matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        DICDropDown                     matlab.ui.control.DropDown
        DICCheckBox                     matlab.ui.control.CheckBox
        DICLabel                        matlab.ui.control.Label
        ClicksLabel                     matlab.ui.control.Label
        AutoLabel                       matlab.ui.control.Label
        WDropDown                       matlab.ui.control.DropDown
        WCheckBox                       matlab.ui.control.CheckBox
        WLabel                          matlab.ui.control.Label
        BDropDown                       matlab.ui.control.DropDown
        BCheckBox                       matlab.ui.control.CheckBox
        BLabel                          matlab.ui.control.Label
        GDropDown                       matlab.ui.control.DropDown
        GCheckBox                       matlab.ui.control.CheckBox
        GLabel                          matlab.ui.control.Label
        RDropDown                       matlab.ui.control.DropDown
        RCheckBox                       matlab.ui.control.CheckBox
        RLabel                          matlab.ui.control.Label
        GanglionNeuronIDLabel           matlab.ui.control.Label
        GanglionTree                    matlab.ui.container.Tree
        PharynxNode                     matlab.ui.container.TreeNode
        AnteriorNode                    matlab.ui.container.TreeNode
        PosteriorNode                   matlab.ui.container.TreeNode
        HeadNode                        matlab.ui.container.TreeNode
        AnteriorLNode                   matlab.ui.container.TreeNode
        AnteriorRNode                   matlab.ui.container.TreeNode
        DorsalNode                      matlab.ui.container.TreeNode
        LateralLNode                    matlab.ui.container.TreeNode
        LateralRNode                    matlab.ui.container.TreeNode
        VentralNode                     matlab.ui.container.TreeNode
        RetroVesicularNode              matlab.ui.container.TreeNode
        MidbodyNode                     matlab.ui.container.TreeNode
        AnteriorNode_2                  matlab.ui.container.TreeNode
        CentralNode                     matlab.ui.container.TreeNode
        PosteriorNode_2                 matlab.ui.container.TreeNode
        VentralCordNode                 matlab.ui.container.TreeNode
        TailNode                        matlab.ui.container.TreeNode
        PreAnalNode                     matlab.ui.container.TreeNode
        DorsoRectalNode                 matlab.ui.container.TreeNode
        LumbarLNode                     matlab.ui.container.TreeNode
        LumbarRNode                     matlab.ui.container.TreeNode
        UnIDdNeuronsListBox             matlab.ui.control.ListBox
        UnIDdLabel                      matlab.ui.control.Label
        IDdNeuronsListBox               matlab.ui.control.ListBox
        IDdLabel                        matlab.ui.control.Label
        AutoNameLabel                   matlab.ui.control.Label
        MouseClickDropDown              matlab.ui.control.DropDown
        NeuronsLabel                    matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        AutoIDAllButton                 matlab.ui.control.Button
        AutoDetectButton                matlab.ui.control.Button
        UserNeuronIDsListBox            matlab.ui.control.ListBox
        UserNeuronIDsListBoxLabel       matlab.ui.control.Label
        UserIDButton                    matlab.ui.control.Button
        AutoIDDropDown                  matlab.ui.control.DropDown
        ZSlider                         matlab.ui.control.Slider
        ZSliderLabel                    matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        MaxProjection                   matlab.ui.control.UIAxes
    end


    properties (Access = private)

        %% Program info.

        % The log of user activity for code evaluation/optimization.
        log

        % Version info.
        version = Program.ProgramInfo.version;

        % Are we using the parallelization toolbox?
        is_parallel = true;
        parallel_pool;


        %% Image info.

        % The default parameters for the program & matching pursuit.
        params
        mp_params

        % File locations for the open image.
        image_file = [];
        id_file = [];

        % The image preferences for loading/saving.
        image_prefs = [];

        % The image info
        image_info = [];

        % Worm information.
        worm = [];
        neuron_info = Neurons.Hermaphrodite;

        % The open image of the worm.
        % A pixel matrix of (x,y,z, color channel).
        image_name
        image_data
        image_data_zscored = [];
        image_view % a subset of the image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)
        image_um_scale = [1,1,1]; % image micron scale (x,y,z)
        image_gamma = ones(6, 1); % the gamma adjustment for the image
        gamma_RGBW_DIC_GFP_index = 1:6; % the indices for gamma values

        % Reconstructed image from gaussian approximations of neuron positions, shapes, & colors.
        % A reconstructed pixel matrix of (x,y,z, color channel).
        recon
        recon_view  % a subset of the reconstructed image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)

        % The neurons in the image.
        image_neurons

        % Which neuron is selected.
        selected_neuron

        % Mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        mouse_clicked;

        % Zoom percent for the keyboard shortcut.
        zoom_percent = 0.2;

        % Toggle image panning.
        is_panning = false;

        % Mouse position correction (the cursor is offset incorrectly).
        mouse_offset = [-1,1];


        %% Neuron info.

        % Neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        neuron_marker;

        % Hermaphrodite and male neuron info.
        hermaphrodite_neurons;
        male_neurons;

        % Toggle neuron labels.
        show_labels = false;
        label_color = [1,1,0];
        label_fontsize = 12;
        label_fontweight = 'bold';

        % Are we opening a file?
        is_opening_file = false;

        % Are we saving a user ID?
        is_saving_ID = false;

        neuron_activity;
        neuron_activity_by_name;
        activity_var;
        movie_var;
    end

    methods (Access = private)

        %% Initialize the mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        function InitClickStates(app)
            app.mouse_clicked.double_click_delay = 0.3;
            app.mouse_clicked.click = false;
        end


        %% Initialize the neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        function InitNeuronMarker(app)
            app.neuron_marker.shape = 'c';
            app.neuron_marker.color.edge = [0,0,0];
        end


        %% No image loaded, disable all image GUI functionality.
        function DisableImageGUI(app)

            % Disable the menu items.
            app.ImageMenu.Enable = 'off';
            app.PreprocessingMenu.Enable = 'off';

            % Disable the worm info.
            app.BodyDropDown.Enable = 'off';
            app.AgeDropDown.Enable = 'off';
            app.SexDropDown.Enable = 'off';
            app.StrainEditField.Enable = 'off';
            app.NotesEditField.Enable = 'off';

            % Disable the color channels.
            app.RCheckBox.Enable = 'off';
            app.GCheckBox.Enable = 'off';
            app.BCheckBox.Enable = 'off';
            app.WCheckBox.Enable = 'off';
            app.DICCheckBox.Enable = 'off';
            app.GFPCheckBox.Enable = 'off';
            app.RDropDown.Enable = 'off';
            app.GDropDown.Enable = 'off';
            app.BDropDown.Enable = 'off';
            app.WDropDown.Enable = 'off';
            app.DICDropDown.Enable = 'off';
            app.GFPDropDown.Enable = 'off';

            % Disable the neuron detection info.
            app.AutoDetectButton.Enable = 'off';
            app.MouseClickDropDown.Enable = 'off';

            % Disable the image info.
            app.ZSlider.Enable = 'off';
            app.ZAxisDropDown.Enable = 'off';
            app.FlipZButton.Enable = 'off';
            app.ZCenterEditField.Enable = 'off';

            % Disable the neuron info.
            DisableNeuronGUI(app);
        end


        %% Image loaded, enable all image GUI functionality.
        function EnableImageGUI(app)

            % Enable the menu items.
            app.ImageMenu.Enable = 'on';
            app.PreprocessingMenu.Enable = 'on';

            % Enable the worm info.
            app.BodyDropDown.Enable = 'on';
            app.AgeDropDown.Enable = 'on';
            app.SexDropDown.Enable = 'on';
            app.StrainEditField.Enable = 'on';
            app.NotesEditField.Enable = 'on';

            % Enable the color channels.
            app.RCheckBox.Enable = 'on';
            app.GCheckBox.Enable = 'on';
            app.BCheckBox.Enable = 'on';
            app.WCheckBox.Enable = 'on';
            app.DICCheckBox.Enable = 'on';
            app.GFPCheckBox.Enable = 'on';
            app.RDropDown.Enable = 'on';
            app.GDropDown.Enable = 'on';
            app.BDropDown.Enable = 'on';
            app.WDropDown.Enable = 'on';
            app.DICDropDown.Enable = 'on';
            app.GFPDropDown.Enable = 'on';

            % Enable the neuron detection info.
            app.AutoDetectButton.Enable = 'on';
            app.MouseClickDropDown.Enable = 'on';

            % Enable the image info.
            app.ZSlider.Enable = 'on';
            app.ZAxisDropDown.Enable = 'on';
            app.FlipZButton.Enable = 'on';
            app.ZCenterEditField.Enable = 'on';
        end


        %% No neurons present, disable all neuron GUI functionality.
        function DisableNeuronGUI(app)

            % Disable the menu items.
            app.AnalysisMenu.Enable = 'off';
            app.RotateImageMenu.Enable = 'off';
            app.RotateNeuronsMenu.Enable = 'off';
            app.DeleteUserIDsMenu.Enable = 'off';
            app.DeleteModelIDsMenu.Enable = 'off';
            app.SaveIDImageMenu.Enable = 'off';

            % Disable the neuron info.
            app.SaveIDsButton.Enable = 'off';
            app.AutoIDAllButton.Enable = 'off';
            app.AutoIDButton.Enable = 'off';
            app.UserIDButton.Enable = 'off';
            app.ColorAtlasCheckBox.Enable = 'off';
            app.NextNeuronDropDown.Enable = 'off';
            app.UserNeuronIDsListBox.Enable = 'off';
        end


        %% Neurons present, enable all neuron GUI functionality.
        function EnableNeuronGUI(app)

            % Enable the menu items.
            app.AnalysisMenu.Enable = 'on';
            app.RotateImageMenu.Enable = 'on';
            app.RotateNeuronsMenu.Enable = 'on';
            app.DeleteUserIDsMenu.Enable = 'on';
            app.DeleteModelIDsMenu.Enable = 'on';
            app.SaveIDImageMenu.Enable = 'on';

            % Enable the neuron info.
            app.SaveIDsButton.Enable = 'on';
            app.AutoIDAllButton.Enable = 'on';
            app.AutoIDButton.Enable = 'on';
            app.UserIDButton.Enable = 'on';
            app.ColorAtlasCheckBox.Enable = 'on';
            app.NextNeuronDropDown.Enable = 'on';
            app.UserNeuronIDsListBox.Enable = 'on';
        end


        %% Draw the annotated image (image volume & neuron markers).
        function DrawImageData(app)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Determine the color channel indices.
            red = str2double(app.RDropDown.Value);
            green = str2double(app.GDropDown.Value);
            blue = str2double(app.BDropDown.Value);
            white = str2double(app.WDropDown.Value);
            dic = str2double(app.DICDropDown.Value);
            gfp = str2double(app.GFPDropDown.Value);

            % Determine the channel=color assignments for displaying.
            color_indices = [red, green, blue];

            % Draw the 3 color channels.
            app.image_view = app.image_data(:,:,:,color_indices);

            % Remove unchecked color channels.
            if ~app.RCheckBox.Value % Red
                app.image_view(:,:,:,1) = 0;
            end
            if ~app.GCheckBox.Value % Green
                app.image_view(:,:,:,2) = 0;
            end
            if ~app.BCheckBox.Value % Blue
                app.image_view(:,:,:,3) = 0;
            end

            % Add in the white channel.
            if app.WCheckBox.Value % White

                % Compute the white channel.
                wchannel = app.image_data(:,:,:,white);

                % Adjust the gamma.
                W_i = app.gamma_RGBW_DIC_GFP_index(4); % white channel gamma index
                if length(app.image_gamma) >= W_i && app.image_gamma(W_i) ~= 1
                    wchannel = imadjustn(wchannel,[],[], app.image_gamma(W_i));
                end

                % Add the white channel.
                app.image_view = app.image_view + repmat(wchannel, [1,1,1,3]);
            end

            % Add in the DIC channel.
            if app.DICCheckBox.Value % DIC

                % Compute the DIC channel.
                dic_channel = app.image_data(:,:,:,dic);

                % Adjust the gamma.
                DIC_i = app.gamma_RGBW_DIC_GFP_index(5); % DIC channel gamma index
                if length(app.image_gamma) >= DIC_i && app.image_gamma(DIC_i) ~= 1
                    dic_channel = imadjustn(dic_channel,[],[], app.image_gamma(DIC_i));
                end

                % Add the DIC channel.
                app.image_view = app.image_view + repmat(dic_channel, [1,1,1,3]);
            end

            % Add in the GFP channel.
            if app.GFPCheckBox.Value % GFP

                % Compute the GFP channel.
                gfp_color = Program.GUIPreferences.instance().GFP_color;
                gfp_channel = app.image_data(:,:,:,gfp);

                % Adjust the gamma.
                GFP_i = app.gamma_RGBW_DIC_GFP_index(6); % GFP channel gamma index
                if length(app.image_gamma) >= GFP_i && app.image_gamma(GFP_i) ~= 1
                    gfp_channel = imadjustn(gfp_channel,[],[], app.image_gamma(GFP_i));
                end

                % Add the GFP channel.
                gfp_channel = repmat(gfp_channel, [1,1,1,3]);
                gfp_channel(:,:,:,~gfp_color) = 0;
                app.image_view = app.image_view + gfp_channel;
            end

            % Adjust the gamma.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            app.image_view = uint16(double(intmax('uint16')) * ...
                double(app.image_view)/double(max(app.image_view(:))));
            for c = 1:size(app.image_view, 4)
                if app.image_gamma(c) ~= 1
                    app.image_view(:,:,:,c) = ...
                        imadjustn(squeeze(app.image_view(:,:,:,c)),[],[], ...
                        app.image_gamma(c));
                end
            end
            app.image_view = double(app.image_view)/double(max(app.image_view(:)));

            % Redraw the max projection.
            % Note: the image only shows RGB. We added the other channels
            % (W, DIC, GFP) to the RGB in order to show these as well.
            image(app.MaxProjection, squeeze(max(app.image_view,[],3)));

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end


        %% Draw the neurons in this z-slice.
        function DrawZSlice(app, slider, view, ax)

            % Sanity check the Z slice value.
            z = round(app.ZSlider.Value);
            app.ZSlider.Value = z;

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % Flip the Z-axis.
            z_num = z;
            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end

            % Where are we in Z?
            background_color = [0.94,0.94,0.94];
            LD_color = [0,1,1]; % left or dorsal color
            RV_color = [1,0,1]; % right or ventral color
            z_center_thresh = 1; % center +/- 1 slice
            z_center = app.image_prefs.z_center;
            if z_num >= z_center - z_center_thresh && z_num <= z_center + z_center_thresh
                app.XYPanel.BackgroundColor = background_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = background_color;
            elseif z_num < z_center - z_center_thresh
                app.XYPanel.BackgroundColor = LD_color;
                app.ZLeftLabel.BackgroundColor = LD_color;
                app.ZRightLabel.BackgroundColor = background_color;
            else % if z > z_center + z_center_thresh
                app.XYPanel.BackgroundColor = RV_color;
                app.ZLeftLabel.BackgroundColor = background_color;
                app.ZRightLabel.BackgroundColor = RV_color;
            end

            % Clear the contents of the axis to draw the new Z-slice.
            cla(ax);
            % Create the slice at z for displaying in the axis.
            xy = squeeze(view(:,:,z,:,:));
            % Display the current slice in the XY axis.
            gui_image = image(xy, 'Parent', ax); hold(ax, 'on');
            % Add the AddNeuron function as mouse click listener.
            gui_image.ButtonDownFcn = {@app.ImageClicked};

            % Redraw the neurons in this z slice.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Which neurons belong in this z-slice?
                neuron_locations = app.image_neurons.get_positions();
                neuron_marker_colors = app.image_neurons.get_marker_colors();
                neuron_marker_sizes = app.image_neurons.get_marker_sizes();
                neuron_line_size = Program.GUIPreferences.instance().neuron_dot.line;

                % Since z (which is found by MP) can be a continuous value
                % to find all the neurons in the current slice we find the
                % ones that lie in the interval [z-z_dot_view, z+z_dot_view]. Finally we
                % change the x and y dimension to fix the inconsistent
                % behavior of Matlab figures for scatter and image function.
                z_dot_view = 1.5;
                current_z_indices = neuron_locations(:,3)>z-z_dot_view & neuron_locations(:,3)<z+z_dot_view;
                positions = neuron_locations(current_z_indices, 1:2);

                % Draw the neuron markers.
                neuron_marker_plot = scatter(ax, positions(:, 2), positions(:, 1), ...
                    neuron_marker_sizes(current_z_indices), ...
                    neuron_marker_colors(current_z_indices, :), ...
                    'filled', 'MarkerEdgeColor', app.neuron_marker.color.edge, ...
                    'LineWidth', neuron_line_size);

                % Are we showing the neuron annotations?
                if app.show_labels

                    % Get the labels.
                    labels = app.image_neurons.get_annotations();
                    labels = labels(current_z_indices);

                    % Get the ON/OFF annotations.
                    is_on = app.image_neurons.get_is_annotations_on();
                    is_on = is_on(current_z_indices);

                    % Get the confidences.
                    confidences = app.image_neurons.get_annotation_confidences();
                    confidences = confidences(current_z_indices);

                    % Get the emphasized neurons.
                    is_emphasized = app.image_neurons.get_is_emphasized();
                    is_emphasized = is_emphasized(current_z_indices);

                    % Remove empty labels.
                    is_label = ~cellfun('isempty', labels);
                    labels = labels(is_label);
                    is_on = is_on(is_label);
                    confidences = confidences(is_label);
                    is_emphasized = is_emphasized(is_label);

                    % Add ON/OFF & confidence to the labels.
                    for i = 1:length(confidences)

                        % Is the neuron ON/OFF.
                        switch is_on(i)
                            case false
                                labels{i} = [labels{i} '-OFF'];
                            case true
                                labels{i} = [labels{i} '-ON'];
                        end

                        % Is the user uncertain about the ID?
                        if confidences(i) <= 0.5
                            labels{i} = [labels{i} '?'];
                        end

                        % Is the neuron emphasized?
                        if is_emphasized(i)
                            labels{i} = [labels{i} '!'];
                        end
                    end

                    % Draw the labels.
                    DrawImageLabels(app, positions(is_label,:), labels);
                end

                % Setup the mouse-click callback.
                neuron_marker_plot.ButtonDownFcn = {@app.NeuronClicked};
            end

            % Draw the color atlas.
            if app.ColorAtlasCheckBox.Value

                % Do we have the atlas info?
                if isempty(app.image_neurons.get_aligned_xyzRGBs())

                    % Uncheck the atlas.
                    app.ColorAtlasCheckBox.Value = false;

                    % Warn the user.
                    uialert(app.CELL_ID, ...
                        'Please press "Auto-ID All" to create the neuron ID atlas!', ...
                        'No Atlas', 'Icon', 'warning');

                    % Draw the neuron ID atlas.
                else
                    Methods.AutoId.instance().visualize(...
                        app.image_neurons, app.worm, 'ax', app.XY, 'z', z);
                end
            end
        end


        %% Draw text labels on the image.
        function DrawImageLabels(app, positions, labels, varargin)

            % Are the left & right labels pre-determined?
            left_i = [];
            if ~isempty(varargin)
                left_i = varargin{1};
            end

            % Determine the left & right labels.
            if isempty(left_i)
                center_y = size(app.image_view, 2) / 2;
                left_i = positions(:,2) <= center_y;
            end
            right_i = ~left_i;

            % Draw the labels on the left side.
            if sum(left_i) > 0
                left_pos = positions(left_i,1:2);
                left_pos(:,2) = left_pos(:,2) + 4;
                left_labels = cellfun(@(x) cat(2, '\leftarrow', x), labels(left_i), 'UniformOutput', false);
                text(app.XY, left_pos(:,2), left_pos(:,1), left_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'left');
            end

            % Draw the labels on the right side.
            if sum(right_i) > 0
                right_pos = positions(right_i,1:2);
                right_pos(:,2) = right_pos(:,2) - 4;
                right_labels = cellfun(@(x) cat(2, x, '\rightarrow'), labels(right_i), 'UniformOutput', false);
                text(app.XY, right_pos(:,2), right_pos(:,1), right_labels, ...
                    'Color', app.label_color, 'FontSize', app.label_fontsize, ...
                    'FontWeight', app.label_fontweight, 'HorizontalAlignment', 'right');
            end
        end


        %% The image was clicked. Add a neuron (on middle or double-click) or delete image artifacts (if selected).
        function ImageClicked(app, image_neurons, hit)
            double_click = false; % did the user double-click?

            switch hit.Button
                case 1
                    % Check for a double click.
                    if app.mouse_clicked.click
                        %disp('double click');
                        double_click = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            %disp('single click');
                        end
                    end
                    app.mouse_clicked.click = false;

                case 2
                    double_click = true;
            end

            % Add a neuron where the user clicked.
            if double_click
                switch app.MouseClickDropDown.Value
                    case app.MouseClickDropDown.Items{1} % Add a neuron where the user clicked.
                        app.image_neurons.add_meta_data('auto_detect', false);
                        AddNeuron(app, hit);

                    case app.MouseClickDropDown.Items{2} % Auto detect a neuron where the user clicked.
                        app.image_neurons.add_meta_data('auto_detect', true);
                        AddNeuron(app, hit);
                end
            end
        end


        %% Add a neuron to a z-slice.
        function AddNeuron(app, hit)
            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2)) + app.mouse_offset;

            % Read the z slice from the ZSlider.
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            % Create & add the neuron.
            app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                point, app.mp_params.hnsz, app.image_um_scale');

            % Save the updated IDs.
            SaveIDToFile(app);

            % Redraw the image.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end
        end


        %% Select or delete neurons where the user clicked.
        function NeuronClicked(app, image_neurons, hit)
            select_neuron = false; % select the neuron where the user clicked?
            delete_neuron = false; % delete the neuron where the user clicked?

            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2));

            % Where are we in Z?
            z = getZSlice(app, round(app.ZSlider.Value));
            point = [mouse_pos, z];

            % Find the closest neuron to the mouse click.
            positions = app.image_neurons.get_positions();
            [~, neuron_i] = min(mean((positions-point).^2,2));

            switch hit.Button
                case 1 % left click

                    % Check for a double click.
                    if app.mouse_clicked.click
                        delete_neuron = true;

                        % Single click.
                    else
                        app.mouse_clicked.click = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.click
                            select_neuron = true;
                        end
                    end
                    app.mouse_clicked.click = false;

                case 3 % right click
                    delete_neuron = true;
            end

            % Select the neuron.
            if select_neuron
                SelectNeuron(app, neuron_i);

                % Delete the neuron from the list.
            elseif delete_neuron
                DeleteNeuron(app, neuron_i);
            end
        end


        %% Send focus to a UI element.
        % Hack: Matlab App Designer!!!
        function SendFocus(app, ui_element)
            [~, widgetID] = mlapptools.getWebElements(ui_element);
            js_code = ['dijit.byId("' widgetID.ID_val '").focusNode.focus()'];
            hWin = mlapptools.getWebWindow(app.CELL_ID);
            hWin.executeJS(js_code);
        end


        %% Restore the mouse pointer.
        % Hack: Matlab App Designer!!!
        function RestorePointer(app)
            js_code = ['var elementToChange = document.getElementsByTagName("body")[0];' ...
                'elementToChange.style.cursor = "url(''cursor:default''), auto";'];
            hWin = mlapptools.getWebWindow(app.CELL_ID);
            hWin.executeJS(js_code);
        end


        %% Select a neuron from the z-slice image.
        function SelectNeuron(app, neuron_i)

            % Update the neuron plot marker to show that it's selected.
            % Find the index of selected neuron in the list of displayed
            % neurons that are in the current slice. The index of neuron
            % in the app.image_neurons.neurons (which contains all the neurons) is
            % different from its index in the list of current z neurons.

            % Unselect the selected neuron.
            if app.selected_neuron == neuron_i
                UnselectNeuron(app);
                return;
            end

            % Unselect the previously selected neuron.
            if ~isempty(app.selected_neuron)
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
            end

            % Select the neuron.
            app.selected_neuron = neuron_i;
            app.image_neurons.neurons(app.selected_neuron).is_selected = true;

            % Flip the Z-axis.
            z = app.image_neurons.neurons(app.selected_neuron).position(3);
            z = getZSlice(app, z);

            % Redraw the Z-slice.
            app.ZSlider.Value = z;
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

            % If the AutoID is already run and the IDs are present.
            is_IDEditField_set = false;
            if ~isempty(app.image_neurons.neurons) ...
                    && isempty(app.image_neurons.neurons(neuron_i).annotation) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)

                % Initialize the IDs & probabilities.
                ids = app.image_neurons.neurons(neuron_i).probabilistic_ids;
                probs = app.image_neurons.neurons(neuron_i).probabilistic_probs();
                probs = round(probs * 100);

                % Remove zero probability choices.
                probs_i = probs > 0;
                ids = ids(probs_i);
                probs = probs(probs_i);

                % Concatenate names and probabilities to display them.
                str = strcat(ids, '=', arrayfun(@(x) num2str(x, '%d%% '), probs, 'UniformOutput', 0));

                % Update the GUI.
                app.AutoIDDropDown.Items = str;
                name = app.image_neurons.neurons(neuron_i).deterministic_id;
                name = upper(name);
                if ~app.AutoNameCheckBox.Value || ...
                        strcmp(name, 'ARTIFACT') || strcmp(name, 'NAN')
                    app.IDEditField.Value = '';
                else
                    app.IDEditField.Value = name;
                end
                is_IDEditField_set = true;

                % Select the appropriate Auto ID.
                neuron_rank = app.image_neurons.neurons(neuron_i).rank;
                if ~isempty(neuron_rank)
                    app.UserNeuronIDsListBox.Value = neuron_rank;
                    %app.UserNeuronIDsListBox.Value = app.UserNeuronIDsListBox.Items{neuron_rank};
                end
            else
                % Select nothing.
                app.AutoIDDropDown.Items = {};
                app.UserNeuronIDsListBox.Value = {};
            end

            % Show the user annotation.
            user_ID = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(user_ID)

                % Is the neuron ON/OFF?
                switch app.image_neurons.neurons(neuron_i).is_annotation_on
                    case false
                        user_ID = [user_ID '-OFF'];
                    case true
                        user_ID = [user_ID '-ON'];
                end

                % Is the user uncertain about the ID?
                if app.image_neurons.neurons(neuron_i).annotation_confidence <= 0.5
                    user_ID = [user_ID '?'];
                end

                % Is the neuron emphasized?
                if app.image_neurons.neurons(neuron_i).is_emphasized
                    user_ID = [user_ID '!'];
                end

                % Display the user ID.
                app.IDEditField.Value = user_ID;
                is_IDEditField_set = true;
            end

            % Empty the ID field.
            if ~is_IDEditField_set
                app.IDEditField.Value = '';
            end

            % Enable the input fields.
            app.AutoIDDropDown.Enable = 'on';
            app.IDEditField.Enable = 'on';
            app.AutoIDButton.Enable = 'on';
            app.UserIDButton.Enable = 'on';

            % If the neuron is unID'd, send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            if isempty(user_ID)
                SendFocus(app, app.IDEditField);
            end
        end


        %% Select the nearest neuron in need of a user ID.
        function SelectNearestUnIDdNeuron(app, neuron_i)

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.nearest_unannotated(neuron_i);

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the nearest neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the nearest neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %SendFocus(app, app.IDEditField);
        end


        %% Select the most uncertain neuron in need of a user ID.
        function SelectProbabilisticUnIDdNeuron(app, neuron_i)

            % No more neurons to ID.
            if app.image_neurons.num_neurons() == app.image_neurons.num_user_id_neurons()
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Store the currently selcted neuron.
            old_neuron_i = neuron_i;

            % Select the nearest neuron in need of a user ID by z, then (x,y).
            [~, neuron_i] = app.image_neurons.find_rank(1);

            % Is the neuron already selected?
            if app.image_neurons.num_user_unid_neurons() > 1 && neuron_i == old_neuron_i
                [~, neuron_i] = app.image_neurons.find_rank(2);
            end

            % No more neurons to ID.
            if isempty(neuron_i)
                uialert(app.CELL_ID, ...
                    'All neurons have been assigned an ID!',...
                    'All Neurons ID''d', 'Icon', 'success');
                return;
            end

            % Auto ID the most uncertain neuron.
            auto_ID = [];
            if ~isempty(app.image_neurons.neurons) ...
                    && ~isempty(app.image_neurons.neurons(neuron_i).deterministic_id)
                auto_ID = app.image_neurons.neurons(neuron_i).deterministic_id;
            end

            % Select the most uncertain neuron.
            SelectNeuron(app, neuron_i);
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {[auto_ID '???']}, 0);

            % Send focus to the ID text field.
            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            %SendFocus(app, app.IDEditField);
        end


        %% Unselect the currently selected neuron. Optional argument, re-draw the image?
        %% Note: Matlab buffers re-draws so, if you want them to execute in an orderly fashion,
        %% you need to only make one call.
        function UnselectNeuron(app, varargin)

            % Unselect the selected neuron.
            if ~isempty(app.selected_neuron) && app.image_neurons.neurons(app.selected_neuron).is_selected == true

                % Unselect the neuron.
                app.image_neurons.neurons(app.selected_neuron).is_selected = false;
                app.selected_neuron = [];

                % Disable the ID fields.
                app.AutoIDDropDown.Items = {''};
                app.AutoIDDropDown.Value = '';
                app.IDEditField.Value = '';

                % Disable the input fields.
                app.AutoIDDropDown.Enable = 'off';
                app.IDEditField.Enable = 'off';
                app.AutoIDButton.Enable = 'off';
                app.UserIDButton.Enable = 'off';

                % Redraw the Z-slice.
                is_redraw = true; % re-draw the image?
                if ~isempty(varargin)
                    is_redraw = varargin{1};
                end
                if is_redraw

                    % Give the GUI time to update.
                    pause(0.2);

                    % Re-draw.
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
                end
            end
        end


        %% Delete a neuron from the z-slice image.
        function DeleteNeuron(app, neuron_i)

            % Did the neuron have an ID?
            neuron = app.image_neurons.neurons(neuron_i).annotation;
            if ~isempty(neuron)
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is ID''d!'], 'Do you want delete the neuron?'}, ...
                    'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the neuron.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect all neurons.
            app.UnselectNeuron(false); % wait for re-draw
            %app.UnselectNeuron();

            % NO LONGER USED!!! UPDATEAUTOIDMODEL HANDLES THIS.
            % Delete the neuron from the GUI list.
            %app.UserNeuronIDsListBox.Value = {};
            %uncertain_idx = app.image_neurons.neurons(neuron_i).rank;
            %if ~isempty(uncertain_idx)
            %    app.UserNeuronIDsListBox.Items = ...
            %        {app.UserNeuronIDsListBox.Items{1:(uncertain_idx-1)}, ...
            %        app.UserNeuronIDsListBox.Items{(uncertain_idx+1):end}};
            %end

            % Delete the neuron from the master list.
            app.image_neurons.del_neuron(neuron_i);

            % Save the updated IDs.
            app.SaveIDToFile();

            % Redraw the auto ID list.
            app.DrawAutoIDList();

            % Redraw the Z-slice.
            app.DrawZSlice(app.ZSlider, app.image_view, app.XY);

            % Update the ID'd neuron count.
            app.UpdateNeuronLists();
        end


        %% Draw the auto ID neuron uncertainty list.
        function DrawAutoIDList(app)

            % Initialize the probabilities.
            prob_IDs = app.image_neurons.get_probabilistic_ids();
            prob_vals = app.image_neurons.get_probabilistic_probs();
            ranks = app.image_neurons.get_ranks();
            if isempty(ranks)
                return;
            end

            % Construct the ranked strings.
            neurons = app.image_neurons.neurons;
            prob_vals = round(prob_vals * 100);
            prob_strs = [];
            prob_ranks = [];
            for i = 1:length(neurons)

                % Is the neuron ranked?
                neuron = neurons(i);
                if neuron.rank > 0 % && isempty(neuron.annotation)

                    % Display the most probable neuron.
                    prob_IDs = neuron.probabilistic_ids;
                    prob_vals = round(neuron.probabilistic_probs * 100);
                    prob_strs{end+1} = sprintf('%s=%d%%', prob_IDs{1}, prob_vals(1));
                    prob_ranks(end+1) = neuron.rank;

                    % Display alternative neurons.
                    alt_i = find(prob_vals(2:end) > 0) + 1;
                    if ~isempty(alt_i)
                        prob_strs{end} = [prob_strs{end}, '   or  '];
                    end
                    for j = 1:length(alt_i)
                        k = alt_i(j);
                        if j > 1
                            prob_strs{end} = [prob_strs{end}, ','];
                        end
                        prob_strs{end} = [prob_strs{end}, sprintf(' %s=%d%%', ...
                            prob_IDs{k}, prob_vals(k))];
                    end
                end
            end

            % Draw the auto ID neuron uncertainty list.
            [~, sort_i] = sort(prob_ranks);
            app.UserNeuronIDsListBox.Items = prob_strs(sort_i);
            app.UserNeuronIDsListBox.ItemsData = 1:length(sort_i);
            app.UserNeuronIDsListBox.Value = {};
        end


        %% Update the user ID'd neurons.
        function UpdateNeuronLists(app)

            % Show the selected neurons.
            selectedNodes = app.GanglionTree.SelectedNodes;
            neurons = {};
            for i = 1:length(selectedNodes)
                node = selectedNodes(i);
                if isempty(node.Children) % Child node on the tree.
                    neurons = union(neurons, GetChildNodeNeurons(app, node));
                else % Parent node on the tree.
                    neurons = union(neurons, GetParentNodeNeurons(app, node));
                end
            end
            neurons = sort(neurons);

            % None of the neurons have IDs.
            if isempty(app.image_neurons) || isempty(app.image_neurons.neurons)
                app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
                app.IDdLabel.Text = '     ID''d     ';
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.ItemsData = [];
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(neurons));
                app.UnIDdNeuronsListBox.Items = neurons;

                % Which neurons have IDs?
            else
                % Show the neuron counts.
                user_id_neurons = app.image_neurons.user_id_neurons();
                app.UserNeuronIDsListBoxLabel.Text = sprintf('User Neuron IDs = %d/%d', ...
                    length(user_id_neurons), app.image_neurons.num_neurons());

                % Remove emphasized neurons. They're not really ID'd.
                is_emphasized = vertcat(user_id_neurons.is_emphasized);
                user_id_neurons = user_id_neurons(~is_emphasized);

                % Separate the neurons into those with & without user IDs.
                user_id_names = {user_id_neurons.annotation};
                [id_neurons, ~, user_id_i] = intersect(neurons, user_id_names);
                unid_neurons =  setdiff(neurons, id_neurons);

                % Keep a copy of the neuron names.
                id_neuron_names = id_neurons;

                % Add ON/OFF & confidences to the neuron names.
                for i = 1:length(user_id_i)

                    % Is the neuron ON/OFF?
                    switch user_id_neurons(user_id_i(i)).is_annotation_on
                        case false
                            id_neurons{i} = [id_neurons{i} '-OFF'];
                        case true
                            id_neurons{i} = [id_neurons{i} '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if user_id_neurons(user_id_i(i)).annotation_confidence <= 0.5
                        id_neurons{i} = [id_neurons{i} ' ?'];
                    end
                end

                % Add the neuron birth times.
                if Program.GUIPreferences.instance().is_show_birth_times

                    % Initialize the neuron info.
                    neuron_data = app.hermaphrodite_neurons;
                    if contains(lower(app.worm.sex), 'xo')
                        neuron_data = app.male_neurons;
                    end

                    % Add birth times to the ID'd neurons.
                    [~, id_i, ~] = intersect(neuron_data.names, id_neuron_names);
                    if ~isempty(id_i)
                        id_stages = neuron_data.birth_stages(id_i);
                        id_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            id_neurons, id_stages, ...
                            'UniformOutput', false);
                    end

                    % Add birth times to the ID'd neurons.
                    [~, unid_i, ~] = intersect(neuron_data.names, unid_neurons);
                    if ~isempty(unid_i)
                        unid_stages = neuron_data.birth_stages(unid_i);
                        unid_neurons = cellfun(@(x,y) ...
                            sprintf('%s (%s)', x, y), ...
                            unid_neurons, unid_stages, ...
                            'UniformOutput', false);
                    end
                end

                % Update the GUI.
                black = [0,0,0];
                gray = [0.95,0.95,0.95];
                app.IDdLabel.BackgroundColor = gray;
                app.IDdLabel.FontColor = black;
                app.UnIDdLabel.BackgroundColor = gray;
                app.UnIDdLabel.FontColor = black;
                app.IDdLabel.Text = sprintf('ID''d = %d', length(id_neurons));
                app.IDdNeuronsListBox.Items = id_neurons;
                app.UnIDdLabel.Text = sprintf('Un-ID''d = %d', length(unid_neurons));
                app.UnIDdNeuronsListBox.Items = unid_neurons;
            end
        end


        %% Update the neuron lists.
        function UpdateSexNeuronLists(app)

            % Is the worm a male?
            is_male = contains(lower(app.worm.sex), 'xo');

            % Find the male tail node.
            tree = app.GanglionTree;
            names = {tree.Children.Text};
            male_i = find(strcmp(names,'Male Tail'));

            % Add the male tail.
            if is_male && isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the hermaphrodite tail.
                hermaphrodite_i = find(strcmp(names,'Tail'));
                if ~isempty(hermaphrodite_i)
                    tree.Children(hermaphrodite_i).delete();
                end

                % Add the male tail.
                app.neuron_info = Neurons.Male;
                ganglia = uitreenode(tree,'Text','Male Tail');
                uitreenode(ganglia,'Text','Pre-Anal (L)');
                uitreenode(ganglia,'Text','Pre-Anal (R)');
                uitreenode(ganglia,'Text','Rays (L)');
                uitreenode(ganglia,'Text','Rays (R)');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Cloacal (L)');
                uitreenode(ganglia,'Text','Cloacal (R)');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);

                % Remove the male tail.
            elseif ~is_male && ~isempty(male_i)

                % Unselect all ganglia & neurons.
                app.GanglionTree.SelectedNodes = [];
                UnselectNeuron(app);

                % Remove the male tail.
                tree.Children(male_i).delete();
                UpdateNeuronLists(app);

                % Add the hermaphrodite tail.
                app.neuron_info = Neurons.Hermaphrodite;
                ganglia = uitreenode(tree,'Text','Tail');
                uitreenode(ganglia,'Text','Pre-Anal');
                uitreenode(ganglia,'Text','Dorso-Rectal');
                uitreenode(ganglia,'Text','Lumbar (L)');
                uitreenode(ganglia,'Text','Lumbar (R)');
                UpdateNeuronLists(app);
            end
        end


        %% Get the neurons associated with this parent node.
        function neurons = GetParentNodeNeurons(app, node)
            neurons = [];
            switch node.Text
                case 'Pharynx'
                    neurons = app.neuron_info.getAnteriorPharynx();
                    neurons = union(neurons, app.neuron_info.getPosteriorPharynx());
                case 'Head'
                    neurons = app.neuron_info.getLeftAnteriorGanglion();
                    neurons = union(neurons, app.neuron_info.getRightAnteriorGanglion());
                    neurons = union(neurons, app.neuron_info.getDorsalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLateralGanglion());
                    neurons = union(neurons, app.neuron_info.getVentralGanglion());
                    neurons = union(neurons, app.neuron_info.getRetroVesicularGanglion());
                case 'Midbody'
                    neurons = app.neuron_info.getAnteriorMidbody();
                    neurons = union(neurons, app.neuron_info.getCentralMidbody());
                    neurons = union(neurons, app.neuron_info.getPosteriorMidbody());
                    neurons = union(neurons, app.neuron_info.getVentralNerveCord());
                case 'Tail'
                    neurons = app.neuron_info.getPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
                case 'Male Tail'
                    neurons = app.neuron_info.getLeftPreAnalGanglion();
                    neurons = union(neurons, app.neuron_info.getRightPreAnalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftRays());
                    neurons = union(neurons, app.neuron_info.getRightRays());
                    neurons = union(neurons, app.neuron_info.getDorsoRectalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getRightCloacalGanglion());
                    neurons = union(neurons, app.neuron_info.getLeftLumbarGanglion());
                    neurons = union(neurons, app.neuron_info.getRightLumbarGanglion());
            end
        end


        %% Get the neurons associated with this child node.
        function neurons = GetChildNodeNeurons(app, node)
            neurons = [];
            switch node.Parent.Text
                case 'Pharynx'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorPharynx();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorPharynx();
                    end
                case 'Head'
                    switch node.Text
                        case 'Anterior (L)'
                            neurons = app.neuron_info.getLeftAnteriorGanglion();
                        case 'Anterior (R)'
                            neurons = app.neuron_info.getRightAnteriorGanglion();
                        case 'Dorsal'
                            neurons = app.neuron_info.getDorsalGanglion();
                        case 'Lateral (L)'
                            neurons = app.neuron_info.getLeftLateralGanglion();
                        case 'Lateral (R)'
                            neurons = app.neuron_info.getRightLateralGanglion();
                        case 'Ventral'
                            neurons = app.neuron_info.getVentralGanglion();
                        case 'Retro-Vesicular'
                            neurons = app.neuron_info.getRetroVesicularGanglion();
                    end
                case 'Midbody'
                    switch node.Text
                        case 'Anterior'
                            neurons = app.neuron_info.getAnteriorMidbody();
                        case 'Central'
                            neurons = app.neuron_info.getCentralMidbody();
                        case 'Posterior'
                            neurons = app.neuron_info.getPosteriorMidbody();
                        case 'Ventral Cord'
                            neurons = app.neuron_info.getVentralNerveCord();
                    end
                case 'Tail'
                    switch node.Text
                        case 'Pre-Anal'
                            neurons = app.neuron_info.getPreAnalGanglion();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
                case 'Male Tail'
                    switch node.Text
                        case 'Pre-Anal (L)'
                            neurons = app.neuron_info.getLeftPreAnalGanglion();
                        case 'Pre-Anal (R)'
                            neurons = app.neuron_info.getRightPreAnalGanglion();
                        case 'Rays (L)'
                            neurons = app.neuron_info.getLeftRays();
                        case 'Rays (R)'
                            neurons = app.neuron_info.getRightRays();
                        case 'Dorso-Rectal'
                            neurons = app.neuron_info.getDorsoRectalGanglion();
                        case 'Cloacal (L)'
                            neurons = app.neuron_info.getLeftCloacalGanglion();
                        case 'Cloacal (R)'
                            neurons = app.neuron_info.getRightCloacalGanglion();
                        case 'Lumbar (L)'
                            neurons = app.neuron_info.getLeftLumbarGanglion();
                        case 'Lumbar (R)'
                            neurons = app.neuron_info.getRightLumbarGanglion();
                    end
            end
        end


        %% Save the user ID for a neuron.
        function SaveUserID(app, user_ID)
            % Depending on the question mark set the annotation confidence and the neuron name.
            % The marker colors will change accordingly -> see Neuron.m
            %   Examples:
            %       'AVR?' -> 50% confidence if it's AVR or not.
            %       '?' -> 0% confidence about the neuron.
            %       'AVR' -> 100% confidence that it's AVR.

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            neuron_i = app.selected_neuron;
            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Get rid of the whitespace and uppercase the ID.
            user_ID = upper(strtrim(user_ID));

            % Did the user input an empty ID (e.g., "")?
            if isempty(user_ID)

                % Are we deleting the neuron's existing ID?
                neuron = app.image_neurons.neurons(neuron_i).annotation;
                if ~isempty(neuron)
                    answer = uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is ID''d!'], 'Do you want delete the ID?'}, ...
                        'Neuron ID Deleted', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the ID.
                    if strcmpi(answer, 'cancel')
                        UnselectNeuron(app);
                        app.is_saving_ID = false;
                        return;
                    end

                    % Delete the ID.
                    app.image_neurons.neurons(neuron_i).annotate('', 0, nan);
                end


                % Save the updated IDs.
                SaveIDToFile(app);

                % Update the ID'd neuron count.
                UpdateNeuronLists(app);

                % Clear the ID field.
                UnselectNeuron(app);
                app.is_saving_ID = false;

                % Select the next neuron in need of a user ID.
                if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                    app.SelectNearestUnIDdNeuron(neuron_i);
                else
                    app.SelectProbabilisticUnIDdNeuron(neuron_i);
                end

                % Done.
                return;
            end

            % Is the user emphasizing this neuron?
            % Note: as one example, neurons may be emphasized in mutants.
            neuron = user_ID;
            is_emphasized = false;
            if neuron(end) == '!'
                is_emphasized = true;
                neuron = neuron(1:(end-1));
            end

            % Is the user uncertain about the ID?
            is_uncertain = false;
            if neuron(end) == '?'
                is_uncertain = true;
                neuron = neuron(1:(end-1));
            end

            % Is the neuron ON/OFF?
            [neuron, is_on] = app.neuron_info.stripOnOff(neuron);

            % Did the user input a correct neuron name?
            % Note: emphasized neurons can exist as duplicates.
            %if ~app.neuron_info.isNeuron(neuron)
            if is_emphasized
                if ~Neurons.Hermaphrodite.isCell(neuron) && ...
                        ~Neurons.Male.isCell(neuron)
                    uiconfirm(app.CELL_ID, ...
                        {['"' neuron '" is not a cell type!'], ...
                        ['Did you forget the "L" or "R"?']}, ...
                        'Neuron Name Error', 'Options', {'Ok'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, ...
                        'Icon', 'error');
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                    %else
                    % Add the emphasis back onto the neuron.
                    % Note: this has 2 side effects
                    % 1. We can assign the neuron name more than once.
                    % 2. The neuron assignment will not affect our predictions.
                    %neuron = [neuron '!'];
                end
            elseif ~app.neuron_info.isCell(neuron)
                uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" is not a neuron in ' app.worm.sex ' animals!'], ...
                    ['Did you forget the "L" or "R", or is the sex incorrect?']}, ...
                    'Neuron Name Error', 'Options', {'Ok'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');
                SendFocus(app, app.IDEditField);
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && ~is_emphasized && ...
                    app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            % What did the user enter?
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = is_on;
            app.image_neurons.neurons(neuron_i).is_emphasized = is_emphasized;
            if is_uncertain
                app.image_neurons.neurons(neuron_i).annotation_confidence = 0.5;
            else
                app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {user_ID}, 1);
        end


        %% Save the auto ID for a neuron.
        function SaveAutoID(app, neuron)

            % Are we already saving an ID?
            if app.is_saving_ID
                return;
            end
            app.is_saving_ID = true;

            % Are any neurons selected?
            neuron_i = app.selected_neuron;
            if isempty(neuron_i)
                app.is_saving_ID = false;
                return;
            end

            % Has the neuron already been assigned?
            prev_assigned_neuron = app.image_neurons.find_user_id_neuron(neuron);
            if ~isempty(prev_assigned_neuron) && app.image_neurons.neurons(neuron_i) ~= prev_assigned_neuron

                % Are we re-assigning the ID?
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' neuron '" was assigned to a different neuron!'], ...
                    'Do you want delete the previously assigned ID?'}, ...
                    'Neuron ID Re-Assigned', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Cancel.
                if strcmpi(answer, 'cancel')
                    SendFocus(app, app.IDEditField);
                    app.is_saving_ID = false;
                    return;
                end

                % Delete the previous ID.
                prev_assigned_neuron.annotate('', 0, nan);
            end

            % Annotate the neuron.
            app.image_neurons.neurons(neuron_i).annotation = neuron;
            app.image_neurons.neurons(neuron_i).is_annotation_on = nan;
            app.image_neurons.neurons(neuron_i).annotation_confidence = 1;
            app.image_neurons.neurons(neuron_i).is_emphasized = false;
            if ~isempty(app.image_neurons.neurons(neuron_i).probabilistic_probs)
                app.image_neurons.neurons(neuron_i).deterministic_id = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_ids{1} = neuron;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(1) = 1;
                app.image_neurons.neurons(neuron_i).probabilistic_probs(2:end) = 0;
            end
            if ~isempty(app.image_neurons.neurons(neuron_i).rank)
                app.image_neurons.rank_neuron_max(neuron_i);
            end

            % Update the auto-ID model.
            UpdateAutoIDModel(app);

            % Save the updated IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Done.
            UnselectNeuron(app);
            app.is_saving_ID = false;

            % Select the next neuron in need of a user ID.
            if strcmp(app.NextNeuronDropDown.Value, 'Nearest')
                app.SelectNearestUnIDdNeuron(neuron_i);
            else
                app.SelectProbabilisticUnIDdNeuron(neuron_i);
            end

            % Show the annotated neuron's ID.
            position = app.image_neurons.neurons(neuron_i).position;
            DrawImageLabels(app, position, {neuron}, 1);
        end


        %% Update the model for neuron ID.
        function UpdateAutoIDModel(app)
            % Update the log_likelihood, uncertainty, and neuron ranks,
            % then update the list of neurons accordingly.
            worm = app.worm;
            if Program.GUIPreferences.instance().is_autoID_updates && ...
                    Methods.AutoId.isBodyID(worm.sex, worm.body)
                Methods.AutoId.instance().update_id(...
                    app.image_neurons, worm);
            end
            DrawAutoIDList(app);
        end


        %% Update the neuorn ID confidences.
        function UpdateNeuronIDConfidence(app)
            % Update the confidence computations that each labeled neuron
            % is correctly identified.
            worm = app.worm;
            if ~Methods.AutoId.isBodyID(worm.sex, worm.body)
                return;
            end
            Methods.AutoId.instance().update_confidences(...
                app.image_neurons, worm);
        end

        %% Save the IDs to a file.
        function SaveIDToFile(app)
            version = Program.ProgramInfo.version;
            mp_params = app.mp_params;
            mp_params.k = length(app.image_neurons.neurons);
            neurons = app.image_neurons;
            save(app.id_file, 'version', 'neurons', 'mp_params');
            %sp = app.image_neurons.to_superpixel();
            %save(app.id_file, 'version', 'sp', 'mp_params');
        end


        %% Get the z slice (correcting for the flipped axis).
        function z = getZSlice(app, z)
            if app.image_prefs.is_Z_flip
                z = size(app.image_data,3) - z + 1;
            end
        end

        % Function that responds to the movement of the time sliders
        function lMoving(~, source)
            % Always keep the position rounded to 1 decimal place
            source.Position(1,1) = round(source.Position(1,1),1);
            source.Position(2,1) = round(source.Position(2,1),1);
            % Add any other action here triggered by the movement of the sliders
        end

        function DispNeuron(app,event)
            caller = event.Source;
            grid_space = caller.Parent.Parent.Layout;

            if strcmp(caller.Tag,'selector')
                app.LoadNeuron(caller, event)
            elseif strcmp(caller.Tag,'in_graph')
                app.PickNeuron(caller, 'in_graph')
            elseif strcmp(caller.Tag,'add_graph')
                app.LoadNeuron(caller, event, 'add_graph')
            else
                if grid_space.Column < max(size(app.GridLayout.ColumnWidth)) & grid_space.Row < max(size(app.GridLayout.RowHeight))
                    app.PickNeuron(caller)
                else
                    app.PickNeuron(caller)
                    %app.CreateNeuron(caller,event)
                end
            end
        end

        function PickNeuron(app,caller,tag)

            if ~exist('tag','var')
                tag = 'selector';
                g_parent = app.CELL_ID;
                parent = caller.Parent;
            elseif strcmp(tag,'col') | strcmp(tag,'row')
                'tbd'
            else
                tag = 'add_graph';
                g_parent = caller.Parent;
                parent = caller.Parent.Parent;
            end

            set(caller,'Visible','Off');

            % Create DropDown
            DropDown = uidropdown(g_parent);
            DropDown.Parent = parent;
            DropDown.Position = caller.Position;
            DropDown.Items = {'Sample Neuron #1','Sample Neuron #2','Sample Neuron #3'};
            DropDown.Placeholder = 'Select Neuron';
            DropDown.Tag = tag;
            DropDown.ValueChangedFcn = createCallbackFcn(app, @DispNeuron, true);
        end

        function LoadNeuron(app,caller,event,tag)

            if ~exist('tag','var')
                % Create new 
                new_grid = uigridlayout(caller.Parent.Parent);
                delete(caller.Parent)
                new_grid.ColumnWidth = {'1x', '1x', '1x', '1x', '1x'};
                new_grid.RowHeight = {'1x', '1x', '1x', '1x', '1x', '1x'};
    
                % Create UIAxes
                new_axes = uiaxes(new_grid);
                title(new_axes, event.Value)
                xlabel(new_axes, 't')
                ylabel(new_axes, 'Ca+')
                zlabel(new_axes, 'Z')
                new_axes.Layout.Row = [1 6];
                new_axes.Layout.Column = [1 5];
    
                % Create AddNeuronButton
                new_button = uibutton(new_grid, 'push');
                new_button.Layout.Row = 2;
                new_button.Layout.Column = 5;
                new_button.Text = 'Add Neuron';
                new_button.Tag = 'in_graph';
                new_button.ButtonPushedFcn = createCallbackFcn(app, @DispNeuron, true);
            else
                targAx = caller.Parent.Children(2).Children(1);
                title(targAx, [targAx.Title.String,', ', event.Value])
            end
        end

        function CreateNeuron(app,caller,event)
            % Create TL_Panel
            new_panel = uipanel(app.GridLayout);

            if grid_space(1) == max(size(app.GridLayout.ColumnWidth))
                new_panel.Layout.Column = grid_space(2)+1;
            else
                new_panel.Layout.Column = grid_space;
            end

            if grid_space(2) == max(size(app.GridLayout.RowHeight))
                new_panel.Layout.Row = grid_space(1)+1;
            end

            % Create TL_Grid
            new_grid = uigridlayout(app.TL_Panel);
            new_grid.ColumnWidth = {'1x'};
            new_grid.RowHeight = {'1x'};

            % Create TL_Button
            new_button = uibutton(app.TL_Grid, 'push');
            new_button.ButtonPushedFcn = createCallbackFcn(app, @AddNeuron, true);
            new_button.FontWeight = 'bold';
            new_button.Layout.Row = 1;
            new_button.Layout.Column = 1;
            new_button.Text = 'Add Neuron';
        end
        
        function init_browser(app, neuron)

            if ~isempty(app.movie_var)
                length = size(app.movie_var,4);
            else
                sample_neuron = keys(app.neuron_activity_by_name);
                length = max(size(app.neuron_activity_by_name(sample_neuron{1})));
            end
        
            if ~exist('neuron','var')
                % Create the 1D axes
                y = zeros(length);
            else
                y = app.neuron_activity_by_name(neuron);
            end

            max_read = 0;
            key = keys(app.neuron_activity_by_name);

            for n=1:size(key,2)
                if ~strcmp(key(n),'neuron')
                    max_local = max(app.neuron_activity_by_name(key{n}));
                    if max_local > max_read
                        max_read = max_local;
                    end
                end
            end

            app.ActivityTrace.YLim = [0, max_read];
            plot(app.ActivityTrace,y);

            % Add a listener for changes in 'XLim' property
            addlistener(app.ActivityTrace, 'XLim', 'PostSet', @(src, event) updateXTicks(app, length));
            
            % Initial setup
            updateXTicks(app, length);
        end
        
        function update_browser(app, neuron)
            old_xlim = app.ActivityTrace.XLim;
            old_ylim = app.ActivityTrace.YLim;
            if any(strcmp(app.neuron_activity_by_name.keys(),neuron))
                plot(app.ActivityTrace, app.neuron_activity_by_name(neuron));
            else
                cla(app.ActivityTrace);
            end
            app.ActivityTrace.XLim = old_xlim;
            app.ActivityTrace.YLim = old_ylim;
        end

        function updateXTicks(app, length)
            ax = app.ActivityTrace;
        
            % Calculate new ticks based on current axes limits
            lims = round(ax.XLim);
            lims = [ max(0, lims(1)), min(length, lims(2)) ];
        
            tick_indices = round(linspace(lims(1), lims(2), 9)); % 9 points to get 8 segments
            tick_values = tick_indices;
            tick_labels = arrayfun(@(i) sprintf('%02d:%02d', floor(i/60), mod(i,60)), tick_values, 'UniformOutput', false);
        
            % Apply new tick values and labels to the X-axis
            ax.XAxis.TickValues = tick_values;
            ax.XAxis.TickLabels = tick_labels;
        end

    end
    
    methods (Access = public)
        
        function UpdateFromChild(app, image_data, image_neurons,image_gamma)

            if size(app.image_data,3) ~= size(image_data,3)

                num_z_slices = size(image_data, 3);
                z_slices = 1:num_z_slices;

                if num_z_slices <= 1
                    uialert(app.CELL_ID, 'The image is not a volume!', ...
                        'Image Not a Volume', 'Icon', 'error');
                    return;
                else
                    z_labels = arrayfun(@(z) num2str(z, '%.1f'), ...
                        (z_slices-1) * app.image_info.scale(3), 'UniformOutput', false);
                end

                app.ZSlider.Limits = [1, num_z_slices];
                app.ZSlider.MajorTicks = z_slices;
                app.ZSlider.MajorTickLabels = z_labels;
    
                % Change the neuron properties (mean and covariance) according
                % to user selected parameters.
                for neuron=1:length(app.image_neurons.neurons)
                    app.image_neurons.neurons(neuron).position = ...
                        app.image_neurons.neurons(neuron).position/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                    app.image_neurons.neurons(neuron).covariance = ...
                        app.image_neurons.neurons(neuron).covariance/( app.image_neurons.neurons(neuron).position/(size(app.image_data,3)/size(image_data,3)) );
                end

            else
                app.image_neurons = image_neurons;
            end

            app.image_data = image_data;
            app.image_gamma = image_gamma;
            
            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            info.scale = app.image_um_scale;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);
            app.DrawImageData();

        end

        function ImpNeurons(app,auto_neurons)
            for r = 1:size(auto_neurons, 1)
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                [auto_neurons(r,1) auto_neurons(r,2) auto_neurons(r,3)], app.mp_params.hnsz, app.image_um_scale');
            end
        end

        function eyedrop(app, Red, Green, Blue, Background, White, GFP, Lyso, Gut)
        
            color_struct = struct('red',Red,'green',Green,'blue',Blue,'background',Background,'white',White,'GFP',GFP,'Lyso',Lyso,'Gut',Gut);
            imwrite(squeeze(max(app.image_view,[],3)), 'cache.png');
            im = imread('cache.png');
            color_roi = drawpoint(app.MaxProjection);
            pos = round(color_roi.Position);
        
            for color = fieldnames(color_struct)'
                if color_struct.(color{1})
                    uiwait(msgbox(sprintf('In the maximum projection, click on the region that best represents %s.', color{1})))
                    color_values = impixel(im,pos(2), pos(1));
                    color_struct.(color{1}) = color_values;
                    delete(color_roi)
                end
            end
        
            delete('cache.png')
        
        end

        % Define the function to return the activity of a particular neuron over time.
        function activity = get_neuron_activity(worldline_id)
            % Get the indices of the annotations for the specified worldline_id.
            indices = find(annotations.worldline_id == worldline_id);
        
            % Extract the corresponding activity values.
            activity = app.neuron_activity(indices, :);
        end

        function hh=ticklabelinside(app,h,ax,cornerflag)
            %TICKLABELINSIDE moves tick labels to the inside of the plot axis
            %   HH=TICKLABELINSIDE(H,AX,CORNERFLAG) is the calling form.
            %   HH contains handles to the text objects created.
            %   H is the handle to the figure or axis of interest (default is GCF).
            %   AX is 'x' 'y' or 'xy' (default) to indicate which axis labels should be moved in.
            %   CORNERFLAG is 1 to print labels at the corners, 0 to skip them for clarity (default)
            %
            %   Run this file after all plotting is complete - labels do not update
            %      with axis zooming or panning.
            %
            %   NOTE: to move axis ticks out you can call SET(gca,'tickdir','out')
            %   NOTE: this doesn't work with log plots.
            %
            %   Example 1: A smiley face, with a variety of options.
            %      figure, x=0:10;
            %      h(1)=subplot(221); rectangle('position',[-1e7 -1e7 2e7 2e7],'curvature',[1 1],'linewidth',2,'edgecolor','b'), title('Regular old plot'), xlabel('outside'), ylabel('outside')
            %      h(2)=subplot(222); rectangle('position',[-1e7 -1e7 2e7 2e7],'curvature',[1 1],'linewidth',2,'edgecolor','b'), h1=ticklabelinside(h(2)); title('ticklabelinside(h(2))'), xlabel('inside'), ylabel('inside')
            %      h(3)=subplot(223); plot(-x,x.^2,'r.-',-x,.5*x.^2+50,'r.-'), h2=ticklabelinside(h(3),'y'); title('ticklabelinside(h(3),''y'')'), xlabel('outside'), ylabel('inside')
            %      h(4)=subplot(224); plot(x,x.^2,'r.-',x,.5*x.^2+50,'r.-'), h3=ticklabelinside(h(4),'xy',1); title('ticklabelinside(h(4),''xy'',1)'), xlabel('inside w/corners'), ylabel('inside w/corners')
            %
            %   Example 2: A big plot with many subplots. 
            %      h=subplotsCM(3,4); for n=1:numel(h),plot(h(n),1:10,rand(1,10),'.-'), end, hh=ticklabelinside(h);
            %
            %   See also: SUBPLOTSCM, ROTATETICKLABEL (both on the file exchange)
            
            %   Written by Andrew Bliss, April 2013
            
            %parse inputs
            if nargin<3
                cornerflag=0;
            end
            if nargin<2
                ax='xy';
            end
            if nargin==0
                h=gcf;
            end
            if strcmp(get(h,'type'),'figure')
                hc=get(h,'children');
                h=hc(strcmp(get(hc,'type'),'axes')); %get rid of children that are not axes
                h=h(strcmp(get(h,'tag'),'')); %get rid of axes that are not for plots (e.g. legends)
            end
            
            %initialize handles
            hout=[];
            
            %loop over all axes
            for n=1:numel(h)
                %if axis is invisible, skip it
                if strcmp(get(h(n),'Visible'),'off')
                    continue
                end
                %initialize handles to text objects
                h1=[]; h2=[];
            
                %replace xticklabels with text labels inside the axis
                if strcmp(ax,'x') || strcmp(ax,'xy')
                    %get axis properties
                    xtick=get(h(n),'xtick');
                    xticklabel=get(h(n),'xticklabel');
                    axlim=get(h(n),'xlim');
                    %reformat labels
                    xticklabel=tickformat(xticklabel,xtick);
                    %normalized x positions
                    xticknorm=(xtick-axlim(1))/(axlim(2)-axlim(1));
                    if cornerflag
                        ind=true(size(xticknorm)); %print numbers at the corners. Best practice is probably to set axis limits appropriately and use this option.
                    elseif ~cornerflag
                        ind=xticknorm ~=0 & xticknorm~=1; %don't print numbers at the corners.
                    end
                    ypos=0.5; %y position for text. ranges from .05 to .08 for different size fonts and plots
                    h1=text(xticknorm(ind),repmat(ypos,1,sum(ind)),xticklabel(ind),...
                                'units','normalized','horizontalalignment','center','fontsize',get(h(n),'fontsize'),'fontweight','bold','hittest','off','parent',h(n));
                    set(h(n),'xticklabel',[])
                end
                
                %replace yticklabels with text labels inside the axis
                if strcmp(ax,'y') || strcmp(ax,'xy')
                    %get axis properties
                    ytick=get(h(n),'ytick');
                    yticklabel=get(h(n),'yticklabel');
                    axlim=get(h(n),'ylim');
                    %reformat labels
                    yticklabel=tickformat(yticklabel,ytick);
                    %normalized y positions
                    yticknorm=(ytick-axlim(1))/(axlim(2)-axlim(1));
                    if cornerflag
                        ind=true(size(yticknorm)); %print numbers at the corners. Best practice is probably to set axis limits appropriately and use this option.
                    elseif ~cornerflag
                        ind=yticknorm ~=0 & yticknorm~=1; %don't print numbers at the corners.
                    end
                    h2=text(repmat(.02,1,sum(ind)),yticknorm(ind),yticklabel(ind),...
                                'units','normalized','horizontalalignment','left','fontsize',get(h(n),'fontsize'),'fontweight','bold','hittest','off','parent',h(n));
                    set(h(n),'yticklabel',[])
                end
                
                hout=[hout; h1; h2];
            end
            
            %concatenate outputs
            if nargout
                hh=hout;
            end
        
            function ticklabel=tickformat(ticklabel,tick)
            %TICKFORMAT adds the exponent to tick labels and formats tick for time
            ticklabeldbl=str2double(ticklabel); %will be empty if there are any characters
            if ~isempty(ticklabeldbl)
                %check for exponent
                if ticklabeldbl(end)~=0
                    texp=tick(end)/ticklabeldbl(end); %e.g. 10000
                else
                    texp=tick(1)/ticklabeldbl(1);
                end
                texp=log10(texp); %e.g. 4. Should be an integer.
                texp=1/100*round(texp*100); %give it some rounding just to be sure (this avoids a problem with texp being 1e-17)
                %reformat labels
                ticklabel=cellstr(ticklabel);
                if texp~=0
                    ticklabel=strcat(ticklabel,['e' num2str(texp)]);
                end
            else
                ticklabel=repmat({''},size(tick'));
            end
            ticklabel = appendZeroAndSuffix(ticklabel);
            end


        function ticklabel = appendZeroAndSuffix(ticklabel)
            %APPENDZEROANDSUFFIX appends leading zero and suffix ':00' to each tick label
            for i = 1:numel(ticklabel)
                if length(ticklabel{i}) == 1
                    ticklabel{i} = ['0' ticklabel{i} ':00'];
                else
                    ticklabel{i} = [ticklabel{i} ':00'];
                end
            end
        end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Hack: close that weird window that pops up for no reason.
            % Note: I have to do this because Matlab sucks.
            close all;

            % Using mlapptools because Matlab refuses to give me focus control.
            % This warning needs to shut up :-)
            % mlapptools.getWebWindow (line 337)
            warning off MATLAB:ui:javaframe:PropertyToBeRemoved;

            % Resize the figure to fit most of the screen size.
            screen_size = get(groot, 'ScreenSize');
            screen_size = screen_size(3:4);
            screen_margin = floor(screen_size .* [0.07,0.05]);
            figure_size(1:2) = screen_margin / 2;
            figure_size(3) = screen_size(1) - screen_margin(1);
            figure_size(4) = screen_size(2) - 2*screen_margin(2);
            drawnow; % need to draw before resizing
            app.CELL_ID.Position = figure_size;

            % Load the application preferences.
            is_loaded = Program.GUIPreferences.load();

            % Add the path corresponding to the models.
            if ~isdeployed
                [path, ~, ~] = fileparts(mfilename('fullpath'));
                addpath(genpath([path, filesep, 'Data']));
            end

            % Log the change.
            if exist('TraceHistory', 'class')
                app.log = TraceHistory.Instance;
                app.log.setup({'startupFcn'});
            end

            % Are we using the parallelization toolbox?
            parallel_tb = ver('parallel');
            if isempty(parallel_tb)
                app.is_parallel = false;
            end

            % Initialize the structured properties.
            InitClickStates(app);
            InitNeuronMarker(app);
            DisableImageGUI(app);

            % Initialize the neuron birth times.
            [app.hermaphrodite_neurons.names, ...
                app.hermaphrodite_neurons.birth_stages, ~] = ...
                Neurons.NeuronBirth.getHermaphroditeBirthText();
            [app.male_neurons.names, ...
                app.male_neurons.birth_stages, ~] = ...
                Neurons.NeuronBirth.getMaleBirthText();

            % Setup the GUI.
            if is_loaded
                GUIPrefs = Program.GUIPreferences.instance();

                % Are we showing the neuron birth times?
                if GUIPrefs.is_show_birth_times
                    app.ToggleBirthTimesMenu.Text = 'Hide Birth Times';
                end

                % Are auto-completing neuron names?
                app.AutoNameCheckBox.Value = GUIPrefs.is_auto_name;

                % Are we auto-updating neuron IDs?
                if ~GUIPrefs.is_autoID_updates
                    app.ToggleAutoIDUpdatesMenu.Text = 'Enable Auto-ID Updates';
                end

                % Are we using matching pursuit (MP) to detect neurons?
                if ~GUIPrefs.is_MP_detect
                    app.ToggleNeuronDetectionMenu.Text = 'Use MP-Detect Neurons';
                end

                % This is a new user, give them some help!
            else
                answer = uiconfirm(app.CELL_ID, ...
                    {['Welcome to NeuroPAL ID!'], ...
                    ['Please read the instructions very carefully.']}, ...
                    'NeuroPAL ID Instructions', 'Options', {'OK'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'info');

                % Show the instructions.
                try
                    if isdeployed
                        DataHandling.PNGViewer.show('Instructions.png', ...
                            'Software Instructions');
                    else
                        DataHandling.PNGViewer.show('./Data/Documents/Instructions.png', ...
                            'Software Instructions');
                    end
                catch ME
                    msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                    uialert(app.CELL_ID, ...
                        {'Cannot open instructions!', '', 'Error:', msg}, ...
                        'Display Instructions', 'Icon', 'error');
                    return;
                end

                % Save the GUI preferences.
                Program.GUIPreferences.save();
            end
        end

        % Callback function: OpenButton, OpenMenu
        function OpenFile(app, event)

            % Are we already opening a file?
            if app.is_opening_file
                return;
            end
            app.is_opening_file = true;
            
            % Unselect any neurons.
            UnselectNeuron(app);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Setup the file chooser's path.
            %path = '../';
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.mat;*.czi;*.nd2;*.tif;*.tiff;*.h5;*.nwb'];
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [name, path, ~] = uigetfile(file_info, 'Select Worm Image');
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if name == 0
                app.is_opening_file = false;
                return; % user cancelled
            end
            filename = [path, name];

            % Save the path in our preferences.
            GUI_prefs.image_dir = path;
            GUI_prefs.save();

            % Load the file.
            d = uiprogressdlg(app.CELL_ID,'Title','Loading file...',...
        'Indeterminate','on');
            try                
                [data, info, prefs, worm, mp, neurons, np_file, id_file] = ...
                    DataHandling.NeuroPALImage.open(filename);
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Cannot read "' filename '"!'], ['Error:' msg]}, ...
                    'Image File Failure', 'Icon', 'error');
                return;
            end
            close(d)

            % Check the worm info.
            if ~any(strcmp(worm.body, app.BodyDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm body region "' worm.body '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.age, app.AgeDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm age region "' worm.age '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end
            if ~any(strcmp(worm.sex, app.SexDropDown.Items))
                uialert(app.CELL_ID, ...
                    ['Unrecognized worm sex region "' worm.sex '" in "' filename '"!'], ...
                    'File Format Failure', 'Icon', 'error');
                return;
            end

            % Fix the prefs for z-axis orientation.
            if ~isfield(prefs, 'z_center')
                prefs.z_center = ceil(size(data,3) / 2);
                prefs.is_Z_LR = true;
                prefs.is_Z_flip = true;
            end

            % Setup the file.
            app.image_file = np_file;
            app.id_file = [];
            app.image_prefs = prefs;

            % Setup the image.
            app.image_name = name; %strrep(name, '_', '\_');
            app.image_data = data;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Load and update the gamma.
            gamma_size = length(app.gamma_RGBW_DIC_GFP_index);
            if length(prefs.gamma) == 1
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:3) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            elseif length(prefs.gamma) < gamma_size
                app.image_gamma = ones(gamma_size, 1);
                app.image_gamma(1:length(prefs.gamma)) = prefs.gamma;
                app.image_prefs.gamma = app.image_gamma;
            else
                app.image_gamma = prefs.gamma;
            end

            % Load the image scale and info.
            app.image_um_scale = info.scale;
            app.image_info = info;

            % Setup the color channels.
            RGBW = prefs.RGBW;
            RGBW_nan = isnan(RGBW);
            RGBW(RGBW_nan) = 1; % default unassigned colors to channel 1
            channels_str = arrayfun(@num2str, 1:size(app.image_data, 4), 'UniformOutput', false);
            % Red.
            app.RDropDown.Items = channels_str;
            app.RDropDown.Value = app.RDropDown.Items{RGBW(1)};
            app.RCheckBox.Value = true;
            % Green.
            app.GDropDown.Items = channels_str;
            app.GDropDown.Value = app.GDropDown.Items{RGBW(2)};
            app.GCheckBox.Value = true;
            % Blue.
            app.BDropDown.Items = channels_str;
            app.BDropDown.Value = app.BDropDown.Items{RGBW(3)};
            app.BCheckBox.Value = true;
            % White.
            app.WDropDown.Items = channels_str;
            if ~isnan(RGBW(4))
                app.WDropDown.Value = app.WDropDown.Items{RGBW(4)};
            end
            app.WCheckBox.Value = false;
            % DIC.
            app.DICDropDown.Items = channels_str;
            if ~isnan(prefs.DIC)
                app.DICDropDown.Value = app.DICDropDown.Items{prefs.DIC};
            end
            app.DICCheckBox.Value = false;
            % GFP.
            app.GFPDropDown.Items = channels_str;
            if ~isnan(prefs.GFP)
                app.GFPDropDown.Value = app.GFPDropDown.Items{prefs.GFP};
            end
            app.GFPCheckBox.Value = false;

            % Setup the worm info.
            app.worm = worm;
            app.BodyDropDown.Value = worm.body;
            app.AgeDropDown.Value = worm.age;
            app.SexDropDown.Value = worm.sex;
            app.StrainEditField.Value = worm.strain;
            app.NotesEditField.Value = worm.notes;

            % Which part of the worm is this?
            %if isempty(worm.body)
            %    worm.body = uiconfirm(app.CELL_ID, ...
            %        'Which part of the worm is in the image?', ...
            %        'Worm Body Part', 'Options', {'Head', 'Tail'}, ...
            %        'DefaultOption', 1, 'Icon', 'question');
            %   	%'Worm Body Part', 'Head', 'Midbody', 'Tail', 'Head');
            %    save(app.image_file, 'prefs', '-append');
            %end

            % If NWB file, load movie.
            if strcmp(filename(end-2:end),'nwb')
                try
                    d = uiprogressdlg(app.CELL_ID,'Title','Loading movie...',...
            'Indeterminate','on');
                    whole_file = nwbRead(filename);
                    gc_data = whole_file.acquisition.get('GCaMP_series');
                    gc_data = permute(gc_data,[2,3,1,4]);
                    app.movie_var = gc_data.data.load();
                    save('gcamp.mat', 'app.movie_var');
                    close(d)
                catch
                    'Unable to load any movie.'
                end
            end

            % Enable the image GUI.
            EnableImageGUI(app);
            DisableNeuronGUI(app);

            % Determine the image scale.
            scale = ones(1,3);
            if ~isempty(info.scale)
                scale = info.scale;
            end

            % Did we detect neurons?
            app.id_file = id_file;
            app.mp_params = mp;
            if ~isempty(neurons)
                app.image_neurons = neurons;
            else
                app.image_neurons = Neurons.Image([], worm.body, 'scale', app.image_um_scale');
            end


            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end

            % Restrict the slider to the z stack.
            num_z_slices = size(app.image_data, 3);
            z_slices = 1:num_z_slices;
            if num_z_slices <= 1
                uialert(app.CELL_ID, 'The image is not a volume!', ...
                    'Image Not a Volume', 'Icon', 'error');
                return;
            else
                z_labels = arrayfun(@(z) num2str(z, '%.1f'), ...
                    (z_slices-1) * info.scale(3), 'UniformOutput', false);
            end
            app.ZSlider.Limits = [1, num_z_slices];
            app.ZSlider.MajorTicks = z_slices;
            app.ZSlider.MajorTickLabels = z_labels;

            % Setup the z-axis orientation.
            app.ZCenterEditField.Value = round((prefs.z_center - 1) * info.scale(3), 1);
            if prefs.is_Z_LR
                app.ZAxisDropDown.Value = 'L/R';
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';
            else
                app.ZAxisDropDown.Value = 'D/V';
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Setup the max projection.
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            axis(app.MaxProjection, 'off');

            % Constrain the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];

            % Label the image.
            app.XY.Title.Interpreter = 'none';
            app.XY.Title.String = app.image_name;
            app.XY.TitleFontSizeMultiplier = 2;
            app.XY.TitleFontWeight = 'bold';
            x_ticks = linspace(0, size(app.image_data, 2), 15);
            y_ticks = linspace(0, size(app.image_data, 1), 5);
            app.XY.XTick = x_ticks;
            app.XY.YTick = y_ticks;
            x_labels = arrayfun(@(x) num2str(x, '%.1f'), x_ticks * info.scale(1), 'UniformOutput', false);
            y_labels = arrayfun(@(y) num2str(y, '%.1f'), y_ticks * info.scale(2), 'UniformOutput', false);
            x_labels{1} = '0';
            y_labels{1} = '0';
            app.XY.XTickLabel = x_labels;
            app.XY.YTickLabel = flip(y_labels);

            % Select no neurons.
            app.selected_neuron = [];

            % Draw everything.
            app.UnselectNeuron();
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};
            app.DrawImageData();
            app.UpdateSexNeuronLists();
            if ~isempty(app.image_neurons)
                app.UpdateNeuronLists();
                app.DrawAutoIDList();
            end

            % Go to the middle.
            zEvent.Value = round(num_z_slices / 2);
            app.ZSliderValueChanging(zEvent);

            % Done.
            app.is_opening_file = false;
        end

        % Menu selected function: DownsampleImageMenu
        function DecimateButtonPushed(app, event)
            downsample_window(app, app.image_data, app.image_view, app.image_gamma, app.image_neurons);        
        end

        % Menu selected function: ThresholdImageMenu
        function AdaptThreshButtonPushed(app, event)
            % Open a propt to ask the adaptive thresholding parameters
            % from user.
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);

            % Run thresholding based on user input.
            app.image_data = Methods.Preprocess.threshold_frame(app.image_data, answer{1}, str2double(answer{2}));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the thresholded image.
            app.DrawImageData();
        end

        % Menu selected function: HistogramMatchingMenu
        function HistMatchButtonPushed(app, event)
            % Histogram matching for color normalization. The function
            % has no parameters.
            app.image_data = Methods.Preprocess.histmatch_frame(double(app.image_data));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the histogram matched image.
            app.DrawImageData();
        end

        % Menu selected function: AutoHistogramMenu
        function AutoHistogram(app, event)
            % Auto Adjust the histogram.
            RGB = app.image_prefs.RGBW(1:3);
            app.image_data(:,:,:,RGB) = imadjustn(app.image_data(:,:,:,RGB));
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            
            % Update the view using the histogram matched image.
            app.DrawImageData();
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)

            % Snap the slider into place.
            z = round(event.Value);
            app.ZSlider.Value = z;

            % Draw the neurons in this z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Callback function
        function ZSliderSValueChanging(app, event)

            % Snap the slider into place.
            z = round(event.Value);
            app.ZSliderS.Value = z;

            % Draw the neurons in this z-slice.
            DrawZSlice(app, app.ZSliderS, app.recon_view, app.XYS);
        end

        % Button pushed function: AutoDetectButton
        function AutoDetectButtonPushed(app, event)

            % Determine the number of neurons.
            body = app.BodyDropDown.Value;
            num_neurons = app.neuron_info.numNeurons(body);
            if isempty(num_neurons)
                return;
            end

            % Do we already have neurons detected?
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                answer = uiconfirm(app.CELL_ID, ...
                    'Neurons have already been marked, would you like to overwrite them?', ...
                    'Neurons Already Marked', ...
                    'Options', {'Overwrite', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ItemsData = [];
            app.UserNeuronIDsListBox.Value = {};

            % Detect the neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            if GUI_prefs.is_MP_detect
                [sp, app.mp_params] = Methods.AutoDetect.instance().detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    num_neurons, 1.0, app.image_um_scale', app.worm, 2);
            else
                [sp, app.mp_params] = Methods.NNDetect.detect(app.image_file, ...
                    app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            end

            % Do we have any neuron predictions?
            if isempty(sp)

                % Warn the user.
                uiconfirm(app.CELL_ID, ...
                    'Auto detect neurons failed to find any neurons!', ...
                    'Auto Detect Neurons Failed', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');

                % Enable the neuron GUI.
                if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                    EnableNeuronGUI(app);
                end
                return;
            end

            % Add the detected neurons to the image.
            app.image_neurons = Neurons.Image(sp, app.worm.body, 'scale', app.image_um_scale');

            % Remove duplicate detections.
            % Neurons within 2 microns of each other.
            Methods.Utils.removeNearbyNeurons(app.image_neurons, 2, 2);

            % Save the IDs.
            SaveIDToFile(app);

            % Update the ID'd neuron count.
            UpdateNeuronLists(app);

            % Enable the neuron GUI.
            if ~isempty(app.image_neurons) && app.image_neurons.num_neurons() > 0
                EnableNeuronGUI(app);
            end

            % Draw the image.
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto detect neurons completed successfully.', 'Auto Detect Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Button pushed function: AutoIDAllButton
        function AutoIDAllButtonPushed(app, event)

            % Are there any neurons?
            if isempty(app.image_neurons) || app.image_neurons.num_neurons() < 1
                uiconfirm(app.CELL_ID, ...
                    'There are no neurons to auto ID!', 'No Neurons to ID', ...
                    'Options', {'Cancel'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'warning');
                return;
            end

            % Can we handle this body part?
            %body = app.image_neurons.bodypart; DEPRECATED
            body = app.worm.body;
            switch lower(body)
                case 'head'
                    % all good
                case 'tail'
                    % all good
                otherwise
                    uiconfirm(app.CELL_ID, ...
                        {['Presently, there is no atlas to auto ID the "' body '".'], ...
                        'Please manually ID this region.'}, ...
                        'No Atlas for Auto ID', 'Options', {'Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                    return;
            end

            % Are the neurons already ID'd?
            if ~isempty(app.image_neurons.get_probabilistic_probs())
                answer = uiconfirm(app.CELL_ID, ...
                    'The neurons have already been auto ID''d, would you like to re-ID them?', ...
                    'Neurons Already Auto ID''d', ...
                    'Options', {'Re-ID', 'Cancel'}, 'DefaultOption', 2, 'CancelOption', 2, ...
                    'Icon', 'warning');
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Unselect neurons.
            UnselectNeuron(app);

            % Auto ID the neurons.
            try
                Methods.AutoId.instance().id(app.image_file, ...
                    app.image_neurons, app.worm);
            catch E
                uiconfirm(app.CELL_ID, ...
                    {E.message}, ...
                    'Error', 'Options', {'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 1, 'Icon', 'error');
                return;
            end

            % Update the auto ID'd neuron list.
            DrawAutoIDList(app);

            % Draw the image.
            app.DrawImageData();

            % Save the IDs.
            SaveIDToFile(app);

            % Update ID confidences
            UpdateNeuronIDConfidence(app);

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Auto ID neurons completed successfully.', 'Auto ID Neurons Completed', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Value changed function: AutoNameCheckBox
        function AutoNameNeurons(app, event)

            % Save the GUI preferences.
            GUI_prefs = Program.GUIPreferences.instance();
            GUI_prefs.is_auto_name = app.AutoNameCheckBox.Value;
            GUI_prefs.save();
        end

        % Menu selected function: NormalizeColorsMenu
        function ZScoringMenuSelected(app, event)
            app.image_data = Methods.Preprocess.zscore_frame(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();
        end

        % Menu selected function: RotateAllHorizontalMenu
        function RotateAllHorizontal(app, event)
            % Rotate the image and neurons pi degrees around x-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi,0,0]);
            app.image_data = app.image_neurons.rotate_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllVerticalMenu
        function RotateAllVertical(app, event)
            % Rotate the image and neurons pi degrees around y-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [0,pi,0]);
            app.image_data = app.image_neurons.rotate_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateAllClockwiseMenu
        function RotateAllClockwise(app, event)
            % Rotate the image and neurons pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateAllAntiClockwiseMenu
        function RotateAllAntiClockwise(app, event)
            % Rotate the image and neurons -pi/2 degrees around z-axis.
            %app.image_data = app.image_neurons.rotate(app.image_data, [-pi/2,0,0]);
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageHorizontalMenu
        function RotateImageHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around x-axis.
            app.image_data = app.image_neurons.rotate_image_X_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageVerticalMenu
        function RotateImageVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi degrees around y-axis.
            app.image_data = app.image_neurons.rotate_image_Y_180(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateImageClockwiseMenu
        function RotateImageClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_90(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateImageAntiClockwiseMenu
        function RotateImageAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the image separately from the neurons?', ...
                'Rotate Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the image -pi/2 degrees around z-axis.
            [app.image_data, app.image_info.scale] = ...
                app.image_neurons.rotate_image_Z_270(app.image_data, app.image_info.scale);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            info = app.image_info;
            save(app.image_file, 'data', 'info', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsHorizontalMenu
        function RotateNeuronsHorizontal(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_X_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsVerticalMenu
        function RotateNeuronsVertical(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi degrees around x-axis.
            app.image_neurons.rotate_neurons_Y_180(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsClockwiseMenu
        function RotateNeuronsClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_90(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: RotateNeuronsAntiClockwiseMenu
        function RotateNeuronsAntiClockwise(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to rotate the neurons separately from the image?', ...
                'Rotate Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Rotate the neurons -pi/2 degrees around z-axis.
            app.image_neurons.rotate_neurons_Z_270(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.DrawImageData();
        end

        % Menu selected function: MirrorImageMenu
        function MirrorImage(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the image separately from the neurons?', ...
                'Mirror Image', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the image around the z-axis.
            app.image_data = app.image_neurons.mirror_image_Z(app.image_data);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);

            % Save the image & neurons.
            data = app.image_data;
            save(app.image_file, 'data', '-append');
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Menu selected function: MirrorNeuronsMenu
        function MirrorNeurons(app, event)
            % Warn the user.
            answer = uiconfirm(app.CELL_ID, ...
                'Are you sure you want to mirror the neurons separately from the image?', ...
                'Mirror Neurons', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Don't rotate.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Mirror the neurons around the z-axis.
            app.image_neurons.mirror_neurons_Z(app.image_data);

            % Save the image & neurons.
            SaveIDToFile(app);

            % Redraw the image.
            app.XY.XLim = [0, size(app.image_data, 2)];
            app.XY.YLim = [0, size(app.image_data, 1)];
            app.DrawImageData();
        end

        % Value changed function: AutoIDDropDown
        function AutoIDDropDownValueChanged(app, event)
            % Update the user ID field with the chosen value.
            value = app.AutoIDDropDown.Value;
            names = strsplit(value,'=');
            app.IDEditField.Value = names{1};

            % Send focus to the user ID field.
            SendFocus(app, app.IDEditField);
        end

        % Value changed function: UserNeuronIDsListBox
        function AutoNeuronIDListBoxValueChanged(app, event)

            % Find the neuron's rank.
            neuron_rank = app.UserNeuronIDsListBox.Value;

            % Select the neuron.
            [~, neuron_i] = app.image_neurons.find_rank(neuron_rank);
            SelectNeuron(app, neuron_i);

            % Hack: Matlab App Designer sucks, I shouldn't have to do this.
            SendFocus(app, app.IDEditField);
        end

        % Key press function: CELL_ID
        function CELL_IDKeyPress(app, event)

            % Is there an image?
            if isempty(app.image_data)
                return;
            end

            % What did the user press?
            key = event.Key;
            switch key

                case '0' % Toggle the ID atlas.
                    app.ColorAtlasCheckBox.Value = ~app.ColorAtlasCheckBox.Value;
                    app.DrawImageData();

                case '1' % Toggle the red channel.
                    app.RCheckBox.Value = ~app.RCheckBox.Value;
                    app.DrawImageData();

                case '2' % Toggle the green channel.
                    app.GCheckBox.Value = ~app.GCheckBox.Value;
                    app.DrawImageData();

                case '3' % Toggle the blue channel.
                    app.BCheckBox.Value = ~app.BCheckBox.Value;
                    app.DrawImageData();

                case '4' % Toggle the white channel.
                    app.WCheckBox.Value = ~app.WCheckBox.Value;
                    app.DrawImageData();

                case '5' % Toggle the DIC channel.
                    app.DICCheckBox.Value = ~app.DICCheckBox.Value;
                    app.DrawImageData();

                case '6' % Toggle the GFP channel.
                    app.GFPCheckBox.Value = ~app.GFPCheckBox.Value;
                    app.DrawImageData();

                case 'leftarrow' % Go up in the z stack.
                    if app.ZSlider.Value > 1
                        zEvent.Value = app.ZSlider.Value - 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'rightarrow' % Go down in the z stack.
                    if app.ZSlider.Value < app.ZSlider.Limits(2)
                        zEvent.Value = app.ZSlider.Value + 1;
                        app.ZSliderValueChanging(zEvent);
                    end
                    %disp(key);

                case 'uparrow' % Zoom in.
                    zoom(app.XY, 1 + app.zoom_percent);

                case 'downarrow' % Zoom out.
                    zoom(app.XY, 1 - app.zoom_percent);

                case {'h','H'} % Toggle half zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> lower half.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/2
                        app.XY.YLim = [ceil(size(app.image_data, 1)/2), ...
                            size(app.image_data, 1)];

                        % Lower -> upper half.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/2)];
                    end

                case {'j','J'} % Toggle third zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> middle third.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)/3), ...
                            floor(size(app.image_data, 1)*2/3)];

                        % Middle -> lower third.
                    elseif ylims(1) < size(app.image_data, 1)*2/3
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/3), ...
                            size(app.image_data, 1)];

                        % Lower -> upper third.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/3)];
                    end

                case {'k','K'} % Toggle quarter zoom.
                    % Reset x-axis limits.
                    app.XY.XLim = [1, size(app.image_data, 2)];

                    % Upper -> upper-middle quarter.
                    ylims = app.XY.YLim;
                    if ylims(1) < size(app.image_data, 1)/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)/4), ...
                            floor(size(app.image_data, 1)*2/4)];

                        % Upper-middle -> lower-middle quarter.
                    elseif ylims(1) < size(app.image_data, 1)*2/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*2/4), ...
                            floor(size(app.image_data, 1)*3/4)];

                        % Lower-middle -> lower quarter.
                    elseif ylims(1) < size(app.image_data, 1)*3/4
                        app.XY.YLim = [ceil(size(app.image_data, 1)*3/4), ...
                            size(app.image_data, 1)];

                        % Lower -> upper quarter.
                    else
                        app.XY.YLim = [1, floor(size(app.image_data, 1)/4)];
                    end

                case {'p','P'} % Toggle image panning.

                    % Toggle image panning.
                    if app.is_panning
                        pan(app.XY,'off');
                        pause(0.5);
                        RestorePointer(app);
                        app.is_panning = false;
                    else
                        pan(app.XY,'on');
                        pause(0.5);
                        app.is_panning = true;
                    end

                case {'r','R'} % Reset the figure to center.
                    app.XY.XLim = [1, size(app.image_data, 2)];
                    app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XLim = [1, size(app.image_data, 2)];
                    %app.XY.YLim = [1, size(app.image_data, 1)];
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';

                    %case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');

                case {'i','I'} % Toggle showing neuron IDs.
                    app.show_labels = ~app.show_labels;
                    DrawZSlice(app, app.ZSlider, app.image_view, app.XY);

                case {'a', 'A'} % Toggle autocompleting neuron names.
                    app.AutoNameCheckBox.Value = ~app.AutoNameCheckBox.Value;
                    AutoNameNeurons(app, event);

                case {'b','B'} % Toggle showing neuron birth times.
                    ToggleNeuronBirthTimes(app, event);

                otherwise
                    % Do nothing.
            end

        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            % ZSliderValueChanging(app, event);
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Close request function: CELL_ID
        function CELL_IDCloseRequest(app, event)

            % Unselect any neurons.
            app.UnselectNeuron(false);
            if ~isempty(app.id_file) && exist(app.id_file, 'file')
                app.SaveIDToFile();
            end

            % Save the log file for further 'number of user clicks' analysis.
            if ~isempty(app.log)
                log = app.log;
                [path, name, ~] = fileparts(app.image_file);
                directory = [path, filesep, 'logs', filesep];
                if ~exist(directory, 'dir')
                    mkdir(directory);
                end
                save([directory,name, '_',strrep(datestr(datetime('now')),':','_'), '.mat'], 'log');
            end

            % Done.
            delete(app);
        end

        % Callback function
        function SaveButtonPushed(app, event)
            % Save the final result of running MP and annotations in file.
            SaveIDToFile(app);
        end

        % Callback function
        function RotateSliderValueChanged(app, event)
            % Read the rotation angle.
            value = app.RotateSlider.Value;

            % Rotate image and all the neurons -> see Image.m
            app.image_data = app.image_neurons.rotate(app.image_data, [value,0,0]);
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            % Rotated image might have different dimensions so here we are updating the ZLimits.
            app.ZSlider.Limits = [1, size(app.image_data, 3)];
            app.ZSliderS.Limits = [1, size(app.image_data, 3)];

            % Bring the rotate slider back to zero.
            app.RotateSlider.Value = 0;

            % Update the views.
            app.DrawImageData();
        end

        % Callback function
        function AutoDetectCheckBoxValueChanged(app, event)
            value = app.AutoDetectCheckBox.Value;
            app.image_neurons.add_meta_data('auto_detect', value);
        end

        % Value changed function: BCheckBox, BDropDown, DICCheckBox, 
        % ...and 9 other components
        function UpdateColorChannels(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Redraw the image.
            app.DrawImageData();

            % Initialize the updated color channels.
            red = str2double(app.RDropDown.Value);
            green = str2double(app.GDropDown.Value);
            blue = str2double(app.BDropDown.Value);
            white = str2double(app.WDropDown.Value);
            dic = str2double(app.DICDropDown.Value);
            gfp = str2double(app.GFPDropDown.Value);
            old_RGBW = app.image_prefs.RGBW;
            new_RGBW = [red, green, blue, white];
            app.image_prefs.RGBW = new_RGBW;
            app.image_prefs.DIC = dic;
            app.image_prefs.GFP = gfp;

            % Update the neurons.
            app.image_neurons.update_colors(app.image_data_zscored, old_RGBW, new_RGBW);
            if ~isequaln(old_RGBW, new_RGBW)
                app.SaveIDToFile();
            end

            % Save the updated preferences.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');
        end

        % Value changing function: IDEditField
        function IDEditFieldChanging(app, event)
            value = event.Value;

            % Space means done.
            if length(value) > 0 && isspace(value(end))
                SaveUserID(app, value);
            end
        end

        % Button pushed function: UserIDButton
        function UserIDButtonPushed(app, event)
            SaveUserID(app, app.IDEditField.Value);
        end

        % Selection changed function: GanglionTree
        function GanglionTreeSelectionChanged(app, event)

            % Update the ID'd & UnID'd neurons list.
            app.IDdNeuronsListBox.Value = {};
            app.IDdLabel.BackgroundColor = 'none';
            app.IDdLabel.FontColor = [0,0,0];
            app.UnIDdNeuronsListBox.Value = {};
            app.UnIDdLabel.BackgroundColor = 'none';
            app.UnIDdLabel.FontColor = [0,0,0];
            UnselectNeuron(app);
            UpdateNeuronLists(app);
        end

        % Value changed function: IDdNeuronsListBox
        function IDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron.
            value = app.IDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name.
                % Remove birth_time, confidence, and ON/OFF.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end
                if endsWith(value, ' ?')
                    value = value(1:(end-2));
                end
                value = app.neuron_info.stripOnOff(value);

                % No color available.
                color = app.neuron_info.getNeuronColor(value);
                if isempty(color)
                    app.IDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.IDdLabel.FontColor = [0,0,0];

                    % Show the selected NeuroPAL neuron's color.
                else
                    app.IDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.IDdLabel.FontColor = [0,0,0];
                    else
                        app.IDdLabel.FontColor = [1,1,1];
                    end
                end

                % Select the neuron & show its name.
                [neuron, neuron_i] = app.image_neurons.find_user_id_neuron(value);
                if ~isempty(neuron_i)

                    % Select the neuron.
                    SelectNeuron(app, neuron_i);

                    % Is the neuron ON/OFF?
                    name = neuron.annotation;

                    switch neuron.is_annotation_on
                        case false
                            name = [name '-OFF'];
                        case true
                            name = [name '-ON'];
                    end

                    % Is the user uncertain about the ID?
                    if neuron.annotation_confidence <= 0.5
                        name = [name '?'];
                    end

                    % Is the neuron emphasized?
                    if neuron.is_emphasized
                        name = [name '!'];
                    end

                    % Show the neuron's ID.
                    DrawImageLabels(app, neuron.position, {name});
                end
            end
        end

        % Double-clicked callback: IDdNeuronsListBox
        function IDdNeuronsListBoxDoubleClicked(app, event)
            name = app.IDdNeuronsListBox.Items{event.InteractionInformation.Item};

            if strcmp(app.ActivityTrace.Visible,'on')
                app.update_browser(name);
            end
            
        end

        % Value changed function: UnIDdNeuronsListBox
        function UnIDdNeuronsListChanged(app, event)

            % Show the selected NeuroPAL neuron's color.
            value = app.UnIDdNeuronsListBox.Value;
            if ~isempty(value)

                % Get the neuron name. Remove birth_time.
                space_i = find(value == ' ', 1);
                if ~isempty(space_i)
                    value = value(1:space_i-1);
                end

                % Get the neuron color.
                color = app.neuron_info.getNeuronColor(value);

                % No color available.
                if isempty(color)
                    app.UnIDdLabel.BackgroundColor = [0.95,0.95,0.95];
                    app.UnIDdLabel.FontColor = [0,0,0];

                    % Set the color.
                else
                    app.UnIDdLabel.BackgroundColor = app.neuron_info.getNeuronColor(value);

                    % Make the font visible against the background color.
                    if mean(color) > 0.5
                        app.UnIDdLabel.FontColor = [0,0,0];
                    else
                        app.UnIDdLabel.FontColor = [1,1,1];
                    end
                end
            end
        end

        % Button pushed function: AutoIDButton
        function AutoIDButtonPushed(app, event)
            % Save the chosen value.
            value = app.AutoIDDropDown.Value;
            if isempty(value)
                return;
            end
            names = upper(strsplit(value,'='));
            SaveAutoID(app, names{1});
        end

        % Menu selected function: DeleteUserIDsMenu
        function DeleteUserIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'Delete all the neuron''s user IDs???', 'You will lose all your work!'}, ...
                'Delete All Neuron User IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all user IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_annotations();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: DeleteModelIDsMenu
        function DeleteModelIDs(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                'Delete all the neuron''s model IDs?', ...
                'Delete All Neuron Model IDs', 'Options', {'Yes', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

            % Delete all user IDs.
            if strcmpi(answer, 'yes')

                % Delete all model IDs.
                UnselectNeuron(app);
                app.image_neurons.delete_model_IDs();

                % Save the updated IDs.
                SaveIDToFile(app);

                % Uncheck the atlas.
                app.ColorAtlasCheckBox.Value = false;

                % Draw the auto ID neuron uncertainty list.
                app.UserNeuronIDsListBox.Items = {};
                app.UserNeuronIDsListBox.Value = {};

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ToggleAutoIDUpdatesMenu
        function ToggleAutoIDUpdates(app, event)
            % Toggle disabling or enabling neuron auto-ID updates.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleAutoIDUpdatesMenu.Text), 'disable')
                GUI_prefs.is_autoID_updates = false;
                app.ToggleAutoIDUpdatesMenu.Text = 'Enable Auto-ID Updates';
            else
                GUI_prefs.is_autoID_updates = true;
                app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Menu selected function: ToggleNeuronDetectionMenu
        function ToggleNeuronDetection(app, event)
            % Toggle matching-pursuit (MP) vs neural-network (NN)
            % neuron detection.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleNeuronDetectionMenu.Text), 'mp')
                GUI_prefs.is_MP_detect = true;
                app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            else
                GUI_prefs.is_MP_detect = false;
                app.ToggleNeuronDetectionMenu.Text = 'Use MP-Detect Neurons';
            end

            % Save the GUI preferences.
            GUI_prefs.save();
        end

        % Value changed function: ZAxisDropDown
        function ZAxisChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Set the Z-axis to be L/R.
            value = app.ZAxisDropDown.Value;
            if strcmp(value, 'L/R')
                app.image_prefs.is_Z_LR = true;
                app.ZLeftLabel.Text = 'LEFT';
                app.ZRightLabel.Text = 'RIGHT';

                % Set the Z-axis to be D/V.
            else
                app.image_prefs.is_Z_LR = false;
                app.ZLeftLabel.Text = 'DORSAL';
                app.ZRightLabel.Text = 'VENTRAL';
            end

            % Save the update.
            if ~app.is_opening_file
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');
            end
        end

        % Button pushed function: FlipZButton
        function FlipZButtonPushed(app, event)

            % Flip the Z-axis.
            app.image_prefs.is_Z_flip = ~app.image_prefs.is_Z_flip;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Value changed function: ZCenterEditField
        function ZCenterChanged(app, event)

            % Are we opening a file?
            if app.is_opening_file
                return;
            end

            % Did the center change?
            value = app.ZCenterEditField.Value;
            z_center = round(value / app.image_um_scale(3)) + 1;
            if z_center == app.image_prefs.z_center
                return;
            end

            % Is the center in range?
            min_z = round(app.image_um_scale(3), 1);
            max_z = round((size(app.image_data,3) - 2) * app.image_um_scale(3), 1);
            if value < min_z || value > max_z
                uiconfirm(app.CELL_ID, ...
                    {[num2str(value, '%.1f') ' is out of range!'], ...
                    ['The z-axis center must be between [' num2str(min_z, '%.1f') ',' num2str(max_z, '%.1f')  '].']}, ...
                    'Z-Axis Center Error', 'Options', {'Ok'}, 'DefaultOption', 1, 'CancelOption', 1, ...
                    'Icon', 'error');

                % Restore the previous value.
                value = round((app.image_prefs.z_center - 1) * app.image_um_scale(3), 1);
                app.ZCenterEditField.Value = value;
            end

            % Change the center of the Z-axis.
            app.image_prefs.z_center = z_center;

            % Save the update.
            prefs = app.image_prefs;
            save(app.image_file, 'prefs', '-append');

            % Redraw the Z-slice.
            DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
        end

        % Menu selected function: ManualMenu
        function ManualArtifactRemoval(app, event)
            % Give the user instruction on how to remove artifacts.
            answer = uiconfirm(app.CELL_ID, ...
                {'To manually remove artifacts:', ...
                '1. Use the cursor to add vertices, drawing', ...
                '   a polygon around the image artifacts.', ...
                '2. When done, click the 1st point of your polygon', ...
                '   or hit the return key on your keyboard.'}, ...
                'Manually Remove Artifacts', 'Options', {'Remove Artifacts', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

            % Keep the existing file.
            if strcmpi(answer, 'cancel')
                return;
            end

            % Unselect any neurons.
            app.UnselectNeuron(false);

            % Manually trace the artifacts.
            mask = Methods.Preprocess.manual_artifact_removal(app.image_view);
            if isempty(mask)
                return;
            end

            % Remove any neurons in the artifact polygon.
            if ~isempty(app.image_neurons) && ~isempty(app.image_neurons.neurons)

                % Remove the neurons in the polygon.
                neurons = app.image_neurons.neurons;
                positions = app.image_neurons.get_positions();
                z = app.ZSlider.Value;
                if app.image_prefs.is_Z_flip
                    z = size(app.image_data,3) - z + 1;
                end
                inside_mask = mask(sub2ind(size(mask), round(positions(:,1)), round(positions(:,2))));
                app.image_neurons.neurons = neurons(~(positions(:,3) < z+1.5 & positions(:,3) > z-1.5 & inside_mask));

                % Redraw the auto ID list.
                app.DrawAutoIDList();
            end
            mask = repmat(mask,1,1,size(app.image_data,3),length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();
        end

        % Menu selected function: AutomaticMenu
        function AutomaticArtifactRemoval(app, event)

            % Find artifacts.
            [~, mask_raw] = Methods.Preprocess.filter_gut_lysosomes(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));
            mask = repmat(mask_raw,1,1,1,length(app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))));

            % Remove artifacts.
            tmp = app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW)));
            tmp(mask) = 0;
            app.image_data(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))) = tmp;
            app.image_data(mask) = 0;

            % Z-score the image.
            app.image_data_zscored = Methods.Preprocess.zscore_frame(app.image_data);
            app.DrawImageData();

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Automatic artifact removal completed successfully.', ...
                'Automatically Remove Artifacts', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Menu selected function: BatchDetectIDNeuronsMenu
        function BatchIDNeurons(app, event)
            %% Purpose: batch detect+ID neurons for all image files in a directory.

            % Setup the file chooser's path.
            % Note: don't change the user's preferred path.
            %path = '../../';
            path = Program.GUIPreferences.instance().image_dir;

            % Ask the user which folder they want to use.
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            folder = uigetdir(path, 'Select Worm Image Folder');
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if folder == 0
                return; % user cancelled
            end

            % Determine the image files.
            dir_files = dir(folder);
            files = {dir_files.name};
            formats = {'.czi', '.nd2', '.lif', '.tif', '.tiff', '.h5'};
            files_i = find(endsWith(files, formats, 'IgnoreCase', true));
            dir_files = dir_files(files_i);
            num_files = length(dir_files);

            % Are there any files?
            if num_files < 1
                uiconfirm(app.CELL_ID, ...
                    {'No image file present in:', folder}, ...
                    'No Image Files Present', ...
                    'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'error');
                return;
            end

            % Did we already detect & ID neurons in these files?
            for i = num_files

                % Construct the ID file.
                name = dir_files(i).name;
                name_dot_i = strfind(name, '.');
                name_dot_i = name_dot_i(end);
                name = [name(1:(name_dot_i - 1)), '_ID.mat'];
                id_file = [dir_files(i).folder '/' name];

                % Does the ID file exist?
                if exist(id_file, 'file')
                    answer = uiconfirm(app.CELL_ID, ...
                        {'Some of the ID files already exist!', ' ', 'Do you want to overwrite them?'}, ...
                        'Overwrite ID Files', 'Options', {'Yes', 'Cancel'}, ...
                        'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                    % Keep the existing file.
                    if strcmpi(answer, 'cancel')
                        return;
                    end

                    % Done.
                    break;
                end
            end

            % What is the age of these worms?
            title = 'Worm Age';
            prompt  = 'What is the age of these worms?';
            list = app.AgeDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [age_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(age_i)
                return;
            end
            age = list{age_i};

            % What sex are these worms?
            title = 'Worm Sex';
            prompt  = 'What is the sex of these worms?';
            list = app.SexDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [sex_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(sex_i)
                return;
            end
            sex = list{sex_i};

            % Which section of the body are we detecting?
            title = 'Worm Body Section';
            prompt  = 'Which section of the worm is in these images?';
            list = app.BodyDropDown.Items;
            list = list(~cellfun(@isempty, list));
            app.CELL_ID.Visible = 'off'; % Hack On! * Matlab can't seem to put the modal dialogue in the foreground
            [body_i,~] = listdlg('PromptString', prompt, 'Name', title, ...
                'SelectionMode', 'single', 'ListString', list);
            app.CELL_ID.Visible = 'on'; % Hack Off! * Matlab can't seem to put the modal dialogue in the foreground
            if isempty(body_i)
                return;
            end
            body = list{body_i};

            % Create the worm info.
            worm_info.age = age;
            worm_info.sex = sex;
            worm_info.body = body;
            worm_info.strain = '';
            worm_info.notes = '';

            % Determine the number of neurons.
            GUI_prefs = Program.GUIPreferences.instance();
            num_neurons = app.neuron_info.numNeurons(body);
            if isempty(num_neurons) && GUI_prefs.is_MP_detect
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Please use the "Neurons" menu to select "Use NN-Detect Neurons".'}, ...
                    'Unsupported Body Section');
                return;
            end

            % Setup the file names.
            files = cell(length(dir_files),1);
            for i = 1:length(files)
                files{i} = [dir_files(i).folder '/' dir_files(i).name];
            end

            % Batch detect the neurons.
            is_parallel = app.is_parallel;
            if is_parallel

                % Start the parallel pool.
                if isempty(app.parallel_pool) || ...
                        ~isvalid(app.parallel_pool) || ...
                        ~app.parallel_pool.Connected
                    app.parallel_pool = parpool('local');
                end

                % Setup the progress bar.
                wait_title = 'Detecting Neurons';
                wb = waitbar(0, 'Initializing ...', 'Name', wait_title);
                wb.Children.Title.Interpreter = 'none';
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    if GUI_prefs.is_MP_detect
                        funcs(i) = parfeval(... %app.parallel_pool, ...
                            @Methods.AutoDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                    else
                        funcs(i) = parfeval(... %app.parallel_pool, ...
                            @Methods.NNDetect.BatchDetect, 0, files{i}, worm_info, num_neurons);
                    end
                    waitbar(0, wb, ...
                        ['Detecting neurons in ' num2str(i) ' files.'], ...
                        'Name', wait_title);
                else
                    if GUI_prefs.is_MP_detect
                        Methods.AutoDetect.BatchDetect(files{i}, worm_info, num_neurons);
                    else
                        Methods.NNDetect.BatchDetect(files{i}, worm_info, num_neurons);
                    end
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    funcs_i = fetchNext(funcs);

                    % Update the progress.
                    progress = double(i)/num_files;
                    waitbar(progress, wb, {['Completed: ' files{funcs_i}], ...
                        sprintf('%d%% completed ...', progress*100)}, ...
                        'Name', wait_title);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));

                % Done.
                try
                    close(wb);
                catch
                    warning('The detection was canceled.');
                end
            end

            % Can we ID this body part?
            if ~Methods.AutoId.isBodyID(sex, body)
                uialert(app.CELL_ID, ...
                    {['The body section "' body '" is not yet supported.'], ' ', ...
                    'Neurons in this section of the body cannot yet be identified.'}, ...
                    'No ID for Body Section');
                return;
            end

            % Batch ID the neurons.
            is_parallel = false; % auto ID uses its own parallelization & cannot be called with a parallel pool.
            if is_parallel
                delete funcs;
                %pool = gcp(); % may want to have a ready-use pool
            end
            for i = 1:num_files

                % Batch detect the neurons.
                if is_parallel
                    funcs(i) = parfeval(... %app.parallel_pool, ...
                        @Methods.AutoId.BatchId, 0, files{i}, worm_info);
                else
                    Methods.AutoId.BatchId(files{i}, worm_info);
                end
            end

            % Wait for each task to complete.
            if is_parallel
                for i = 1:num_files
                    fetchNext(funcs);
                end

                % Terminate the existing parallel session.
                delete(gcp('nocreate'));
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Batch auto detect + ID neurons completed successfully.', ...
                'Batch Auto Detect + ID Neurons', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Callback function: SaveIDInfoMenu, SaveIDsButton
        function SaveIDs2CSV(app, event)
            %%  Purpose: save the IDs, position, & colors to a CSV file.

            % Do we have any neurons ID'd?
            if ~app.image_neurons.is_any_auto_ID() && ~app.image_neurons.is_any_annotated()
                uialert(app.CELL_ID, ...
                    'No neurons have been assigned an ID!',...
                    'No Neurons ID''d', 'Icon', 'warning');
                return;
            end

            % Warn the user to align the neurons.
            answer = uiconfirm(app.CELL_ID, ...
                {'Aligned neuron colors + positions REQUIRE', ...
                'correct auto ID''s for analysis!', ' ', ...
                'Do you want to (re-)run auto ID prior to saving the IDs?'}, ...
                'Neuron Color + Position', 'Options', {'Yes', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3, 'Icon', 'info');
            if strcmpi(answer, 'cancel')
                return;
            elseif strcmpi(answer, 'yes')
                app.AutoIDAllButtonPushed(event);
            end

            % Create the filename "<filename>_IDs.csv".
            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            filename = app.image_file;
            suffixI = strfind(filename, '.');
            suffixI = suffixI(end);
            csvfile = cat(2, filename(1:(suffixI - 1)), '.csv');
            if exist(csvfile, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' csvfile '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end
            delete(csvfile);

            % Write the CSV file.
            try
                Output.ImageAnalysis.saveID2CSV(csvfile, app.image_prefs, ...
                    app.image_data, app.image_data_zscored, app.image_neurons, app.image_um_scale);
            catch ME
                % Error.
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to write neuron IDs to "' csvfile '"!'], ['Error:' msg]}, ...
                    'Neuron ID File Failure', 'Icon', 'error');
            end

            % Done.
            uiconfirm(app.CELL_ID, ...
                'Saving neuron IDs completed successfully.', ...
                'Save Neurons IDs', ...
                'Options', {'OK'}, 'DefaultOption', 1, 'Icon', 'success');
        end

        % Value changed function: ColorAtlasCheckBox
        function AtlasCheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Value changed function: BodyDropDown
        function BodyDropDownValueChanged(app, event)

            % Update the neurons.
            value = app.BodyDropDown.Value;
            if isempty(app.image_neurons)
                app.image_neurons = Neurons.Image([], value, 'scale', app.image_um_scale');
                %else DEPRECATED
                %    app.image_neurons.bodypart = value;
            end

            % Save the changes.
            app.worm.body = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
            app.SaveIDToFile();
        end

        % Value changed function: AgeDropDown
        function AgeDropDownValueChanged(app, event)

            % Update the worm's age.
            app.worm.age = app.AgeDropDown.Value;
            %UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: SexDropDown
        function SexDropDownValueChanged(app, event)

            % Update the worm's sex.
            app.worm.sex = app.SexDropDown.Value;
            UpdateSexNeuronLists(app);

            % Save the changes.
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: StrainEditField
        function StrainEditFieldValueChanged(app, event)
            value = app.StrainEditField.Value;
            app.worm.strain = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Value changed function: NotesEditField
        function NotesEditFieldValueChanged(app, event)
            value = app.NotesEditField.Value;
            app.worm.notes = value;
            worm = app.worm;
            save(app.image_file, 'worm', '-append');
        end

        % Menu selected function: CheckforUpdatesMenu
        function CheckforUpdatesMenuSelected(app, event)
            % Get the latest version info.
            prog_ver = Program.ProgramInfo.version;
            try
                latest_ver = Program.ProgramInfo.checkUpdates();
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot check online version!', '', 'Error:', msg}, ...
                    'Software Update Check', 'Icon', 'error');
                return;
            end

            % The software is out of date.
            if latest_ver > prog_ver
                answer = uiconfirm(app.CELL_ID, ...
                    {'A newer version of NeuroPAL ID is available!', 'Please download it.'}, ...
                    'Software Update Check', 'Options', {'Download', 'OK'}, ...
                    'DefaultOption', 1, 'CancelOption', 2, 'Icon', 'info');

                % Go to the website.
                if strcmp(answer, 'Download')
                    web(Program.ProgramInfo.website_URL, '-browser');
                end

                % The software is up-to-date.
            else
                uialert(app.CELL_ID, ...
                    'You have the latest version of NeuroPAL ID.', ...
                    'Software Update Check', 'Icon', 'success');
            end
        end

        % Menu selected function: SaveIDImageMenu
        function SaveIDImageMenuSelected(app, event)
            % Is there an image loaded?
            data = app.image_view;
            if isempty(data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Image', 'Icon', 'error');
                return;
            end

            % Initialize the neurons & scale.
            neurons = app.image_neurons.user_id_neurons();
            um_scale = app.image_um_scale;

            % Determine the filename.
            image_filename = app.image_file;
            file = strrep(image_filename, '.mat', '');

            % Is there already a file with this name & does the user want to overwrite it?
            % *** if the file exists & they don't want to overwrite, cancel the operation.
            pdf_file = [file '_1.pdf'];
            if exist(pdf_file, 'file')
                answer = uiconfirm(app.CELL_ID, ...
                    {['"' pdf_file '" already exists!'], ' ', 'Do you want to overwrite it?'}, ...
                    'Overwrite ID Image File', 'Options', {'Yes', 'Cancel'}, ...
                    'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'warning');

                % Keep the existing file.
                if strcmpi(answer, 'cancel')
                    return;
                end
            end

            % Print the neuron ID image(s).
            is_error = false;
            try
                Output.Illustration.saveIDImage(file, data, ...
                    neurons, um_scale, app.image_prefs.is_Z_flip);
            catch ME
                % Error.
                is_error = true;
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {['Failed to save ID images to "' file '"!'], ['Error:' msg]}, ...
                    'Save ID Image', 'Icon', 'error');
            end

            % Done.
            if ~is_error
                uiconfirm(app.CELL_ID, ...
                    'Neuron ID images saved.', ...
                    'Save Neuron ID Images', ...
                    'Options', {'OK'}, 'DefaultOption', 1, ...
                    'Icon', 'success');
            end
        end

        % Callback function
        function SaveIDCartoonMenuSelected(app, event)
            % Is there an image loaded?
            if isempty(app.image_data)
                uialert(app.CELL_ID, 'No image!', 'Save ID Cartoon', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            CELL_IDCloseRequest(app, event);
        end

        % Menu selected function: KeyboardShortcutsMenu
        function KeyboardShortcutsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Keyboard.png', ...
                        'Keyboard Shortcuts');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Keyboard.png', ...
                        'Keyboard Shortcuts');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('About.png', ...
                        'About NeuroPAL ID');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/About.png', ...
                        'About NeuroPAL ID');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            try
                if isdeployed
                    DataHandling.PNGViewer.show('Instructions.png', ...
                        'Software Instructions');
                else
                    DataHandling.PNGViewer.show('./Data/Documents/Instructions.png', ...
                        'Software Instructions');
                end
            catch ME
                msg = getReport(ME, 'extended', 'hyperlinks', 'off');
                uialert(app.CELL_ID, ...
                    {'Cannot open instructions!', '', 'Error:', msg}, ...
                    'Display Instructions', 'Icon', 'error');
                return;
            end
        end

        % Menu selected function: GFPColorMenu
        function GFPColorMenuSelected(app, event)
            color = Program.GUIPreferences.inputGFPColor();
            if ~isempty(color)

                % Save the preferences.
                Program.GUIPreferences.save();

                % Redraw the image.
                if app.GFPCheckBox.Value
                    app.DrawImageData();
                end
            end
        end

        % Menu selected function: NeuronDotsMenu
        function NeuronDotsMenuSelected(app, event)
            dot = Program.GUIPreferences.inputNeuronDot();
            if ~isempty(dot)
                Program.GUIPreferences.save();

                % Redraw the Z-slice.
                DrawZSlice(app, app.ZSlider, app.image_view, app.XY);
            end
        end

        % Menu selected function: ReportaBugMenu
        function ReportBug(app, event)
            answer = uiconfirm(app.CELL_ID, ...
                {'We welcome bug reports to improve the software.', ...
                ' ', ...
                'You MUST follow these instructions for bug reports:', ...
                '1. Ensure you have the latest software.', ...
                '   Your bug may have already been fixed.', ...
                '2. Review the software instructions.', ...
                '   Your issue may not be a bug.', ...
                '3. Ensure the bug has NOT already been reported.', ...
                '4. Report EXACTLY what happened on the bug website:', ...
                '  A. Your OS and the NeuroPAL ID software version.', ...
                '  B. What were you doing when the bug occurred?', ...
                '  C. What was the software supposed to do?', ...
                '  D. What did the software actually do?', ...
                '  E. Provide both the "*.mat" and "*_ID.mat"', ...
                '     files associated with the bug.', ...
                '  F. If you received an error message, provide it.'}, ...
                'Report a Bug', 'Options', {'Report', 'Cancel'}, ...
                'DefaultOption', 2, 'CancelOption', 2, 'Icon', 'info');

            % Go to the website.
            if strcmp(answer, 'Report')
                web(Program.ProgramInfo.bug_URL, '-browser');
            end
        end

        % Menu selected function: NeuroPALWebsiteMenu
        function NeuroPALWebsiteMenuSelected(app, event)
            web(Program.ProgramInfo.website_URL, '-browser');
        end

        % Menu selected function: AllChannels
        function AllChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'Gamma adjustment:'};
            dlgtitle = 'Adjust Gamma';
            dims = [1,20];
            definput = {num2str(mean(app.image_gamma))};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma(1:end) = gamma;
                app.image_prefs.gamma(1:end) = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: IndividualChannels
        function IndividualChannelsMenuSelected(app, event)
            % Ask the user for the gamma adjustment.
            prompt = {'R:', 'G:', 'B:', 'W:', 'DIC:', 'GFP:'};
            dlgtitle = 'Adjust Gamma by Channel';
            dims = [1,20];
            gamma = app.image_gamma;
            prompt = prompt(1:length(gamma));
            definput = arrayfun(@(x) num2str(x), gamma, 'UniformOutput', false);
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            % Adjust the gamma.
            if ~isempty(answer)

                % Sanitize the input.
                gamma = str2double(answer);
                if size(gamma,1) < size(gamma,2)
                    gamma = gamma';
                end
                gamma = max(gamma,0);
                gamma = min(gamma,1);

                % Save the new gamma.
                app.image_gamma = gamma;
                app.image_prefs.gamma = gamma;
                prefs = app.image_prefs;
                save(app.image_file, 'prefs', '-append');

                % Update the views.
                app.DrawImageData();
            end
        end

        % Menu selected function: ToggleBirthTimesMenu
        function ToggleNeuronBirthTimes(app, event)
            % Toggle showing or hiding neuron birth times.
            GUI_prefs = Program.GUIPreferences.instance();
            if contains(lower(app.ToggleBirthTimesMenu.Text), 'show')
                GUI_prefs.is_show_birth_times = true;
                app.ToggleBirthTimesMenu.Text = 'Hide Birth Times';
            else
                GUI_prefs.is_show_birth_times = false;
                app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            end

            % Save the preferences.
            GUI_prefs.save();

            % Update the display.
            UpdateNeuronLists(app)
        end

        % Callback function
        function ImportfromcsvMenuSelected(app, event)
            
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');
            uiwait(msgbox('Please note that depending on the number of neurons being imported, this may take a while.','NeuroPAL'));

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end

            msgbox("Finished importing neurons.","Success");

            SaveIDToFile(app);

        end

        % Menu selected function: AdjustHistogramMenu
        function AdjustHistogramMenuSelected(app, event)
            histogram_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, Program.GUIPreferences.instance(), app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value);
        end

        % Menu selected function: AutoSegmentationMenu
        function AutoSegmentationMenuSelected(app, event)
            autoseg_window(app, app.image_data, app.image_neurons, app.image_view, app.image_gamma, app.image_um_scale, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value)
        end

        % Menu selected function: ColorRegisterMenu
        function ColorRegisterMenuSelected(app, event)
            channel_selector(app);
        end

        % Menu selected function: FlipLRNeuronsMenu
        function FlipLRNeuronsMenuSelected(app, event)
            if contains(lower(app.worm.sex), 'xo') % Check if male or hermaphrodite
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Male.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            else
                for n=1:size(app.image_neurons.neurons,2) % Go through all neurons
                    app.image_neurons.neurons(n).annotation = Neurons.Hermaphrodite.flipLR(app.image_neurons.neurons(n).annotation); % Run every neuron through L/R flip appropriate for gender.
                end
            end            
        end

        % Menu selected function: TraceActivityMenu_2
        function TraceActivityMenu_2Selected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            fullpath = join([GUI_prefs.image_dir,app.image_name]);
            trace_window(app,app.image_data,app.image_view,app.image_neurons, fullpath, GUI_prefs.image_dir, app.RDropDown.Value, app.GDropDown.Value, app.BDropDown.Value, app.WDropDown.Value, app.DICDropDown.Value, app.GFPDropDown.Value, app.image_gamma);
        end

        % Callback function
        function TL_ButtonPushed(app, event)
            app.DispNeuron(event)
        end

        % Callback function
        function ButtonPushed(app, event)
            app.DispNeuron(event)
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.DispNeuron(event)
        end

        % Menu selected function: DisplayNeuronActivityMenu
        function DisplayNeuronActivityMenuSelected(app, event)
            if app.DisplayNeuronActivityMenu.Checked == 1
                set(app.ActivityBrowser,'Visible','Off')
                set(app.MaxProjection,'Visible','On')
                set(app.DisplayNeuronActivityMenu,'Checked','off')
            else
                d = uiprogressdlg(app.CELL_ID,'Title','Loading activities...',...
        'Indeterminate','on');
                app.init_browser()
                set(app.MaxProjection,'Visible','Off')
                set(app.ActivityBrowser,'Visible','On')
                app.ActivityBrowser.Position = app.MaxProjection.Position;
                set(app.DisplayNeuronActivityMenu,'Checked','on')
                close(d)
            end
        end

        % Menu selected function: ActivityBrowserMenu
        function ActivityBrowserMenuSelected(app, event)
            activity_browser(app, app.GanglionTree, app.neuron_activity_by_name, app.neuron_info);
        end

        % Menu selected function: CSVfileMenu
        function CSVfileMenuSelected(app, event)
                        
            % Display expected formatting.
            DataHandling.PNGViewer.show('./Data/Documents/csv-format.png', 'Expected CSV formatting');

            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');

            imported_neurons = readtable(join([path,name]),'NumHeaderLines',1);

            d = uiprogressdlg(app.CELL_ID,'Title','Loading neurons...','Indeterminate','on');
            for r = 1:size(imported_neurons.Var4)
                adjusted_coords = flip([imported_neurons.Var5(r) imported_neurons.Var4(r)]) + app.mouse_offset;
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords imported_neurons.Var3(r)], app.mp_params.hnsz, app.image_um_scale');
            end
            
            SaveIDToFile(app);
            app.DrawImageData()
            close(d)

        end

        % Menu selected function: NWBfileMenu
        function NWBfileMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.nwb'];
            [name, path, ~] = uigetfile(file_info, 'Select NWB file.');
            nwb = nwbRead(join([path,name]));

            neuroPAL_module = nwb.processing.get('NeuroPAL')
            %{
            all_neurons = neuroPAL_module.dynamictable.get('VolumeSegmentation').voxel_mask.data.load();

            d = uiprogressdlg(app.CELL_ID,'Title','Loading neurons...','Indeterminate','on');
            for r = 1:size(all_neurons, 1)
                adjusted_coords = flip(double([all_neurons{r,1}, all_neurons{r,2}])) + double(app.mouse_offset);
                app.image_neurons.add_neuron(app.image_data_zscored(:,:,:,app.image_prefs.RGBW(~isnan(app.image_prefs.RGBW))), ...
                    [adjusted_coords, double(all_neurons{r,3})], app.mp_params.hnsz, app.image_um_scale');
            end
            %}

            'nwb'
            disp(nwb)
            'processing'
            disp(nwb.processing)
            'gcamp_data'
            gc_data = nwb.acquisition.get('GCaMP_series');

            %SaveIDToFile(app);
            %app.DrawImageData()
            %close(d)

        end

        % Menu selected function: SaveNWBMenu
        function SaveNWBMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();      
            gcamp = h5read([GUI_prefs.image_dir,'data.h5'], '/data');
            save([GUI_prefs.image_dir,'gcamp.mat'], 'gcamp');
            
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;
            cmd = [pwd,sprintf('/External_Dependencies/matnwb/pynwb/conv2nwb.py %s', path)];
            pyrunfile(cmd)
        end

        % Menu selected function: h5fileMenu
        function h5fileMenuSelected(app, event)
            % Prompt the user to select the directory containing the data and annotations files.
            directory = uigetdir('.', 'Select the directory');
            
            % Define file paths
            annotations_file = fullfile(directory, 'annotations.h5');
            data_file = fullfile(directory, 'data.h5');
            
            % Load files
            anno_x = h5read(annotations_file, '/x');
            anno_y = h5read(annotations_file, '/y');
            anno_z = h5read(annotations_file, '/z');
            anno_id = h5read(annotations_file, '/id');
            anno_tidx = h5read(annotations_file, '/t_idx');
            anno_provenance = h5read(annotations_file, '/provenance');
            anno_worldline = h5read(annotations_file, '/worldline_id');
            times = h5read(data_file, '/times');
            
            % Initialize neuron activity array
            app.neuron_activity = zeros(size(anno_id, 1), 2);
            
            % Get data dimensions
            data_info = h5info(data_file, '/data');
            data_size = data_info.Dataspace.Size;
            
            % Get the scale based on the size of the data
            scale = data_size(1:3);
            
            last_t = -1;
            start_time = times(1);
            
            for i = 1:size(anno_id, 1)
                if strcmp(anno_provenance(i), 'ZEIR')
                    x = round(anno_x(i) * scale(1)) + 1;
                    y = round(anno_y(i) * scale(2)) + 1;
                    z = round(anno_z(i) * scale(3)) + 1;
                    t = find(times >= anno_tidx(i), 1);
            
                    % Validate indices
                    if x > 0 && y > 0 && z > 0 && t > 0 && ...
                       x <= data_size(1) && y <= data_size(2) && ...
                       z <= data_size(3) && t <= data_size(5)
                        try
                            % Debug prints
                            fprintf('Processing index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                            
                            % Only load new time-slice if necessary
                            if t ~= last_t
                                current_time_slice = h5read(data_file, '/data', [1, 1, 1, 1, t], [512, 400, 23, 3, 1]);
                                last_t = t;
                            end
                            
                            % Store intensity and time in neuron_activity
                            app.neuron_activity(i, 1) = current_time_slice(x, y, z, 1);
                            app.neuron_activity(i, 2) = (times(t) - start_time) / 1000;  % convert to seconds
            
                            % Debug print for activity data and time
                            fprintf('Activity data: %f, Time: %f\n', app.neuron_activity(i, 1), app.neuron_activity(i, 2));
            
                        catch ME
                            fprintf('Error while reading file: %s\n', ME.message);
                            fprintf('Invalid index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                        end
                    else
                        fprintf('Invalid index: x=%d, y=%d, z=%d, t=%d\n', x, y, z, t);
                    end
                end
            end

            % Extract activity of the 5th neuron
            neuron_activity = app.neuron_activity(5, :);
            
            % Plot neuron activity over time
            figure; % Create a new figure
            plot(neuron_activity(:, 2), neuron_activity(:, 1));
            xlabel('Time');
            ylabel('Activity');
            title('Neuron 5 Activity Over Time');

            d = uiprogressdlg(app.CELL_ID,'Title','Pulling worldline...','Indeterminate','on');

            % Load additional worldline data file
            worldline_file = fullfile(directory, 'worldlines.h5');
            worldline_id = h5read(worldline_file, '/id');
            worldline_name = strtrim(h5read(worldline_file, '/name'));

            % Assuming worldline_name is your 1x165 cell array of names
            for i = 1:length(worldline_name)
                worldline_name{i} = regexprep(worldline_name{i}, '[^a-zA-Z0-9]', '');  % Keep only alphanumeric characters
            end

            % Initialize the neuron_activity_by_name as a Map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
            
            % Build the neuron_activity_by_name map
            for i = 1:numel(worldline_id)
                % Find matching worldline ids in anno_worldline
                idx = anno_worldline == worldline_id(i);
                
                % Use logical indexing to pull corresponding activity and time data
                % for each matching neuron
                neuron_activity = app.neuron_activity(idx, :);
                
                % Use the worldline_name as a key in the map.
                app.neuron_activity_by_name(worldline_name{i}) = neuron_activity;
            end
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View in main app', 'View in activity browser', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View in main app'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'View in activity browser'
                        activity_browser(app, app.GanglionTree, app.neuron_activity_by_name, app.neuron_info);
                    case 'Skip view'
                        return
                end
            end
        end

        % Menu selected function: ZephiroutputMenu
        function ZephiroutputMenuSelected(app, event)
            GUI_prefs = Program.GUIPreferences.instance();
            path = GUI_prefs.image_dir;

            % Ask the user which file they want.
            file_info = [path ';*.csv'];
            [name, path, ~] = uigetfile(file_info, 'Select CSV file.');
            fileName = [path,name];

            % Read the CSV file
            dataTable = readtable(fileName);
        
            % Initialize the map
            app.neuron_activity_by_name = containers.Map('KeyType', 'char', 'ValueType', 'any');
        
            d = uiprogressdlg(app.CELL_ID,'Title','Loading activity...', 'Indeterminate','on');
            % Iterate through the rows
            for i = 1:size(dataTable, 1)
                % Get the neuron name
                try
                    neuronName = dataTable.Var2{i};
                catch
                    %fprintf('Error accessing Var2 at row %d\n', i);
                    continue
                end
        
                % Debugging print statement
                %fprintf('Processing neuron: %s\n', neuronName);
        
                % Initialize an array to store the neuron activity
                neuronActivity = [];
        
                % Iterate through the columns starting from column 6
                for j = 6:size(dataTable, 2)
                    % Append the activity to the array if it's not a NaN
                    if ~isnan(dataTable{i,j})
                        neuronActivity = [neuronActivity, dataTable{i, j}];
                    end
                end
        
                % If the neuron has activity, save it to the map
                if ~isempty(neuronActivity)
                    app.neuron_activity_by_name(neuronName) = neuronActivity;
                    %fprintf('Saved activity for neuron: %s\n', neuronName);
                end
            end
            close(d)

            if app.DisplayNeuronActivityMenu.Checked == 0
                open_view = uiconfirm(app.CELL_ID,'Activity loaded!','Success!','Options',{'View in main app', 'View in activity browser', 'Skip view'},'DefaultOption','Skip view');
                switch open_view
                    case 'View in main app'
                        app.DisplayNeuronActivityMenuSelected();
                    case 'View in activity browser'
                        activity_browser(app, app.GanglionTree, app.neuron_activity_by_name, app.neuron_info);
                    case 'Skip view'
                        return
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CELL_ID = uifigure('Visible', 'off');
            app.CELL_ID.Name = 'NeuroPAL ID';
            app.CELL_ID.CloseRequestFcn = createCallbackFcn(app, @CELL_IDCloseRequest, true);
            app.CELL_ID.KeyPressFcn = createCallbackFcn(app, @CELL_IDKeyPress, true);
            app.FileMenu = uimenu(app.CELL_ID);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.PreprocessingMenu = uimenu(app.CELL_ID);
            app.PreprocessingMenu.Text = 'Preprocessing';
            app.ArtifactRemovalMenu = uimenu(app.PreprocessingMenu);
            app.ArtifactRemovalMenu.Text = 'Artifact Removal';
            app.ManualMenu = uimenu(app.ArtifactRemovalMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualArtifactRemoval, true);
            app.ManualMenu.Text = 'Manual';
            app.AutomaticMenu = uimenu(app.ArtifactRemovalMenu);
            app.AutomaticMenu.MenuSelectedFcn = createCallbackFcn(app, @AutomaticArtifactRemoval, true);
            app.AutomaticMenu.Text = 'Automatic';
            app.DownsampleImageMenu = uimenu(app.PreprocessingMenu);
            app.DownsampleImageMenu.MenuSelectedFcn = createCallbackFcn(app, @DecimateButtonPushed, true);
            app.DownsampleImageMenu.Text = 'Downsample Image';
            app.ThresholdImageMenu = uimenu(app.PreprocessingMenu);
            app.ThresholdImageMenu.MenuSelectedFcn = createCallbackFcn(app, @AdaptThreshButtonPushed, true);
            app.ThresholdImageMenu.Text = 'Threshold Image';
            app.HistogramMatchingMenu = uimenu(app.PreprocessingMenu);
            app.HistogramMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingMenu.Text = 'Histogram Matching';
            app.AutoHistogramMenu = uimenu(app.PreprocessingMenu);
            app.AutoHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoHistogram, true);
            app.AutoHistogramMenu.Text = 'Auto Histogram';
            app.ImageMenu = uimenu(app.CELL_ID);
            app.ImageMenu.Text = 'Image';
            app.NormalizeColorsMenu = uimenu(app.ImageMenu);
            app.NormalizeColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.NormalizeColorsMenu.Text = 'Normalize Colors';
            app.ColorRegisterMenu = uimenu(app.ImageMenu);
            app.ColorRegisterMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorRegisterMenuSelected, true);
            app.ColorRegisterMenu.Text = 'Color Register';
            app.AdjustGammaMenu = uimenu(app.ImageMenu);
            app.AdjustGammaMenu.Text = 'Adjust Gamma';
            app.AllChannels = uimenu(app.AdjustGammaMenu);
            app.AllChannels.MenuSelectedFcn = createCallbackFcn(app, @AllChannelsMenuSelected, true);
            app.AllChannels.Text = 'All Channels';
            app.IndividualChannels = uimenu(app.AdjustGammaMenu);
            app.IndividualChannels.MenuSelectedFcn = createCallbackFcn(app, @IndividualChannelsMenuSelected, true);
            app.IndividualChannels.Text = 'Individual Channels';
            app.AdjustBrightnessMenu = uimenu(app.ImageMenu);
            app.AdjustBrightnessMenu.Enable = 'off';
            app.AdjustBrightnessMenu.Text = 'Adjust Brightness';
            app.AdjustHistogramMenu = uimenu(app.ImageMenu);
            app.AdjustHistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @AdjustHistogramMenuSelected, true);
            app.AdjustHistogramMenu.Text = 'Adjust Histogram';
            app.RotateAllMenu = uimenu(app.ImageMenu);
            app.RotateAllMenu.Text = 'Rotate All';
            app.RotateAllHorizontalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllHorizontal, true);
            app.RotateAllHorizontalMenu.Text = 'Horizontal';
            app.RotateAllVerticalMenu = uimenu(app.RotateAllMenu);
            app.RotateAllVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllVertical, true);
            app.RotateAllVerticalMenu.Text = 'Vertical';
            app.RotateAllClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllClockwise, true);
            app.RotateAllClockwiseMenu.Text = 'Clockwise';
            app.RotateAllAntiClockwiseMenu = uimenu(app.RotateAllMenu);
            app.RotateAllAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateAllAntiClockwise, true);
            app.RotateAllAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateImageMenu = uimenu(app.ImageMenu);
            app.RotateImageMenu.Text = 'Rotate Image';
            app.RotateImageHorizontalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageHorizontal, true);
            app.RotateImageHorizontalMenu.Text = 'Horizontal';
            app.RotateImageVerticalMenu = uimenu(app.RotateImageMenu);
            app.RotateImageVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageVertical, true);
            app.RotateImageVerticalMenu.Text = 'Vertical';
            app.RotateImageClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageClockwise, true);
            app.RotateImageClockwiseMenu.Text = 'Clockwise';
            app.RotateImageAntiClockwiseMenu = uimenu(app.RotateImageMenu);
            app.RotateImageAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateImageAntiClockwise, true);
            app.RotateImageAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.RotateNeuronsMenu = uimenu(app.ImageMenu);
            app.RotateNeuronsMenu.Text = 'Rotate Neurons';
            app.RotateNeuronsHorizontalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsHorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsHorizontal, true);
            app.RotateNeuronsHorizontalMenu.Text = 'Horizontal';
            app.RotateNeuronsVerticalMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsVertical, true);
            app.RotateNeuronsVerticalMenu.Text = 'Vertical';
            app.RotateNeuronsClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsClockwise, true);
            app.RotateNeuronsClockwiseMenu.Text = 'Clockwise';
            app.RotateNeuronsAntiClockwiseMenu = uimenu(app.RotateNeuronsMenu);
            app.RotateNeuronsAntiClockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateNeuronsAntiClockwise, true);
            app.RotateNeuronsAntiClockwiseMenu.Text = 'Anti-Clockwise';
            app.MirrorImageMenu = uimenu(app.ImageMenu);
            app.MirrorImageMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorImage, true);
            app.MirrorImageMenu.Text = 'Mirror Image';
            app.MirrorNeuronsMenu = uimenu(app.ImageMenu);
            app.MirrorNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @MirrorNeurons, true);
            app.MirrorNeuronsMenu.Text = 'Mirror Neurons';
            app.NeuronsMenu = uimenu(app.CELL_ID);
            app.NeuronsMenu.Text = 'Neurons';
            app.ImportneuronsfromMenu = uimenu(app.NeuronsMenu);
            app.ImportneuronsfromMenu.Text = 'Import neurons from...';
            app.CSVfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.CSVfileMenu.MenuSelectedFcn = createCallbackFcn(app, @CSVfileMenuSelected, true);
            app.CSVfileMenu.Text = '... CSV file.';
            app.NWBfileMenu = uimenu(app.ImportneuronsfromMenu);
            app.NWBfileMenu.MenuSelectedFcn = createCallbackFcn(app, @NWBfileMenuSelected, true);
            app.NWBfileMenu.Text = '... NWB file.';
            app.ImportactivityfromMenu = uimenu(app.NeuronsMenu);
            app.ImportactivityfromMenu.Text = 'Import activity from...';
            app.h5fileMenu = uimenu(app.ImportactivityfromMenu);
            app.h5fileMenu.MenuSelectedFcn = createCallbackFcn(app, @h5fileMenuSelected, true);
            app.h5fileMenu.Text = '... h5 file.';
            app.NWBfileMenu_2 = uimenu(app.ImportactivityfromMenu);
            app.NWBfileMenu_2.Text = '... NWB file.';
            app.ZephiroutputMenu = uimenu(app.ImportactivityfromMenu);
            app.ZephiroutputMenu.MenuSelectedFcn = createCallbackFcn(app, @ZephiroutputMenuSelected, true);
            app.ZephiroutputMenu.Text = '... Zephir output.';
            app.FlipLRNeuronsMenu = uimenu(app.NeuronsMenu);
            app.FlipLRNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipLRNeuronsMenuSelected, true);
            app.FlipLRNeuronsMenu.Text = 'Flip L/R Neurons';
            app.AutoSegmentationMenu = uimenu(app.NeuronsMenu);
            app.AutoSegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoSegmentationMenuSelected, true);
            app.AutoSegmentationMenu.Text = 'Auto-Segmentation';
            app.NeuronActivityMenu = uimenu(app.NeuronsMenu);
            app.NeuronActivityMenu.Text = 'Neuron Activity';
            app.TraceActivityMenu_2 = uimenu(app.NeuronActivityMenu);
            app.TraceActivityMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TraceActivityMenu_2Selected, true);
            app.TraceActivityMenu_2.Text = 'Trace Activity';
            app.ActivityBrowserMenu = uimenu(app.NeuronActivityMenu);
            app.ActivityBrowserMenu.MenuSelectedFcn = createCallbackFcn(app, @ActivityBrowserMenuSelected, true);
            app.ActivityBrowserMenu.Text = 'Activity Browser';
            app.DisplayNeuronActivityMenu = uimenu(app.NeuronActivityMenu);
            app.DisplayNeuronActivityMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayNeuronActivityMenuSelected, true);
            app.DisplayNeuronActivityMenu.Text = 'Display Neuron Activity';
            app.BatchDetectIDNeuronsMenu = uimenu(app.NeuronsMenu);
            app.BatchDetectIDNeuronsMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchIDNeurons, true);
            app.BatchDetectIDNeuronsMenu.Text = 'Batch Detect + ID Neurons';
            app.DeleteUserIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteUserIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteUserIDs, true);
            app.DeleteUserIDsMenu.Text = 'Delete User IDs';
            app.DeleteModelIDsMenu = uimenu(app.NeuronsMenu);
            app.DeleteModelIDsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteModelIDs, true);
            app.DeleteModelIDsMenu.Text = 'Delete Model IDs';
            app.ToggleAutoIDUpdatesMenu = uimenu(app.NeuronsMenu);
            app.ToggleAutoIDUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleAutoIDUpdates, true);
            app.ToggleAutoIDUpdatesMenu.Text = 'Disable Auto-ID Updates';
            app.ToggleNeuronDetectionMenu = uimenu(app.NeuronsMenu);
            app.ToggleNeuronDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronDetection, true);
            app.ToggleNeuronDetectionMenu.Text = 'Use NN-Detect Neurons';
            app.DisplayMenu = uimenu(app.CELL_ID);
            app.DisplayMenu.Text = 'Display';
            app.ToggleBirthTimesMenu = uimenu(app.DisplayMenu);
            app.ToggleBirthTimesMenu.MenuSelectedFcn = createCallbackFcn(app, @ToggleNeuronBirthTimes, true);
            app.ToggleBirthTimesMenu.Text = 'Show Birth Times';
            app.NeuronDotsMenu = uimenu(app.DisplayMenu);
            app.NeuronDotsMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuronDotsMenuSelected, true);
            app.NeuronDotsMenu.Text = 'Neuron Dots';
            app.GFPColorMenu = uimenu(app.DisplayMenu);
            app.GFPColorMenu.MenuSelectedFcn = createCallbackFcn(app, @GFPColorMenuSelected, true);
            app.GFPColorMenu.Text = 'GFP Color';
            app.AnalysisMenu = uimenu(app.CELL_ID);
            app.AnalysisMenu.Text = 'Analysis';
            app.SaveIDInfoMenu = uimenu(app.AnalysisMenu);
            app.SaveIDInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDInfoMenu.Text = 'Save ID Info';
            app.SaveIDImageMenu = uimenu(app.AnalysisMenu);
            app.SaveIDImageMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveIDImageMenuSelected, true);
            app.SaveIDImageMenu.Text = 'Save ID Image';
            app.SaveNWBMenu = uimenu(app.AnalysisMenu);
            app.SaveNWBMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNWBMenuSelected, true);
            app.SaveNWBMenu.Text = 'Save .NWB';
            app.HelpMenu = uimenu(app.CELL_ID);
            app.HelpMenu.Text = 'Help';
            app.CheckforUpdatesMenu = uimenu(app.HelpMenu);
            app.CheckforUpdatesMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckforUpdatesMenuSelected, true);
            app.CheckforUpdatesMenu.Text = 'Check for Updates';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.KeyboardShortcutsMenu = uimenu(app.HelpMenu);
            app.KeyboardShortcutsMenu.MenuSelectedFcn = createCallbackFcn(app, @KeyboardShortcutsMenuSelected, true);
            app.KeyboardShortcutsMenu.Text = 'Keyboard Shortcuts';
            app.NeuroPALWebsiteMenu = uimenu(app.HelpMenu);
            app.NeuroPALWebsiteMenu.MenuSelectedFcn = createCallbackFcn(app, @NeuroPALWebsiteMenuSelected, true);
            app.NeuroPALWebsiteMenu.Text = 'NeuroPAL Website';
            app.ReportaBugMenu = uimenu(app.HelpMenu);
            app.ReportaBugMenu.MenuSelectedFcn = createCallbackFcn(app, @ReportBug, true);
            app.ReportaBugMenu.Text = 'Report a Bug';
            app.TabGroup = uitabgroup(app.CELL_ID);
            app.ImageTab = uitab(app.TabGroup);
            app.ImageTab.Title = 'Image';
            app.MaxProjection = uiaxes(app.ImageTab);
            title(app.MaxProjection, 'Maximum Intensity Projection')
            app.OpenButton = uibutton(app.ImageTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenButton.Text = 'Open';
            app.ZSliderLabel = uilabel(app.ImageTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.ImageTab);
            app.ZSlider.Limits = [1 20];
            app.ZSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Value = 1;
            app.AutoIDDropDown = uidropdown(app.ImageTab);
            app.AutoIDDropDown.Items = {};
            app.AutoIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoIDDropDownValueChanged, true);
            app.AutoIDDropDown.Enable = 'off';
            app.AutoIDDropDown.Value = {};
            app.UserIDButton = uibutton(app.ImageTab, 'push');
            app.UserIDButton.ButtonPushedFcn = createCallbackFcn(app, @UserIDButtonPushed, true);
            app.UserIDButton.Text = 'User ID';
            app.UserNeuronIDsListBoxLabel = uilabel(app.ImageTab);
            app.UserNeuronIDsListBoxLabel.Text = 'User Neuron IDs';
            app.UserNeuronIDsListBox = uilistbox(app.ImageTab);
            app.UserNeuronIDsListBox.Items = {};
            app.UserNeuronIDsListBox.ValueChangedFcn = createCallbackFcn(app, @AutoNeuronIDListBoxValueChanged, true);
            app.UserNeuronIDsListBox.Value = {};
            app.AutoDetectButton = uibutton(app.ImageTab, 'push');
            app.AutoDetectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoDetectButtonPushed, true);
            app.AutoDetectButton.Text = 'Auto-Detect';
            app.AutoIDAllButton = uibutton(app.ImageTab, 'push');
            app.AutoIDAllButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDAllButtonPushed, true);
            app.AutoIDAllButton.Text = 'Auto-ID All';
            app.FileLabel = uilabel(app.ImageTab);
            app.FileLabel.Text = 'File:';
            app.NeuronsLabel = uilabel(app.ImageTab);
            app.NeuronsLabel.Text = 'Neurons:';
            app.MouseClickDropDown = uidropdown(app.ImageTab);
            app.MouseClickDropDown.Items = {'Add Neuron', 'Detect Neuron'};
            app.MouseClickDropDown.Value = 'Add Neuron';
            app.AutoNameLabel = uilabel(app.ImageTab);
            app.AutoNameLabel.Text = 'Auto Name:';
            app.IDdLabel = uilabel(app.ImageTab);
            app.IDdLabel.Text = '     ID''d     ';
            app.IDdNeuronsListBox = uilistbox(app.ImageTab);
            app.IDdNeuronsListBox.Items = {};
            app.IDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @IDdNeuronsListChanged, true);
            app.IDdNeuronsListBox.DoubleClickedFcn = createCallbackFcn(app, @IDdNeuronsListBoxDoubleClicked, true);
            app.IDdNeuronsListBox.Value = {};
            app.UnIDdLabel = uilabel(app.ImageTab);
            app.UnIDdLabel.Text = '     Un-ID''d     ';
            app.UnIDdNeuronsListBox = uilistbox(app.ImageTab);
            app.UnIDdNeuronsListBox.Items = {};
            app.UnIDdNeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @UnIDdNeuronsListChanged, true);
            app.UnIDdNeuronsListBox.Value = {};
            app.GanglionTree = uitree(app.ImageTab);
            app.GanglionTree.Multiselect = 'on';
            app.GanglionTree.SelectionChangedFcn = createCallbackFcn(app, @GanglionTreeSelectionChanged, true);
            app.PharynxNode = uitreenode(app.GanglionTree);
            app.PharynxNode.Text = 'Pharynx';
            app.AnteriorNode = uitreenode(app.PharynxNode);
            app.AnteriorNode.Text = 'Anterior';
            app.PosteriorNode = uitreenode(app.PharynxNode);
            app.PosteriorNode.Text = 'Posterior';
            app.HeadNode = uitreenode(app.GanglionTree);
            app.HeadNode.Text = 'Head';
            app.AnteriorLNode = uitreenode(app.HeadNode);
            app.AnteriorLNode.Text = 'Anterior (L)';
            app.AnteriorRNode = uitreenode(app.HeadNode);
            app.AnteriorRNode.Text = 'Anterior (R)';
            app.DorsalNode = uitreenode(app.HeadNode);
            app.DorsalNode.Text = 'Dorsal';
            app.LateralLNode = uitreenode(app.HeadNode);
            app.LateralLNode.Text = 'Lateral (L)';
            app.LateralRNode = uitreenode(app.HeadNode);
            app.LateralRNode.Text = 'Lateral (R)';
            app.VentralNode = uitreenode(app.HeadNode);
            app.VentralNode.Text = 'Ventral';
            app.RetroVesicularNode = uitreenode(app.HeadNode);
            app.RetroVesicularNode.Text = 'Retro-Vesicular';
            app.MidbodyNode = uitreenode(app.GanglionTree);
            app.MidbodyNode.Text = 'Midbody';
            app.AnteriorNode_2 = uitreenode(app.MidbodyNode);
            app.AnteriorNode_2.Text = 'Anterior';
            app.CentralNode = uitreenode(app.MidbodyNode);
            app.CentralNode.Text = 'Central';
            app.PosteriorNode_2 = uitreenode(app.MidbodyNode);
            app.PosteriorNode_2.Text = 'Posterior';
            app.VentralCordNode = uitreenode(app.MidbodyNode);
            app.VentralCordNode.Text = 'Ventral Cord';
            app.TailNode = uitreenode(app.GanglionTree);
            app.TailNode.Text = 'Tail';
            app.PreAnalNode = uitreenode(app.TailNode);
            app.PreAnalNode.Text = 'Pre-Anal';
            app.DorsoRectalNode = uitreenode(app.TailNode);
            app.DorsoRectalNode.Text = 'Dorso-Rectal';
            app.LumbarLNode = uitreenode(app.TailNode);
            app.LumbarLNode.Text = 'Lumbar (L)';
            app.LumbarRNode = uitreenode(app.TailNode);
            app.LumbarRNode.Text = 'Lumbar (R)';
            app.GanglionNeuronIDLabel = uilabel(app.ImageTab);
            app.GanglionNeuronIDLabel.Text = 'Ganglion Neuron ID';
            app.RLabel = uilabel(app.ImageTab);
            app.RLabel.Text = 'R:';
            app.RCheckBox = uicheckbox(app.ImageTab);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.RCheckBox.Text = '';
            app.RCheckBox.Value = true;
            app.RDropDown = uidropdown(app.ImageTab);
            app.RDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.RDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.RDropDown.Value = '1';
            app.GLabel = uilabel(app.ImageTab);
            app.GLabel.Text = 'G:';
            app.GCheckBox = uicheckbox(app.ImageTab);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GCheckBox.Text = '';
            app.GCheckBox.Value = true;
            app.GDropDown = uidropdown(app.ImageTab);
            app.GDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.GDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GDropDown.Value = '2';
            app.BLabel = uilabel(app.ImageTab);
            app.BLabel.Text = 'B:';
            app.BCheckBox = uicheckbox(app.ImageTab);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.BCheckBox.Text = '';
            app.BCheckBox.Value = true;
            app.BDropDown = uidropdown(app.ImageTab);
            app.BDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.BDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.BDropDown.Value = '3';
            app.WLabel = uilabel(app.ImageTab);
            app.WLabel.Text = 'W:';
            app.WCheckBox = uicheckbox(app.ImageTab);
            app.WCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.WCheckBox.Text = '';
            app.WDropDown = uidropdown(app.ImageTab);
            app.WDropDown.Items = {'1', '2', '3', '4', '5', ''};
            app.WDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.WDropDown.Value = '4';
            app.AutoLabel = uilabel(app.ImageTab);
            app.AutoLabel.Text = 'Auto:';
            app.ClicksLabel = uilabel(app.ImageTab);
            app.ClicksLabel.Text = 'Clicks:';
            app.DICLabel = uilabel(app.ImageTab);
            app.DICLabel.Text = 'DIC:';
            app.DICCheckBox = uicheckbox(app.ImageTab);
            app.DICCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.DICCheckBox.Text = '';
            app.DICDropDown = uidropdown(app.ImageTab);
            app.DICDropDown.Items = {'1', '2', '3', '4', '5'};
            app.DICDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.DICDropDown.Value = '5';
            app.IDEditField = uieditfield(app.ImageTab, 'text');
            app.IDEditField.ValueChangingFcn = createCallbackFcn(app, @IDEditFieldChanging, true);
            app.IDEditField.Enable = 'off';
            app.GFPLabel = uilabel(app.ImageTab);
            app.GFPLabel.Text = 'GFP:';
            app.GFPCheckBox = uicheckbox(app.ImageTab);
            app.GFPCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GFPCheckBox.Text = '';
            app.GFPDropDown = uidropdown(app.ImageTab);
            app.GFPDropDown.Items = {'1', '2', '3', '4', '5'};
            app.GFPDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateColorChannels, true);
            app.GFPDropDown.Value = '5';
            app.SaveIDsButton = uibutton(app.ImageTab, 'push');
            app.SaveIDsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIDs2CSV, true);
            app.SaveIDsButton.Text = 'IDs';
            app.AutoIDButton = uibutton(app.ImageTab, 'push');
            app.AutoIDButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDButtonPushed, true);
            app.AutoIDButton.Text = 'Auto ID';
            app.UserLabel = uilabel(app.ImageTab);
            app.UserLabel.Text = 'User:';
            app.ZAxisDropDown = uidropdown(app.ImageTab);
            app.ZAxisDropDown.Items = {'L/R', 'D/V'};
            app.ZAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ZAxisChanged, true);
            app.ZAxisDropDown.Value = 'L/R';
            app.ZLeftLabel = uilabel(app.ImageTab);
            app.ZLeftLabel.Text = 'LEFT';
            app.ZRightLabel = uilabel(app.ImageTab);
            app.ZRightLabel.Text = 'RIGHT';
            app.FlipZButton = uibutton(app.ImageTab, 'push');
            app.FlipZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZButton.Text = 'Flip Z';
            app.CenterZLabel = uilabel(app.ImageTab);
            app.CenterZLabel.Text = 'Center Z:';
            app.ZCenterEditField = uieditfield(app.ImageTab, 'numeric');
            app.ZCenterEditField.ValueChangedFcn = createCallbackFcn(app, @ZCenterChanged, true);
            app.ZCenterEditField.Value = 10;
            app.ColorAtlasCheckBoxLabel = uilabel(app.ImageTab);
            app.ColorAtlasCheckBoxLabel.Text = 'Color Atlas:';
            app.ColorAtlasCheckBox = uicheckbox(app.ImageTab);
            app.ColorAtlasCheckBox.ValueChangedFcn = createCallbackFcn(app, @AtlasCheckBoxValueChanged, true);
            app.ColorAtlasCheckBox.Text = '';
            app.BodyLabel = uilabel(app.ImageTab);
            app.BodyLabel.Text = 'Body:';
            app.BodyDropDown = uidropdown(app.ImageTab);
            app.BodyDropDown.Items = {'Whole Worm', 'Head', 'Midbody', 'Anterior Midbody', 'Central Midbody', 'Posterior Midbody', 'Tail', ''};
            app.BodyDropDown.ValueChangedFcn = createCallbackFcn(app, @BodyDropDownValueChanged, true);
            app.BodyDropDown.Value = 'Head';
            app.AgeDropDownLabel = uilabel(app.ImageTab);
            app.AgeDropDownLabel.Text = 'Age:';
            app.AgeDropDown = uidropdown(app.ImageTab);
            app.AgeDropDown.Items = {'Adult', 'L4', 'L3', 'L2', 'L1', '3-Fold'};
            app.AgeDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeDropDownValueChanged, true);
            app.AgeDropDown.Value = 'Adult';
            app.SexLabel = uilabel(app.ImageTab);
            app.SexLabel.Text = 'Sex:';
            app.SexDropDown = uidropdown(app.ImageTab);
            app.SexDropDown.Items = {'XX', 'XO'};
            app.SexDropDown.ValueChangedFcn = createCallbackFcn(app, @SexDropDownValueChanged, true);
            app.SexDropDown.Value = 'XX';
            app.StrainEditFieldLabel = uilabel(app.ImageTab);
            app.StrainEditFieldLabel.Text = 'Strain:';
            app.StrainEditField = uieditfield(app.ImageTab, 'text');
            app.StrainEditField.ValueChangedFcn = createCallbackFcn(app, @StrainEditFieldValueChanged, true);
            app.NotesEditFieldLabel = uilabel(app.ImageTab);
            app.NotesEditFieldLabel.Text = 'Notes:';
            app.NotesEditField = uieditfield(app.ImageTab, 'text');
            app.NotesEditField.ValueChangedFcn = createCallbackFcn(app, @NotesEditFieldValueChanged, true);
            app.SaveLabel = uilabel(app.ImageTab);
            app.SaveLabel.Text = 'Save:';
            app.NextNeuronLabel = uilabel(app.ImageTab);
            app.NextNeuronLabel.Text = 'Next Neuron:';
            app.NextNeuronDropDown = uidropdown(app.ImageTab);
            app.NextNeuronDropDown.Items = {'Nearest', 'Probabilistic'};
            app.NextNeuronDropDown.Value = 'Nearest';
            app.NeuronRankedConfidenceLabel = uilabel(app.ImageTab);
            app.NeuronRankedConfidenceLabel.Text = 'Neuron Ranked Confidence';
            app.ColorsLabel = uilabel(app.ImageTab);
            app.ColorsLabel.Text = 'Colors:';
            app.AutoNameCheckBox = uicheckbox(app.ImageTab);
            app.AutoNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoNameNeurons, true);
            app.AutoNameCheckBox.Text = '';
            app.AutoNameCheckBox.Value = true;
            app.XYPanel = uipanel(app.ImageTab);
            app.XY = uiaxes(app.XYPanel);
            app.ActivityBrowser = uipanel(app.CELL_ID);
            app.ActivityBrowser.Visible = 'off';
            app.GridLayout = uigridlayout(app.ActivityBrowser);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.ActivityTrace = uiaxes(app.GridLayout4);
            title(app.ActivityTrace, 'Select Neuron In Ganglion Directory')
            % Show the figure after all components are created
            app.CELL_ID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_light

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CELL_ID)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELL_ID)
        end
    end
end